classdef DialogAddConstructionRC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CreatenewstructureUIFigure      matlab.ui.Figure
        AddconstructionButton           matlab.ui.control.Button
        NameEditFieldLabel              matlab.ui.control.Label
        NameEditField                   matlab.ui.control.EditField
        ModelDropDownLabel              matlab.ui.control.Label
        ModelDropDown                   matlab.ui.control.DropDown
        UITable                         matlab.ui.control.Table
        LayernumberSpinnerLabel         matlab.ui.control.Label
        LayernumberSpinner              matlab.ui.control.Spinner
        VariantNumberEditField          matlab.ui.control.NumericEditField
        layersaredefinedfrominsidetooutsideLabel  matlab.ui.control.Label
        emissioninternalEditFieldLabel  matlab.ui.control.Label
        emissioninternalEditField       matlab.ui.control.NumericEditField
        emissionexternalEditFieldLabel  matlab.ui.control.Label
        emissionexternalEditField       matlab.ui.control.NumericEditField
        absorptionexternalEditFieldLabel  matlab.ui.control.Label
        absorptionexternalEditField     matlab.ui.control.NumericEditField
        d_activeEditFieldLabel          matlab.ui.control.Label
        d_activeEditField               matlab.ui.control.NumericEditField
        T_activeEditFieldLabel          matlab.ui.control.Label
        T_activeEditField               matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        CallingApp
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, parameter)
            app.CallingApp = mainapp;
            
            app.VariantNumberEditField.Value = parameter.variant;
            app.ModelDropDown.ItemsData = [1 2];
            
            app.UITable.Data = [{'new layer'},0.01,0.04,50,1000,1];
            
            LayernumberSpinnerValueChanged(app,app.LayernumberSpinner.ValueChangedFcn);
        end

        % Button pushed function: AddconstructionButton
        function AddconstructionButtonPushed(app, event)
            global building
            
            if strcmp(app.NameEditField.Value,'')
                answer = uiconfirm(app.CreatenewstructureUIFigure,['Please define a name for the structure!'],'Creation failed!','Options',{'OK'},'Icon','error');
            elseif ~isempty(building.construction(app.VariantNumberEditField.Value).get_structure(app.NameEditField.Value))
                answer = uiconfirm(app.CreatenewstructureUIFigure,['There is already a stucture with the same name. Please choose another name!'],'Creation failed!','Options',{'OK'},'Icon','error');
            else
                try
                    dia = uiprogressdlg(app.CreatenewstructureUIFigure,'Title','Please Wait','Message','');
                    
                    tt = app.UITable.Data;
                    
                    for jj = 1:size(tt,1)
                        d(jj) = tt{jj,2};
                        lambda(jj) = tt{jj,3};
                        rho(jj) = tt{jj,4};
                        cp(jj) = tt{jj,5};
                        names{jj} = tt{jj,1};
                        n_layer_beuken(jj) = tt{jj,6};
                    end
                    
                    parameter = building.construction(app.VariantNumberEditField.Value).create_parameter_structure_RC(app.NameEditField.Value, 1, size(tt,1), d, lambda, rho, cp, names, n_layer_beuken);
                    
                    parameter.type = 0;
                    parameter.layers_names = names;
                    parameter.layers_lambda = lambda;
                    parameter.layers_colors = parameter.colors;
                    parameter.d_active = app.d_activeEditField.Value;
                    parameter.T_dactive = app.T_activeEditField.Value;
                    parameter.Phi_dactive = -1;
                    
                    building.construction(app.VariantNumberEditField.Value) = building.construction(app.VariantNumberEditField.Value).add_structure(app.NameEditField.Value, parameter, app.emissioninternalEditField.Value, app.emissionexternalEditField.Value, app.absorptionexternalEditField.Value);
                    
                    updateGUI(app.CallingApp);
                    close(dia);
                    delete(app)
                catch ME
                    ME
                    close(dia);
                    answer = uiconfirm(app.CreatenewstructureUIFigure,['The creation of the new structure failed!'],'Creation failed!','Options',{'OK'},'Icon','error');
                end
            end
        end

        % Value changed function: LayernumberSpinner
        function LayernumberSpinnerValueChanged(app, event)
            value = app.LayernumberSpinner.Value;
            
            tt = app.UITable.Data;
            app.UITable.Data = [];
            app.UITable.ColumnFormat = {'char','numeric','numeric','numeric','numeric','numeric'};
            
            if size(tt,1) < value
                while size(tt,1) < value
                    tt(end+1,:) = tt(end,:);
                end
            elseif size(tt,1) > value
                tt((value+1):end,:) = [];
            end
            
            app.UITable.Data = tt;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CreatenewstructureUIFigure = uifigure;
            app.CreatenewstructureUIFigure.Name = 'Create new structure';
            app.AddconstructionButton = uibutton(app.CreatenewstructureUIFigure, 'push');
            app.AddconstructionButton.ButtonPushedFcn = createCallbackFcn(app, @AddconstructionButtonPushed, true);
            app.AddconstructionButton.Text = 'Add construction';
            app.NameEditFieldLabel = uilabel(app.CreatenewstructureUIFigure);
            app.NameEditFieldLabel.Text = 'Name:';
            app.NameEditField = uieditfield(app.CreatenewstructureUIFigure, 'text');
            app.ModelDropDownLabel = uilabel(app.CreatenewstructureUIFigure);
            app.ModelDropDownLabel.Text = 'Model:';
            app.ModelDropDown = uidropdown(app.CreatenewstructureUIFigure);
            app.ModelDropDown.Items = {'RC', 'hygrothermal'};
            app.ModelDropDown.Value = 'RC';
            app.UITable = uitable(app.CreatenewstructureUIFigure);
            app.UITable.ColumnName = {'name'; 'd [m]'; 'lambda [W/(m.K)]'; 'rho [kg/m³]'; 'cp [J/(kg.K)]'; 'discretization'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.LayernumberSpinnerLabel = uilabel(app.CreatenewstructureUIFigure);
            app.LayernumberSpinnerLabel.Text = 'Layer number:';
            app.LayernumberSpinner = uispinner(app.CreatenewstructureUIFigure);
            app.LayernumberSpinner.Limits = [1 Inf];
            app.LayernumberSpinner.ValueDisplayFormat = '%.0f';
            app.LayernumberSpinner.ValueChangedFcn = createCallbackFcn(app, @LayernumberSpinnerValueChanged, true);
            app.LayernumberSpinner.Value = 1;
            app.VariantNumberEditField = uieditfield(app.CreatenewstructureUIFigure, 'numeric');
            app.VariantNumberEditField.Limits = [1 Inf];
            app.VariantNumberEditField.ValueDisplayFormat = '%.0f';
            app.VariantNumberEditField.Visible = 'off';
            app.VariantNumberEditField.Value = 1;
            app.layersaredefinedfrominsidetooutsideLabel = uilabel(app.CreatenewstructureUIFigure);
            app.layersaredefinedfrominsidetooutsideLabel.Text = 'layers are defined from inside to outside';
            app.emissioninternalEditFieldLabel = uilabel(app.CreatenewstructureUIFigure);
            app.emissioninternalEditFieldLabel.Text = 'emission internal:';
            app.emissioninternalEditField = uieditfield(app.CreatenewstructureUIFigure, 'numeric');
            app.emissioninternalEditField.Limits = [0 1];
            app.emissioninternalEditField.ValueDisplayFormat = '%.2f';
            app.emissioninternalEditField.Value = 0.94;
            app.emissionexternalEditFieldLabel = uilabel(app.CreatenewstructureUIFigure);
            app.emissionexternalEditFieldLabel.Text = 'emission external:';
            app.emissionexternalEditField = uieditfield(app.CreatenewstructureUIFigure, 'numeric');
            app.emissionexternalEditField.Limits = [0 1];
            app.emissionexternalEditField.ValueDisplayFormat = '%.2f';
            app.emissionexternalEditField.Value = 0.94;
            app.absorptionexternalEditFieldLabel = uilabel(app.CreatenewstructureUIFigure);
            app.absorptionexternalEditFieldLabel.Text = 'absorption external:';
            app.absorptionexternalEditField = uieditfield(app.CreatenewstructureUIFigure, 'numeric');
            app.absorptionexternalEditField.Limits = [0 1];
            app.absorptionexternalEditField.ValueDisplayFormat = '%.2f';
            app.absorptionexternalEditField.Value = 0.65;
            app.d_activeEditFieldLabel = uilabel(app.CreatenewstructureUIFigure);
            app.d_activeEditFieldLabel.Text = 'd_active';
            app.d_activeEditField = uieditfield(app.CreatenewstructureUIFigure, 'numeric');
            app.d_activeEditField.Value = -1;
            app.T_activeEditFieldLabel = uilabel(app.CreatenewstructureUIFigure);
            app.T_activeEditFieldLabel.Text = 'T_active';
            app.T_activeEditField = uieditfield(app.CreatenewstructureUIFigure, 'numeric');
            app.T_activeEditField.Value = -1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = DialogAddConstructionRC(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CreatenewstructureUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CreatenewstructureUIFigure)
        end
    end
end