classdef building_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        carnotUIBKUIFigure              matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        StartTab                        matlab.ui.container.Tab
        LoadorCreateButton              matlab.ui.control.Button
        FilenameDropDownLabel           matlab.ui.control.Label
        FilenameDropDown                matlab.ui.control.DropDown
        SaveButton                      matlab.ui.control.Button
        ImportfromfileLabel_8           matlab.ui.control.Label
        StartImportwholeBuildingdropList  matlab.ui.control.DropDown
        Label_3                         matlab.ui.control.Label
        GeometryEditFieldLabel_2        matlab.ui.control.Label
        StartGeometryEditField          matlab.ui.control.NumericEditField
        ConstructionEditFieldLabel_2    matlab.ui.control.Label
        StartConstructionEditField      matlab.ui.control.NumericEditField
        GainsEditFieldLabel_2           matlab.ui.control.Label
        StartGainsEditField             matlab.ui.control.NumericEditField
        BoundaryEditFieldLabel_3        matlab.ui.control.Label
        StartBoundaryEditField          matlab.ui.control.NumericEditField
        ThermalZoneEditFieldLabel       matlab.ui.control.Label
        StartThermalZoneEditField       matlab.ui.control.NumericEditField
        HVACEditFieldLabel_2            matlab.ui.control.Label
        StartHVACEditField              matlab.ui.control.NumericEditField
        ImportButton                    matlab.ui.control.Button
        ExportButton                    matlab.ui.control.Button
        ExporttoEditFieldLabel          matlab.ui.control.Label
        ExportEditField                 matlab.ui.control.EditField
        StartGeomCheckBox               matlab.ui.control.CheckBox
        StartCheckBoxConstr             matlab.ui.control.CheckBox
        StartCheckBoxGains              matlab.ui.control.CheckBox
        StartCheckBoxBoundary           matlab.ui.control.CheckBox
        StartCheckBoxThermalZone        matlab.ui.control.CheckBox
        StartCheckBoxHVAC               matlab.ui.control.CheckBox
        AllLabel                        matlab.ui.control.Label
        StartAllCheckBox                matlab.ui.control.CheckBox
        SimulateTab                     matlab.ui.container.Tab
        GeometryDropDownLabel           matlab.ui.control.Label
        SimulateGeometryDropDown        matlab.ui.control.DropDown
        ConstructionDropDownLabel       matlab.ui.control.Label
        SimulateConstructionDropDown    matlab.ui.control.DropDown
        GainDropDownLabel               matlab.ui.control.Label
        SimulateGainDropDown            matlab.ui.control.DropDown
        BoundaryDropDownLabel           matlab.ui.control.Label
        SimulateBoundaryDropDown        matlab.ui.control.DropDown
        ThermalzoneDropDownLabel        matlab.ui.control.Label
        SimulateThermalzoneDropDown     matlab.ui.control.DropDown
        HVACDropDownLabel               matlab.ui.control.Label
        SimulateHVACDropDown            matlab.ui.control.DropDown
        SimulationnumberEditFieldLabel  matlab.ui.control.Label
        SimulateSimulationnumberEditField  matlab.ui.control.NumericEditField
        DescriptionEditFieldLabel_2     matlab.ui.control.Label
        SimulateDescriptionEditField    matlab.ui.control.EditField
        ModelLabel                      matlab.ui.control.Label
        SimulateModelDropDown           matlab.ui.control.DropDown
        SimulateStartSimulationButton   matlab.ui.control.Button
        SimulateDonotrunsimulationCheckBox  matlab.ui.control.CheckBox
        SimulateSaveSimulationButton    matlab.ui.control.Button
        GeometryTab                     matlab.ui.container.Tab
        GeometryTree                    matlab.ui.container.Tree
        GeometryPanel                   matlab.ui.container.Panel
        VariantnameEditFieldLabel       matlab.ui.control.Label
        GeometryVariantnameEditField    matlab.ui.control.EditField
        GeometryDeleteVariantButton     matlab.ui.control.Button
        GeometryRoomPanel               matlab.ui.container.Panel
        NameEditFieldLabel              matlab.ui.control.Label
        GeometryRoomNameEditField       matlab.ui.control.EditField
        HeightEditFieldLabel            matlab.ui.control.Label
        GeometryRoomHeightEditField     matlab.ui.control.NumericEditField
        n50EditFieldLabel               matlab.ui.control.Label
        GeometryRoomn50EditField        matlab.ui.control.NumericEditField
        GeometryRoomAddWallButton       matlab.ui.control.Button
        AreaEditFieldLabel              matlab.ui.control.Label
        GeometryRoomAreaEditField       matlab.ui.control.NumericEditField
        GeometryRoomDeleteRoomButton    matlab.ui.control.Button
        GeometryRoomPlotButton          matlab.ui.control.Button
        GeometryWallPanel               matlab.ui.container.Panel
        NameEditFieldLabel_2            matlab.ui.control.Label
        GeometryWallNameEditField       matlab.ui.control.EditField
        WidthLabel                      matlab.ui.control.Label
        GeometryWallWidthEditField      matlab.ui.control.NumericEditField
        HeightEditFieldLabel_2          matlab.ui.control.Label
        GeometryWallHeightEditField     matlab.ui.control.NumericEditField
        SlopeLabel                      matlab.ui.control.Label
        GeometryWallSlopeEditField      matlab.ui.control.NumericEditField
        AzimuthLabel                    matlab.ui.control.Label
        GeometryWallAzimuthEditField    matlab.ui.control.NumericEditField
        ViewfactorLabel                 matlab.ui.control.Label
        GeometryWallViewFacEditField    matlab.ui.control.NumericEditField
        AmbientfactorLabel              matlab.ui.control.Label
        GeometryWallAmbFactorEditField  matlab.ui.control.NumericEditField
        ModelconstructionDropDownLabel  matlab.ui.control.Label
        GeometryWallModelConstructionDropDown  matlab.ui.control.DropDown
        ModelheattransferDropDownLabel  matlab.ui.control.Label
        GeometryWallModelHeattransferDropDown  matlab.ui.control.DropDown
        ConstructionLabel               matlab.ui.control.Label
        GeometryWallConstructionEditField  matlab.ui.control.EditField
        InsideLabel                     matlab.ui.control.Label
        GeometryWallInsideDropDown      matlab.ui.control.DropDown
        BoundaryEditFieldLabel_2        matlab.ui.control.Label
        GeometryWallBoundaryEditField   matlab.ui.control.EditField
        GeometryWallCalculateButton     matlab.ui.control.Button
        GeometryRoomAddDoor             matlab.ui.control.Button
        GeometryRoomAddWindow           matlab.ui.control.Button
        GeometryRoomDeleteWallButton    matlab.ui.control.Button
        GeometryWindowDoorPanel         matlab.ui.container.Panel
        NameEditFieldLabel_3            matlab.ui.control.Label
        GeometryWindowDoorNameEditField  matlab.ui.control.EditField
        WidthwindowLabel                matlab.ui.control.Label
        GeometryWindowDoorWidthEditField  matlab.ui.control.NumericEditField
        HeightwindowLabel               matlab.ui.control.Label
        GeometryWindowDoorHeightEditField  matlab.ui.control.NumericEditField
        ConstructionLabel_2             matlab.ui.control.Label
        GeometryWindowDoorConstructionEditField  matlab.ui.control.EditField
        ViewfactorLabel_2               matlab.ui.control.Label
        GeometryWindowDoorViewFacEditField  matlab.ui.control.NumericEditField
        AmbientfactorLabel_2            matlab.ui.control.Label
        GeometryWindowDoorAmbFactorEditField  matlab.ui.control.NumericEditField
        ModelconstructionDropDownLabel_2  matlab.ui.control.Label
        GeometryWindowDoorModelConstructionDropDown  matlab.ui.control.DropDown
        DirtyfactorLabel                matlab.ui.control.Label
        GeometryWindowDoorDirtyFacEditField  matlab.ui.control.NumericEditField
        psitopbottomleftrightLabel      matlab.ui.control.Label
        GeometryWindowDoorPsi1EditField  matlab.ui.control.NumericEditField
        GeometryWindowDoorPsi2EditField  matlab.ui.control.NumericEditField
        GeometryWindowDoorPsi3EditField  matlab.ui.control.NumericEditField
        GeometryWindowDoorPsi4EditField  matlab.ui.control.NumericEditField
        WidthglassLabel                 matlab.ui.control.Label
        GeometryWindowDoorWidthGlassEditField  matlab.ui.control.NumericEditField
        HeightglassLabel                matlab.ui.control.Label
        GeometryWindowDoorHeightGlassEditField  matlab.ui.control.NumericEditField
        ControlshadingLabel             matlab.ui.control.Label
        GeometryWindowDoorModelControlShadingDropDown  matlab.ui.control.DropDown
        ControlopeningLabel             matlab.ui.control.Label
        GeometryWindowDoorModelControlOpeningDropDown  matlab.ui.control.DropDown
        ShadingfactormonthlyLabel       matlab.ui.control.Label
        GeometryWindowDoorShading1EditField  matlab.ui.control.NumericEditField
        GeometryWindowDoorShading2EditField  matlab.ui.control.NumericEditField
        GeometryWindowDoorShading3EditField  matlab.ui.control.NumericEditField
        GeometryWindowDoorShading4EditField  matlab.ui.control.NumericEditField
        GeometryWindowDoorShading5EditField  matlab.ui.control.NumericEditField
        GeometryWindowDoorShading6EditField  matlab.ui.control.NumericEditField
        GeometryWindowDoorShading7EditField  matlab.ui.control.NumericEditField
        GeometryWindowDoorShading8EditField  matlab.ui.control.NumericEditField
        GeometryWindowDoorShading9EditField  matlab.ui.control.NumericEditField
        GeometryWindowDoorShading10EditField  matlab.ui.control.NumericEditField
        GeometryWindowDoorShading11EditField  matlab.ui.control.NumericEditField
        GeometryWindowDoorShading12EditField  matlab.ui.control.NumericEditField
        GeometryRoomDeleteWindowDoorButton  matlab.ui.control.Button
        GeometryCopyVariantButton       matlab.ui.control.Button
        GeometryExportVariantButton     matlab.ui.control.Button
        GeometryRoomAddRoomButton       matlab.ui.control.Button
        GeometryPlotButton              matlab.ui.control.Button
        ImportfromfileLabel             matlab.ui.control.Label
        GeometryImportfromfileDropDown  matlab.ui.control.DropDown
        variantnumberLabel              matlab.ui.control.Label
        GeometryTovariantnumberEditField  matlab.ui.control.NumericEditField
        GeometryImportButton            matlab.ui.control.Button
        nameEditFieldLabel              matlab.ui.control.Label
        GeometryNameEditField           matlab.ui.control.EditField
        GeometryCreateButton            matlab.ui.control.Button
        ConstructionTab                 matlab.ui.container.Tab
        ConstructionTree                matlab.ui.container.Tree
        ConstructionPanel               matlab.ui.container.Panel
        VariantnameEditFieldLabel_2     matlab.ui.control.Label
        ConstructionVariantnameEditField  matlab.ui.control.EditField
        ConstructionDeleteVariantButton  matlab.ui.control.Button
        ConstructionCopyVariantButton   matlab.ui.control.Button
        ConstructionExportVariantButton  matlab.ui.control.Button
        ConstructionStructurePanel      matlab.ui.container.Panel
        ConstructionStructureUIAxes     matlab.ui.control.UIAxes
        ModelLevelLabel                 matlab.ui.control.Label
        ConstructionStructureModelLevelDropDown  matlab.ui.control.DropDown
        TypeEditFieldLabel              matlab.ui.control.Label
        ConstructionStructureTypeEditField  matlab.ui.control.EditField
        StructurenameEditFieldLabel     matlab.ui.control.Label
        ConstructionStructureNameEditField  matlab.ui.control.EditField
        DeletestructureButton           matlab.ui.control.Button
        emissioninternalEditFieldLabel  matlab.ui.control.Label
        ConstructionStructureemissioninternalEditField  matlab.ui.control.NumericEditField
        emissionexternalEditFieldLabel  matlab.ui.control.Label
        ConstructionStructureemissionexternalEditField  matlab.ui.control.NumericEditField
        absorptionexternalEditFieldLabel  matlab.ui.control.Label
        ConstructionStructureabsorptionexternalEditField  matlab.ui.control.NumericEditField
        InformationTextAreaLabel        matlab.ui.control.Label
        ConstructionStructureInformationTextArea  matlab.ui.control.TextArea
        ConstructionAddstructureRCButton  matlab.ui.control.Button
        ConstructionAddWindowButton     matlab.ui.control.Button
        ImportfromfileLabel_2           matlab.ui.control.Label
        ConstructionImportfromfileDropDown  matlab.ui.control.DropDown
        variantnumberLabel_2            matlab.ui.control.Label
        ConstructionTovariantnumberEditField  matlab.ui.control.NumericEditField
        nameEditFieldLabel_2            matlab.ui.control.Label
        ConstructionNameEditField       matlab.ui.control.EditField
        ConstructionImportButton        matlab.ui.control.Button
        ConstructionCreateButton        matlab.ui.control.Button
        GainsTab                        matlab.ui.container.Tab
        GainsTree                       matlab.ui.container.Tree
        GainsPanel                      matlab.ui.container.Panel
        VariantnameEditFieldLabel_3     matlab.ui.control.Label
        GainsVariantnameEditField       matlab.ui.control.EditField
        GainsDeleteVariantButton        matlab.ui.control.Button
        GainsCopyVariantButton          matlab.ui.control.Button
        GainsExportVariantButton        matlab.ui.control.Button
        GainsGainPanel                  matlab.ui.container.Panel
        GainnameLabel                   matlab.ui.control.Label
        GainsGainNameEditField          matlab.ui.control.EditField
        TypeDropDownLabel               matlab.ui.control.Label
        GainsGainTypeDropDown           matlab.ui.control.DropDown
        ModelDropDownLabel              matlab.ui.control.Label
        GainsGainModelDropDown          matlab.ui.control.DropDown
        GainsGainUITable                matlab.ui.control.Table
        useinzonesmultiselectwithCTRLandapplyLabel  matlab.ui.control.Label
        GainsGainUseinzonesListBox      matlab.ui.control.ListBox
        GainsGainUseinzonesApplyButton  matlab.ui.control.Button
        GainsGainDeleteGainButton       matlab.ui.control.Button
        GainsGainUIAxes                 matlab.ui.control.UIAxes
        GainsGain2UIAxes                matlab.ui.control.UIAxes
        GainsGainRefreshplotsButton     matlab.ui.control.Button
        ControlgainLabel                matlab.ui.control.Label
        GainsGainControlDropDown        matlab.ui.control.DropDown
        GainsGainAddGainButton          matlab.ui.control.Button
        ImportfromfileLabel_3           matlab.ui.control.Label
        GainsImportfromfileDropDown     matlab.ui.control.DropDown
        variantnumberLabel_3            matlab.ui.control.Label
        GainsTovariantnumberEditField   matlab.ui.control.NumericEditField
        nameEditFieldLabel_3            matlab.ui.control.Label
        GainsNameEditField              matlab.ui.control.EditField
        GainsImportButton               matlab.ui.control.Button
        GainsCreateButton               matlab.ui.control.Button
        BoundaryTab                     matlab.ui.container.Tab
        BoundaryTree                    matlab.ui.container.Tree
        BoundaryPanel                   matlab.ui.container.Panel
        VariantnameEditFieldLabel_4     matlab.ui.control.Label
        BoundaryVariantnameEditField    matlab.ui.control.EditField
        BoundaryDeleteVariantButton     matlab.ui.control.Button
        BoundaryCopyVariantButton       matlab.ui.control.Button
        BoundaryExportVariantButton     matlab.ui.control.Button
        WeatherPanel                    matlab.ui.container.Panel
        NameEditFieldLabel_4            matlab.ui.control.Label
        WeatherNameEditField            matlab.ui.control.EditField
        WeatherAddChangeweatherButton   matlab.ui.control.Button
        GroundPanel                     matlab.ui.container.Panel
        NameEditFieldLabel_5            matlab.ui.control.Label
        GroundNameEditField             matlab.ui.control.EditField
        useasboundaryEditFieldLabel     matlab.ui.control.Label
        GroundUseasboundaryEditField    matlab.ui.control.EditField
        GroundUITable                   matlab.ui.control.Table
        GroundApplyButton               matlab.ui.control.Button
        NeighbourPanel                  matlab.ui.container.Panel
        NameEditFieldLabel_6            matlab.ui.control.Label
        NeighbourNameEditField          matlab.ui.control.EditField
        useasboundaryEditFieldLabel_2   matlab.ui.control.Label
        NeighbourUseasboundaryEditField  matlab.ui.control.EditField
        NeighbourUITable                matlab.ui.control.Table
        NeighbourApplyButton            matlab.ui.control.Button
        BoundaryUIAxes1                 matlab.ui.control.UIAxes
        BoundaryUIAxes2                 matlab.ui.control.UIAxes
        ImportfromfileLabel_4           matlab.ui.control.Label
        BoundaryImportfromfileDropDown  matlab.ui.control.DropDown
        variantnumberLabel_4            matlab.ui.control.Label
        BoundaryTovariantnumberEditField  matlab.ui.control.NumericEditField
        nameEditFieldLabel_4            matlab.ui.control.Label
        BoundaryNameEditField           matlab.ui.control.EditField
        BoundaryImportButton            matlab.ui.control.Button
        BoundaryCreateButton            matlab.ui.control.Button
        ThermalzoneTab                  matlab.ui.container.Tab
        ThermalzoneTree                 matlab.ui.container.Tree
        ThermalzonePanel                matlab.ui.container.Panel
        VariantnameEditFieldLabel_5     matlab.ui.control.Label
        ThermalzoneVariantnameEditField  matlab.ui.control.EditField
        ThermalzoneDeleteVariantButton  matlab.ui.control.Button
        ThermalzoneCopyVariantButton    matlab.ui.control.Button
        ThermalzoneExportVariantButton  matlab.ui.control.Button
        ThermalzoneZonePanel            matlab.ui.container.Panel
        ZonenameEditFieldLabel          matlab.ui.control.Label
        ThermalzoneZoneNameEditField    matlab.ui.control.EditField
        ThermalzoneZoneClearButton      matlab.ui.control.Button
        CalculationmodelDropDownLabel   matlab.ui.control.Label
        ThermalzoneZoneCalculationmodelDropDown  matlab.ui.control.DropDown
        InitialtemperatureEditFieldLabel  matlab.ui.control.Label
        ThermalzoneZoneInitialtemperatureEditField  matlab.ui.control.NumericEditField
        InitialrelhumidityEditFieldLabel  matlab.ui.control.Label
        ThermalzoneZoneInitialrelhumidityEditField  matlab.ui.control.NumericEditField
        CLabel                          matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        JkgmLabel                       matlab.ui.control.Label
        for1nodespeccpLabel             matlab.ui.control.Label
        ThermalzoneZoneCpEditField      matlab.ui.control.NumericEditField
        sLabel                          matlab.ui.control.Label
        for2nodetimeconstantforradiativenodeLabel  matlab.ui.control.Label
        ThermalzoneZoneTimeConstantEditField  matlab.ui.control.NumericEditField
        AllowmergingwallswithdifferentorientationDropDownLabel  matlab.ui.control.Label
        ThermalzoneZoneOrientationImportantDropDown  matlab.ui.control.DropDown
        ThermalzoneZoneProfileUITable   matlab.ui.control.Table
        ProfileDropDownLabel            matlab.ui.control.Label
        ThermalzoneZoneProfileDropDown  matlab.ui.control.DropDown
        ProfilesLabel                   matlab.ui.control.Label
        RoomsLabel                      matlab.ui.control.Label
        ThermalzoneZoneRoomsTextArea    matlab.ui.control.TextArea
        ThermalzoneZoneRoomsApplyButton  matlab.ui.control.Button
        ThermalzonePlotButton           matlab.ui.control.Button
        ImportfromfileLabel_5           matlab.ui.control.Label
        ThermalzoneImportfromfileDropDown  matlab.ui.control.DropDown
        variantnumberLabel_5            matlab.ui.control.Label
        ThermalzoneTovariantnumberEditField  matlab.ui.control.NumericEditField
        nameEditFieldLabel_5            matlab.ui.control.Label
        ThermalzoneNameEditField        matlab.ui.control.EditField
        ThermalzoneImportButton         matlab.ui.control.Button
        ThermalzoneCreateButton         matlab.ui.control.Button
        HVACTab                         matlab.ui.container.Tab
        HVACTree                        matlab.ui.container.Tree
        HVACPanel                       matlab.ui.container.Panel
        HVACUITable                     matlab.ui.control.Table
        VariantnameEditFieldLabel_6     matlab.ui.control.Label
        HVACVariantnameEditField        matlab.ui.control.EditField
        HVACDeleteVariantButton         matlab.ui.control.Button
        HVACCopyVariantButton           matlab.ui.control.Button
        HVACExportVariantButton         matlab.ui.control.Button
        HVACSystemPanel                 matlab.ui.container.Panel
        HVACSystemAddButton             matlab.ui.control.Button
        HVACSystemImportButton          matlab.ui.control.Button
        ImportfromfileLabel_7           matlab.ui.control.Label
        HVACSystemImportFileDropDown    matlab.ui.control.DropDown
        HVACSystemParameterPanel        matlab.ui.container.Panel
        HVACSystemExportButton          matlab.ui.control.Button
        SystemnumberEditFieldLabel      matlab.ui.control.Label
        HVACSystemNumberEditField       matlab.ui.control.NumericEditField
        SystemnameEditFieldLabel        matlab.ui.control.Label
        HVACSystemNameEditField         matlab.ui.control.EditField
        HVACSystemDeleteButton          matlab.ui.control.Button
        CautionAtthemomentonlytheimportissupportedLabel_4  matlab.ui.control.Label
        ImportfromfileLabel_6           matlab.ui.control.Label
        HVACImportfromfileDropDown      matlab.ui.control.DropDown
        variantnumberLabel_6            matlab.ui.control.Label
        HVACTovariantnumberEditField    matlab.ui.control.NumericEditField
        nameEditFieldLabel_6            matlab.ui.control.Label
        HVACNameEditField               matlab.ui.control.EditField
        HVACImportButton                matlab.ui.control.Button
        HVACCreateButton                matlab.ui.control.Button
        ResultsTab                      matlab.ui.container.Tab
        SimulationNumberDropDown        matlab.ui.control.DropDown
        ResultPanel                     matlab.ui.container.Panel
        DateLabel                       matlab.ui.control.Label
        ResultDateEditField             matlab.ui.control.EditField
        DescriptionEditFieldLabel       matlab.ui.control.Label
        ResultDescriptionEditField      matlab.ui.control.EditField
        NumberofzonesEditFieldLabel     matlab.ui.control.Label
        ResultNumberofzonesEditField    matlab.ui.control.EditField
        GeometryEditFieldLabel          matlab.ui.control.Label
        ResultGeometryEditField         matlab.ui.control.EditField
        ConstructionEditFieldLabel      matlab.ui.control.Label
        ResultConstructionEditField     matlab.ui.control.EditField
        GainsEditFieldLabel             matlab.ui.control.Label
        ResultGainsEditField            matlab.ui.control.EditField
        BoundaryEditFieldLabel          matlab.ui.control.Label
        ResultBoundaryEditField         matlab.ui.control.EditField
        ThermalzoneEditFieldLabel       matlab.ui.control.Label
        ResultThermalzoneEditField      matlab.ui.control.EditField
        HVACEditFieldLabel              matlab.ui.control.Label
        ResultHVACEditField             matlab.ui.control.EditField
        ResultOverviewButton            matlab.ui.control.Button
        ResultPlotButton                matlab.ui.control.Button
        ResultOverviewplotsCheckBox     matlab.ui.control.CheckBox
        ResultTemperatureHumidityCO2Button  matlab.ui.control.Button
        ResultMonthlyEnergyBalanceButton  matlab.ui.control.Button
        ResultEnergyDemandButton        matlab.ui.control.Button
        ResultHeatingandCoolingPowerButton  matlab.ui.control.Button
        ResultHeatLoadCurveButton       matlab.ui.control.Button
        ResultTemperatureBoundaryButton  matlab.ui.control.Button
        ResultTemperatureArrangedButton  matlab.ui.control.Button
        ResultMonthlyLossesGainsButton  matlab.ui.control.Button
        ResultCalculateCharacteristicsButton  matlab.ui.control.Button
        HeatingDemandLabel              matlab.ui.control.Label
        CoolingDemandLabel              matlab.ui.control.Label
        ResultHeatingDemandEditField    matlab.ui.control.NumericEditField
        ResultHeatingDemandSpecEditField  matlab.ui.control.NumericEditField
        kWhaLabel                       matlab.ui.control.Label
        kWhmaLabel                      matlab.ui.control.Label
        ResultCoolingDemandEditField    matlab.ui.control.NumericEditField
        ResultCoolingDemandSpecEditField  matlab.ui.control.NumericEditField
        kWhaLabel_2                     matlab.ui.control.Label
        kWhmaLabel_2                    matlab.ui.control.Label
        ResultAreaEditField             matlab.ui.control.NumericEditField
        mLabel                          matlab.ui.control.Label
    end

    
    properties (Access = private)
        DialogAddConstructionRCApp
        DialogAddWindowApp
    end
    
    methods (Access = public)
        
        function updateGUI(app)
            TabGroupSelectionChanged(app,app.TabGroup.SelectionChangedFcn);
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global building
            
            try
                d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                
                loading = 0;
                
                if isempty(building)
                    building = BUILDING();
                    building.internal_version = 2.0;
                end
                
                if strcmp(building.name, '')
                    building = BUILDING();
                    building.internal_version = 2.0;
                else
                    answer = uiconfirm(app.carnotUIBKUIFigure,['There is already a building model in the workspace. Would you like to load this? (Outerwise the workspace is going to be cleared.)'],'Load existing building model?','Options',{'Yes','No'});
                    if strcmp(answer, 'Yes')
                        loading = 1;
                    else
                        building = BUILDING();
                        building.internal_version = 2.0;
                    end
                end
                
                app.GeometryPanel.Visible = 'off';
                app.ConstructionPanel.Visible = 'off';
                app.GainsPanel.Visible = 'off';
                app.BoundaryPanel.Visible = 'off';
                app.ThermalzonePanel.Visible = 'off';
                app.HVACPanel.Visible = 'off';
                app.ResultPanel.Visible = 'off';
                
                app.GeometryWallModelConstructionDropDown.ItemsData = [-1 0 1 2];
                app.GeometryWallModelHeattransferDropDown.ItemsData = [0 1 2];
                app.GeometryWallInsideDropDown.ItemsData = [1 0];
                app.GeometryWindowDoorModelControlShadingDropDown.ItemsData = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
                app.GeometryWindowDoorModelControlOpeningDropDown.ItemsData = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
                app.GainsGainControlDropDown.ItemsData = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
                app.GeometryWindowDoorModelConstructionDropDown.ItemsData = [0 1 2];
                app.ThermalzoneZoneCalculationmodelDropDown.ItemsData = [0 1 2 3 4];
                app.ThermalzoneZoneOrientationImportantDropDown.ItemsData = [0 1];
                app.ConstructionStructureModelLevelDropDown.ItemsData = [0 1 2];
                app.GainsGainTypeDropDown.ItemsData = [0 1 2 3];
                app.GainsGainUseinzonesListBox.ItemsData = [1 2 3 4 5 6 7 8 9 10];
                
                filenames_x = {};
                        files = evalin('base','dir(''*.xls'')');
                        for jj=1:length(files)
                            filenames_x{end+1} = files(jj).name;
                        end
                        files = evalin('base','dir(''*.xlsx'')');
                        for jj=1:length(files)
                            filenames_x{end+1} = files(jj).name;
                        end
                        files = evalin('base','dir(''*.xml'')');
                        for jj=1:length(files)
                            filenames_x{end+1} = files(jj).name;
                        end
                        app.StartImportwholeBuildingdropList.Items = filenames_x;
                
                close(d);
            catch ME
                ME
                close(d);
            end
            
            if loading
                app.FilenameDropDown.Enable = 'on';
                app.LoadorCreateButton.Enable = 'on';
                
                disp('Load existing building ...')
                app.FilenameDropDown.Items = {building.name};
                app.LoadorCreateButtonPushed(app.LoadorCreateButton.ButtonPushedFcn)
            else
                try
                    d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                    
                    files = evalin('base','dir(''*.mat'')');
                    for jj=1:length(files)
                        try
                            files(jj).name = strrep(files(jj).name,'.mat','');
                            test_b = load([files(jj).name '.mat']);
                            if isfield(test_b,'building')
                            else
                                files(jj).name = '';
                            end
                        catch ME
                            ME
                            files(jj).name = '';
                        end
                    end
                    filenames = {''};
                    ii = 1;
                    for jj=1:length(files)
                        if strcmp(files(jj).name,'')
                            
                        else
                            ii = ii + 1;
                            filenames{ii} = files(jj).name;
                        end
                    end
                    
                    app.FilenameDropDown.Items = filenames;


                    app.FilenameDropDown.Enable = 'on';
                    app.LoadorCreateButton.Enable = 'on';
                    
                    close(d);
                catch ME
                    ME
                    close(d);
                end
            end
        end

        % Button pushed function: LoadorCreateButton
        function LoadorCreateButtonPushed(app, event)
            global building PLOT NUMBEROFZONES NUMBEROFINTERSECTIONS NUMBEROFWALLSINZONES NUMBEROFWINDOWSINZONES NUMBEROFWALLSININTERSECTIONS NUMBEROFWINDOWSINZONESANDINTERSECTIONS NUMBEROFGAINSINZONES NUMBEROFCONTROLS internal_version
            
            actualfolder = split(pwd,'\');
            actualfolder = actualfolder{end};
            
            if strcmp(app.FilenameDropDown.Value, '')
                uiconfirm(app.carnotUIBKUIFigure,'No building model choosen! To create a new building model please choose a name.','Failed!','Options',{'OK'},'Icon','error');
            elseif strcmp(app.FilenameDropDown.Value, actualfolder)
                uiconfirm(app.carnotUIBKUIFigure,'It is not allowed to name the building model like the directory! Please choose another name.','Failed!','Options',{'OK'},'Icon','error');
            else
                try
                    d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                    
                    %%
                    app.LoadorCreateButton.Enable = 'off';
                    
                    %%
                    app.SimulationNumberDropDown.Items = {};
                    app.SimulationNumberDropDown.ItemsData = [];
                                    
                    if strcmp(building.name, '')
                        name_build_obj = app.FilenameDropDown.Value;
                        [PLOT, NUMBEROFZONES, NUMBEROFINTERSECTIONS, NUMBEROFWALLSINZONES, NUMBEROFWINDOWSINZONES, NUMBEROFWALLSININTERSECTIONS, NUMBEROFWINDOWSINZONESANDINTERSECTIONS, NUMBEROFGAINSINZONES, NUMBEROFCONTROLS, building] = load_building_automatic(name_build_obj);
                        building.internal_version = internal_version;
                    end
                    
                    %%
                    app.ResultPanel.Visible = 'on';
                    for jj = 1:length(building.result)
                        if building.result(jj).number > 0
                            app.SimulationNumberDropDown.Items{end+1} = [num2str(building.result(jj).number) ': ' building.result(jj).description];
                            app.SimulationNumberDropDown.ItemsData{end+1} = jj;
                        end
                    end
                    if length(app.SimulationNumberDropDown.Items) > 0
                        SimulationNumberDropDownValueChanged(app,app.SimulationNumberDropDown.ValueChangedFcn);
                    end
                    
                    %%
                    if building.internal_version < internal_version
                        uiconfirm(app.carnotUIBKUIFigure,'This is an older version of a building model file. It is going to be updated. Please save it afterwards!','Update of old version!','Options',{'OK'},'Icon','info');
                        try
                            d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                            if building.internal_version < 2
                                for jj = 1:length(building.geometry)
                                    if ~isempty(building.geometry(jj).room)
                                        building.geometry(jj).name = ['geometry variant ' num2str(jj)];
                                    end
                                end
                                for jj = 1:length(building.construction)
                                    if ~isempty(building.construction(jj).structure)
                                        building.construction(jj).name = ['construction variant ' num2str(jj)];
                                    end
                                end
                                for jj = 1:length(building.thermalzone)
                                    if ~isempty(building.thermalzone(jj).zone(1).rooms)
                                        building.thermalzone(jj).name = ['thermalzone variant ' num2str(jj)];
                                    end
                                end
                                for jj = 1:length(building.boundary)
                                    if ~isempty(building.boundary(jj).weather)
                                        building.boundary(jj).name = ['boundary variant ' num2str(jj)];
                                    end
                                end
                                for jj = 1:length(building.gains)
                                    if ~isempty(building.gains(jj).gain)
                                        building.gains(jj).name = ['gains variant ' num2str(jj)];
                                    end
                                end
                                for jj = 1:length(building.hvac)
                                    if ~isempty(building.hvac(jj).system)
                                        building.hvac(jj).name = ['hvac variant ' num2str(jj)];
                                    end
                                end
                            end
                            building.internal_version = internal_version;
                            
                            close(d);
                        catch ME
                            ME
                            close(d);
                            uiconfirm(app.carnotUIBKUIFigure,'Update failed! Is it a valid building model file?','Failed!','Options',{'OK'},'Icon','error');
                        end
                    end
                    
                    d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                    %%
                    app.SaveButton.Enable = 'on';
%                     app.BuildingPlotButton.Enable = 'on';
                    app.SimulateStartSimulationButton.Enable = 'on';
                    app.SimulateSaveSimulationButton.Enable = 'on';
                    app.GeometryImportButton.Enable = 'on';
                    app.GeometryCreateButton.Enable = 'on';
                    app.ConstructionImportButton.Enable = 'on';
                    app.ConstructionCreateButton.Enable = 'on';
                    app.GainsImportButton.Enable = 'on';
                    app.GainsCreateButton.Enable = 'on';
                    app.BoundaryImportButton.Enable = 'on';
                    app.BoundaryCreateButton.Enable = 'on';
                    app.ThermalzoneImportButton.Enable = 'on';
                    app.ThermalzoneCreateButton.Enable = 'on';
                    app.HVACImportButton.Enable = 'on';
                    app.HVACCreateButton.Enable = 'on';
                    
                    close(d);
                    uiconfirm(app.carnotUIBKUIFigure,'Building model loaded!','Done!','Options',{'OK'},'Icon','success');
                catch ME
                    ME
                    close(d);
                    uiconfirm(app.carnotUIBKUIFigure,'Loading of the building model failed!','Failed!','Options',{'OK'},'Icon','error');
                end
            end
        end

        % Value changed function: SimulationNumberDropDown
        function SimulationNumberDropDownValueChanged(app, event)
            global building
            value = app.SimulationNumberDropDown.Value;
            
            app.ResultAreaEditField.Visible = 'off';
            app.ResultHeatingDemandEditField.Visible = 'off';
            app.ResultHeatingDemandSpecEditField.Visible = 'off';
            app.ResultCoolingDemandEditField.Visible = 'off';
            app.ResultCoolingDemandSpecEditField.Visible = 'off';
            
            app.ResultDateEditField.Value = building.result(value).date_of_sim;
            app.ResultDescriptionEditField.Value = building.result(value).description;
            app.ResultNumberofzonesEditField.Value = num2str(length(building.result(value).list_zones));
%             if isnumeric(building.result(value).time_sim)
%                 app.ResultNumberofzonesEditField.Value = [num2str(building.result(value).time_sim/3600) ' h'];
%             else
%                 app.ResultNumberofzonesEditField.Value = 'no simulation running time available';
%             end
            
            app.ResultGeometryEditField.Value = [building.geometry(building.result(value).variant_geometry).name ' (Variant ' num2str(building.result(value).variant_geometry) ')'];
            app.ResultConstructionEditField.Value = [building.construction(building.result(value).variant_construction).name ' (Variant ' num2str(building.result(value).variant_construction) ')'];
            app.ResultGainsEditField.Value = [building.gains(building.result(value).variant_gains).name ' (Variant ' num2str(building.result(value).variant_gains) ')'];
            app.ResultBoundaryEditField.Value = [building.boundary(building.result(value).variant_boundary).name ' (Variant ' num2str(building.result(value).variant_boundary) ')'];
            app.ResultThermalzoneEditField.Value = [building.thermalzone(building.result(value).variant_thermalzone).name ' (Variant ' num2str(building.result(value).variant_thermalzone) ')'];
            app.ResultHVACEditField.Value = [building.hvac(building.result(value).variant_hvac).name ' (Variant ' num2str(building.result(value).variant_hvac) ')'];
            
        end

        % Selection changed function: GeometryTree
        function GeometryTreeSelectionChanged(app, event)
            global building
            
            selectedNodes = app.GeometryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                app.GeometryPanel.Visible = 'on';
                selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                
                app.GeometryVariantnameEditField.Value = building.geometry(selectedNodes(1)).name;
                
                if length(selectedNodes) == 2
                    app.GeometryRoomPanel.Visible = 'on';
                    app.GeometryWallPanel.Visible = 'off';
                    app.GeometryWindowDoorPanel.Visible = 'off';
                    
                    app.GeometryRoomNameEditField.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).name;
                    app.GeometryRoomAreaEditField.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).area;
                    app.GeometryRoomHeightEditField.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).height;
                    app.GeometryRoomn50EditField.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).n50;
                    
                elseif length(selectedNodes) == 3
                    app.GeometryRoomPanel.Visible = 'off';
                    app.GeometryWallPanel.Visible = 'on';
                    app.GeometryWindowDoorPanel.Visible = 'off';
                    
                    app.GeometryWallNameEditField.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).name;
                    app.GeometryWallWidthEditField.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).width;
                    app.GeometryWallHeightEditField.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).height;
                    app.GeometryWallSlopeEditField.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).orientation_slope;
                    app.GeometryWallAzimuthEditField.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).orientation_azimuth;
                    app.GeometryWallViewFacEditField.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).view_factor;
                    app.GeometryWallAmbFactorEditField.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).amb_factor;
                    app.GeometryWallModelConstructionDropDown.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).model_cons;
                    app.GeometryWallModelHeattransferDropDown.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).model_heattrans;
                    app.GeometryWallConstructionEditField.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).construction;
                    app.GeometryWallInsideDropDown.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).inside;
                    app.GeometryWallBoundaryEditField.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).boundary;
                    
                elseif length(selectedNodes) == 5
                    app.GeometryRoomPanel.Visible = 'off';
                    app.GeometryWallPanel.Visible = 'off';
                    app.GeometryWindowDoorPanel.Visible = 'on';
                    
                    if selectedNodes(4) == 1
                        app.GeometryWindowDoorDirtyFacEditField.Enable = 'on';
                        app.GeometryWindowDoorPsi1EditField.Enable = 'on';
                        app.GeometryWindowDoorPsi2EditField.Enable = 'on';
                        app.GeometryWindowDoorPsi3EditField.Enable = 'on';
                        app.GeometryWindowDoorPsi4EditField.Enable = 'on';
                        app.GeometryWindowDoorWidthGlassEditField.Enable = 'on';
                        app.GeometryWindowDoorHeightGlassEditField.Enable = 'on';
                        app.GeometryWindowDoorModelControlShadingDropDown.Enable = 'on';
                        app.GeometryWindowDoorShading1EditField.Enable = 'on';
                        app.GeometryWindowDoorShading2EditField.Enable = 'on';
                        app.GeometryWindowDoorShading3EditField.Enable = 'on';
                        app.GeometryWindowDoorShading4EditField.Enable = 'on';
                        app.GeometryWindowDoorShading5EditField.Enable = 'on';
                        app.GeometryWindowDoorShading6EditField.Enable = 'on';
                        app.GeometryWindowDoorShading7EditField.Enable = 'on';
                        app.GeometryWindowDoorShading8EditField.Enable = 'on';
                        app.GeometryWindowDoorShading9EditField.Enable = 'on';
                        app.GeometryWindowDoorShading10EditField.Enable = 'on';
                        app.GeometryWindowDoorShading11EditField.Enable = 'on';
                        app.GeometryWindowDoorShading12EditField.Enable = 'on';
                        
                        app.GeometryWindowDoorNameEditField.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).name;
                        app.GeometryWindowDoorWidthEditField.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).width;
                        app.GeometryWindowDoorHeightEditField.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).height;
                        app.GeometryWindowDoorConstructionEditField.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).construction;
                        app.GeometryWindowDoorViewFacEditField.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).view_factor;
                        app.GeometryWindowDoorAmbFactorEditField.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).amb_factor;
                        app.GeometryWindowDoorModelConstructionDropDown.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).model_cons;
                        app.GeometryWindowDoorDirtyFacEditField.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).fd;
                        app.GeometryWindowDoorPsi1EditField.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).psi_i(1);
                        app.GeometryWindowDoorPsi3EditField.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).psi_i(2);
                        app.GeometryWindowDoorPsi2EditField.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).psi_i(3);
                        app.GeometryWindowDoorPsi4EditField.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).psi_i(4);
                        app.GeometryWindowDoorWidthGlassEditField.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).width_glass;
                        app.GeometryWindowDoorHeightGlassEditField.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).height;
                        app.GeometryWindowDoorModelControlShadingDropDown.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).control_s;
                        app.GeometryWindowDoorModelControlOpeningDropDown.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).control_i;
                        
                        if length(building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).fsvalue) == 13
                            app.GeometryWindowDoorShading1EditField.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).fsvalue(2);
                            app.GeometryWindowDoorShading2EditField.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).fsvalue(3);
                            app.GeometryWindowDoorShading3EditField.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).fsvalue(4);
                            app.GeometryWindowDoorShading4EditField.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).fsvalue(5);
                            app.GeometryWindowDoorShading5EditField.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).fsvalue(6);
                            app.GeometryWindowDoorShading6EditField.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).fsvalue(7);
                            app.GeometryWindowDoorShading7EditField.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).fsvalue(8);
                            app.GeometryWindowDoorShading8EditField.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).fsvalue(9);
                            app.GeometryWindowDoorShading9EditField.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).fsvalue(10);
                            app.GeometryWindowDoorShading10EditField.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).fsvalue(11);
                            app.GeometryWindowDoorShading11EditField.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).fsvalue(12);
                            app.GeometryWindowDoorShading12EditField.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).fsvalue(13);
                        else
                            app.GeometryWindowDoorShading1EditField.Value = -1;
                            app.GeometryWindowDoorShading2EditField.Value = -1;
                            app.GeometryWindowDoorShading3EditField.Value = -1;
                            app.GeometryWindowDoorShading4EditField.Value = -1;
                            app.GeometryWindowDoorShading5EditField.Value = -1;
                            app.GeometryWindowDoorShading6EditField.Value = -1;
                            app.GeometryWindowDoorShading7EditField.Value = -1;
                            app.GeometryWindowDoorShading8EditField.Value = -1;
                            app.GeometryWindowDoorShading9EditField.Value = -1;
                            app.GeometryWindowDoorShading10EditField.Value = -1;
                            app.GeometryWindowDoorShading11EditField.Value = -1;
                            app.GeometryWindowDoorShading12EditField.Value = -1;
                        end
                        
                        app.GeometryWindowDoorModelConstructionDropDown.Items = {'TF', 'not used', 'TF w shading'};
                        app.GeometryWindowDoorModelConstructionDropDown.ItemsData = [0, 1, 2];
                    else
                        app.GeometryWindowDoorDirtyFacEditField.Enable = 'off';
                        app.GeometryWindowDoorPsi1EditField.Enable = 'off';
                        app.GeometryWindowDoorPsi2EditField.Enable = 'off';
                        app.GeometryWindowDoorPsi3EditField.Enable = 'off';
                        app.GeometryWindowDoorPsi4EditField.Enable = 'off';
                        app.GeometryWindowDoorWidthGlassEditField.Enable = 'off';
                        app.GeometryWindowDoorHeightGlassEditField.Enable = 'off';
                        app.GeometryWindowDoorModelControlShadingDropDown.Enable = 'off';
                        app.GeometryWindowDoorShading1EditField.Enable = 'off';
                        app.GeometryWindowDoorShading2EditField.Enable = 'off';
                        app.GeometryWindowDoorShading3EditField.Enable = 'off';
                        app.GeometryWindowDoorShading4EditField.Enable = 'off';
                        app.GeometryWindowDoorShading5EditField.Enable = 'off';
                        app.GeometryWindowDoorShading6EditField.Enable = 'off';
                        app.GeometryWindowDoorShading7EditField.Enable = 'off';
                        app.GeometryWindowDoorShading8EditField.Enable = 'off';
                        app.GeometryWindowDoorShading9EditField.Enable = 'off';
                        app.GeometryWindowDoorShading10EditField.Enable = 'off';
                        app.GeometryWindowDoorShading11EditField.Enable = 'off';
                        app.GeometryWindowDoorShading12EditField.Enable = 'off';
                        
                        app.GeometryWindowDoorNameEditField.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).doors(selectedNodes(5)).name;
                        app.GeometryWindowDoorWidthEditField.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).doors(selectedNodes(5)).width;
                        app.GeometryWindowDoorHeightEditField.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).doors(selectedNodes(5)).height;
                        app.GeometryWindowDoorConstructionEditField.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).doors(selectedNodes(5)).construction;
                        app.GeometryWindowDoorViewFacEditField.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).doors(selectedNodes(5)).view_factor;
                        app.GeometryWindowDoorAmbFactorEditField.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).doors(selectedNodes(5)).amb_factor;
                        app.GeometryWindowDoorModelConstructionDropDown.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).doors(selectedNodes(5)).model_cons;
                        app.GeometryWindowDoorModelControlOpeningDropDown.Value = building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).doors(selectedNodes(5)).control_i;
                        
                        app.GeometryWindowDoorModelConstructionDropDown.Items = {'UA', 'RC', 'hygrothermal'};
                        app.GeometryWindowDoorModelConstructionDropDown.ItemsData = [0, 1, 2];
                    end
                else
                    app.GeometryRoomPanel.Visible = 'off';
                    app.GeometryWallPanel.Visible = 'off';
                    app.GeometryWindowDoorPanel.Visible = 'off';
                end
                
            else
                app.GeometryPanel.Visible = 'off';
            end
        end

        % Button pushed function: ResultOverviewButton
        function ResultOverviewButtonPushed(app, event)
            try
                d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                value = app.SimulationNumberDropDown.Value;
                evalin('base',['building.result.info(' num2str(value) ',building)']);
                close(d);
                uiconfirm(app.carnotUIBKUIFigure,'Calculation done!','Done!','Options',{'OK'},'Icon','success');
            catch ME
                ME
                close(d);
                uiconfirm(app.carnotUIBKUIFigure,'Calculation failed (partly)!','Failed!','Options',{'OK'},'Icon','error');
            end
        end

        % Button pushed function: ResultPlotButton
        function ResultPlotButtonPushed(app, event)
            try
                d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                value = app.SimulationNumberDropDown.Value;
                evalin('base',['building.result.plot(' num2str(value) ',building)']);
            close(d);
                uiconfirm(app.carnotUIBKUIFigure,'Calculation done!','Done!','Options',{'OK'},'Icon','success');
            catch ME
                ME
                close(d);
                uiconfirm(app.carnotUIBKUIFigure,'Calculation failed (partly)!','Failed!','Options',{'OK'},'Icon','error');
            end
        end

        % Callback function
        function BuildingPlotButtonPushed(app, event)
            try
                d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                evalin('base','plot(building.get_building())');
                close(d);
            catch ME
                ME
                close(d);
            end
        end

        % Button pushed function: ResultCalculateCharacteristicsButton
        function ResultCalculateCharacteristicsButtonPushed(app, event)
            d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
            
            global building
            value = app.SimulationNumberDropDown.Value;
            
            try
                [En Pow area] = building.result.different_monthly_energies(value, building, 1, 1);
                close(d);
                
                app.ResultAreaEditField.Value = sum(area);
                app.ResultHeatingDemandEditField.Value = sum(En.H_M_A_tot);
                app.ResultHeatingDemandSpecEditField.Value = sum(En.H_M_tot);
                app.ResultCoolingDemandEditField.Value = sum(En.C_M_A_tot);
                app.ResultCoolingDemandSpecEditField.Value = sum(En.C_M_tot);
                
                app.ResultAreaEditField.Visible = 'on';
                app.ResultHeatingDemandEditField.Visible = 'on';
                app.ResultHeatingDemandSpecEditField.Visible = 'on';
                app.ResultCoolingDemandEditField.Visible = 'on';
                app.ResultCoolingDemandSpecEditField.Visible = 'on';
                
                uiconfirm(app.carnotUIBKUIFigure,'Calculation done!','Done!','Options',{'OK'},'Icon','success');
            catch ME
                ME
                close(d);
                uiconfirm(app.carnotUIBKUIFigure,['There was an error during the calculation. Maybe the simulation results are less than one year. Try to re-run the simulation or check the data manually!'],'Calculation not possible!');
                
                app.ResultAreaEditField.Visible = 'off';
                app.ResultHeatingDemandEditField.Visible = 'off';
                app.ResultHeatingDemandSpecEditField.Visible = 'off';
                app.ResultCoolingDemandEditField.Visible = 'off';
                app.ResultCoolingDemandSpecEditField.Visible = 'off';
            end
            
        end

        % Button pushed function: ResultTemperatureHumidityCO2Button
        function ResultTemperatureHumidityCO2ButtonPushed(app, event)
            try
                d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                value = app.SimulationNumberDropDown.Value;
                if app.ResultOverviewplotsCheckBox.Value
                    evalin('base',['building.result.plot_zones_thC(' num2str(value) ', building, ''rel'', ''ppm'', 1)']);
                else
                    evalin('base',['building.result.plot_zones_thC(' num2str(value) ', building, ''rel'', ''ppm'')']);
                end
                close(d);
                uiconfirm(app.carnotUIBKUIFigure,'Calculation done!','Done!','Options',{'OK'},'Icon','success');
            catch ME
                ME
                close(d);
                uiconfirm(app.carnotUIBKUIFigure,'Calculation failed (partly)!','Failed!','Options',{'OK'},'Icon','error');
            end
        end

        % Button pushed function: ResultTemperatureBoundaryButton
        function ResultTemperatureBoundaryButtonPushed(app, event)
            try
                d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                value = app.SimulationNumberDropDown.Value;
                evalin('base',['building.result.plot_temp_amb_buil_soil(' num2str(value) ', building)']);
                close(d);
                uiconfirm(app.carnotUIBKUIFigure,'Calculation done!','Done!','Options',{'OK'},'Icon','success');
            catch ME
                ME
                close(d);
                uiconfirm(app.carnotUIBKUIFigure,'Calculation failed (partly)!','Failed!','Options',{'OK'},'Icon','error');
            end
        end

        % Button pushed function: ResultTemperatureArrangedButton
        function ResultTemperatureArrangedButtonPushed(app, event)
            try
                d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                value = app.SimulationNumberDropDown.Value;
                evalin('base',['building.result.plot_temperature_arranged_hours(' num2str(value) ', building)']);
                close(d);
                uiconfirm(app.carnotUIBKUIFigure,'Calculation done!','Done!','Options',{'OK'},'Icon','success');
            catch ME
                ME
                close(d);
                uiconfirm(app.carnotUIBKUIFigure,'Calculation failed (partly)!','Failed!','Options',{'OK'},'Icon','error');
            end
        end

        % Button pushed function: ResultEnergyDemandButton
        function ResultEnergyDemandButtonPushed(app, event)
            try
                d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                value = app.SimulationNumberDropDown.Value;
                if app.ResultOverviewplotsCheckBox.Value
                    evalin('base',['building.result.energy_demand(' num2str(value) ', building, 1)']);
                else
                    evalin('base',['building.result.energy_demand(' num2str(value) ', building)']);
                end
                close(d);
                uiconfirm(app.carnotUIBKUIFigure,'Calculation done!','Done!','Options',{'OK'},'Icon','success');
            catch ME
                ME
                close(d);
                uiconfirm(app.carnotUIBKUIFigure,'Calculation failed (partly)!','Failed!','Options',{'OK'},'Icon','error');
            end
        end

        % Button pushed function: ResultMonthlyEnergyBalanceButton
        function ResultMonthlyEnergyBalanceButtonPushed(app, event)
            try
                d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                value = app.SimulationNumberDropDown.Value;
                if app.ResultOverviewplotsCheckBox.Value
                    evalin('base',['building.result.different_monthly_energies(' num2str(value) ', building, 1)']);
                else
                    evalin('base',['building.result.different_monthly_energies(' num2str(value) ', building)']);
                end
                close(d);
                uiconfirm(app.carnotUIBKUIFigure,'Calculation done!','Done!','Options',{'OK'},'Icon','success');
            catch ME
                ME
                close(d);
                uiconfirm(app.carnotUIBKUIFigure,'Calculation failed (partly)!','Failed!','Options',{'OK'},'Icon','error');
            end
        end

        % Button pushed function: ResultMonthlyLossesGainsButton
        function ResultMonthlyLossesGainsButtonPushed(app, event)
            try
                d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                value = app.SimulationNumberDropDown.Value;
                evalin('base',['building.result.plot_monthly_losses_gains(' num2str(value) ', building)']);
                close(d);
                uiconfirm(app.carnotUIBKUIFigure,'Calculation done!','Done!','Options',{'OK'},'Icon','success');
            catch ME
                ME
                close(d);
                uiconfirm(app.carnotUIBKUIFigure,'Calculation failed (partly)!','Failed!','Options',{'OK'},'Icon','error');
            end
        end

        % Button pushed function: ResultHeatingandCoolingPowerButton
        function ResultHeatingandCoolingPowerButtonPushed(app, event)
            try
                d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                value = app.SimulationNumberDropDown.Value;
                evalin('base',['building.result.plot_heat_cool_power(' num2str(value) ', building)']);
                close(d);
                uiconfirm(app.carnotUIBKUIFigure,'Calculation done!','Done!','Options',{'OK'},'Icon','success');
            catch ME
                ME
                close(d);
                uiconfirm(app.carnotUIBKUIFigure,'Calculation failed (partly)!','Failed!','Options',{'OK'},'Icon','error');
            end
        end

        % Button pushed function: ResultHeatLoadCurveButton
        function ResultHeatLoadCurveButtonPushed(app, event)
            try
                d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                value = app.SimulationNumberDropDown.Value;
                evalin('base',['building.result.plot_heat_temp_hours(' num2str(value) ', building)']);
                close(d);
                uiconfirm(app.carnotUIBKUIFigure,'Calculation done!','Done!','Options',{'OK'},'Icon','success');
            catch ME
                ME
                close(d);
                uiconfirm(app.carnotUIBKUIFigure,'Calculation failed (partly)!','Failed!','Options',{'OK'},'Icon','error');
            end
        end

        % Value changed function: ResultDescriptionEditField
        function ResultDescriptionEditFieldValueChanged(app, event)
            global building
            
            value = app.SimulationNumberDropDown.Value;
            building.result(value).description = app.ResultDescriptionEditField.Value;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
            try
                evalin('base','building.save;');
                close(d);
                uiconfirm(app.carnotUIBKUIFigure,'Building model saved!','Done!','Options',{'OK'},'Icon','success');
            catch
                close(d);
                uiconfirm(app.carnotUIBKUIFigure,'Saving failed!','Failed!','Options',{'OK'},'Icon','error');
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
            
            global building
            selectedTab = app.TabGroup.SelectedTab;
            
            if ~isempty(building.name)
                switch selectedTab.Title
                    case 'Simulate'
                        app.SimulateGeometryDropDown.Items = {};
                        app.SimulateGeometryDropDown.ItemsData = [];
                        for jj = 1:length(building.geometry)
                            if ~strcmp(building.geometry(jj).name,'')
                                app.SimulateGeometryDropDown.Items{end+1} = [building.geometry(jj).name ' (variant: ' num2str(jj) ')'];
                                app.SimulateGeometryDropDown.ItemsData{end+1} = jj;
                            end
                        end
                        app.SimulateGeometryDropDown.Value=building.variant_geometry;
                        
                        app.SimulateConstructionDropDown.Items = {};
                        app.SimulateConstructionDropDown.ItemsData = [];
                        for jj = 1:length(building.construction)
                            if ~strcmp(building.construction(jj).name,'')
                                app.SimulateConstructionDropDown.Items{end+1} = [building.construction(jj).name ' (variant: ' num2str(jj) ')'];
                                app.SimulateConstructionDropDown.ItemsData{end+1} = jj;
                            end
                        end
                        app.SimulateConstructionDropDown.Value=building.variant_construction;

                        
                        app.SimulateGainDropDown.Items = {};
                        app.SimulateGainDropDown.ItemsData = [];
                        for jj = 1:length(building.gains)
                            if ~strcmp(building.gains(jj).name,'')
                                app.SimulateGainDropDown.Items{end+1} = [building.gains(jj).name ' (variant: ' num2str(jj) ')'];
                                app.SimulateGainDropDown.ItemsData{end+1} = jj;
                            end
                        end
                        app.SimulateGainDropDown.Value=building.variant_gains;
                        
                        app.SimulateBoundaryDropDown.Items = {};
                        app.SimulateBoundaryDropDown.ItemsData = [];
                        for jj = 1:length(building.boundary)
                            if ~strcmp(building.boundary(jj).name,'')
                                app.SimulateBoundaryDropDown.Items{end+1} = [building.boundary(jj).name ' (variant: ' num2str(jj) ')'];
                                app.SimulateBoundaryDropDown.ItemsData{end+1} = jj;
                            end
                        end
                        app.SimulateBoundaryDropDown.Value=building.variant_boundary;
                        
                        app.SimulateThermalzoneDropDown.Items = {};
                        app.SimulateThermalzoneDropDown.ItemsData = [];
                        for jj = 1:length(building.thermalzone)
                            if ~strcmp(building.thermalzone(jj).name,'')
                                app.SimulateThermalzoneDropDown.Items{end+1} = [building.thermalzone(jj).name ' (variant: ' num2str(jj) ')'];
                                app.SimulateThermalzoneDropDown.ItemsData{end+1} = jj;
                            end
                        end
                        app.SimulateThermalzoneDropDown.Value=building.variant_thermalzone;
                        
                        app.SimulateHVACDropDown.Items = {};
                        app.SimulateHVACDropDown.ItemsData = [];
                        for jj = 1:length(building.hvac)
                            if ~strcmp(building.hvac(jj).name,'')
                                app.SimulateHVACDropDown.Items{end+1} = [building.hvac(jj).name ' (variant: ' num2str(jj) ')'];
                                app.SimulateHVACDropDown.ItemsData{end+1} = jj;
                            end
                        end
                        app.SimulateHVACDropDown.Value=building.variant_hvac;

                        
                        if length(building.result) == 1
                            if building.result(1).number == 0
                                app.SimulateSimulationnumberEditField.Value = 1;
                            else
                                app.SimulateSimulationnumberEditField.Value = length(building.result) + 1;
                            end
                        else
                            app.SimulateSimulationnumberEditField.Value = length(building.result) + 1;
                        end

                        
                        files = evalin('base','dir(''*.slx'')');
                        for jj=1:length(files)
                            filenames{jj} = strrep(files(jj).name,'.slx','');
                        end
                        app.SimulateModelDropDown.Items = filenames;
                        
                    case 'Start'
                        filenames = {};
                        files = evalin('base','dir(''*.xls'')');
                        for jj=1:length(files)
                            filenames{end+1} = files(jj).name;
                        end
                        files = evalin('base','dir(''*.xlsx'')');
                        for jj=1:length(files)
                            filenames{end+1} = files(jj).name;
                        end
                        files = evalin('base','dir(''*.xml'')');
                        for jj=1:length(files)
                            filenames{end+1} = files(jj).name;
                        end
                        app.StartImportwholeBuildingdropList.Items = filenames;
                        
                        
                    case 'Results'
                        app.SimulationNumberDropDown.Items = {};
                        app.SimulationNumberDropDown.ItemsData = [];
                        for jj = 1:length(building.result)
                            if building.result(jj).number > 0
                                app.SimulationNumberDropDown.Items{end+1} = [num2str(building.result(jj).number) ': ' building.result(jj).description];
                                app.SimulationNumberDropDown.ItemsData{end+1} = jj;
                            end
                        end
                        if length(app.SimulationNumberDropDown.Items) > 0
                            SimulationNumberDropDownValueChanged(app,app.SimulationNumberDropDown.ValueChangedFcn)
                        end
                        
                    case 'Geometry'
                        filenames = {};
                        files = evalin('base','dir(''*.xls'')');
                        for jj=1:length(files)
                            filenames{end+1} = files(jj).name;
                        end
                        files = evalin('base','dir(''*.xlsx'')');
                        for jj=1:length(files)
                            filenames{end+1} = files(jj).name;
                        end
                        files = evalin('base','dir(''*.xml'')');
                        for jj=1:length(files)
                            filenames{end+1} = files(jj).name;
                        end
                        app.GeometryImportfromfileDropDown.Items = filenames;
                        
                        if length(building.geometry) == 1
                            if isempty(building.geometry(1).room)
                                app.GeometryTovariantnumberEditField.Value = 1;
                            else
                                app.GeometryTovariantnumberEditField.Value = length(building.geometry) + 1;
                            end
                        else
                            app.GeometryTovariantnumberEditField.Value = length(building.geometry) + 1;
                        end
                        
                        delete(app.GeometryTree.Children)
                        for jj = 1:length(building.geometry)
                            if ~strcmp(building.geometry(jj).name,'')
                                TreeGeometryN1(jj) = uitreenode(app.GeometryTree,'Text',[building.geometry(jj).name ' (Variant ' num2str(jj) ')'],'NodeData',[jj]);
                                for jk = 1:length(building.geometry(jj).room)
                                    TreeGeometryN11(jj,jk) = uitreenode(TreeGeometryN1(jj),'Text',[building.geometry(jj).room(jk).name],'NodeData',[jj jk]);
                                    for jl = 1:length(building.geometry(jj).room(jk).wall)
                                        TreeGeometryN111(jj,jk,jl) = uitreenode(TreeGeometryN11(jj,jk),'Text',[building.geometry(jj).room(jk).wall(jl).name],'NodeData',[jj jk jl]);
                                        if ~isempty(building.geometry(jj).room(jk).wall(jl).windows)
                                            TreeGeometryN1111(jj,jk,jl,1) = uitreenode(TreeGeometryN111(jj,jk,jl),'Text','windows','NodeData',[jj jk jl 1]);
                                            for jm = 1:length(building.geometry(jj).room(jk).wall(jl).windows)
                                                TreeGeometryN11111(jj,jk,jl,1,jm) = uitreenode(TreeGeometryN1111(jj,jk,jl,1),'Text',[building.geometry(jj).room(jk).wall(jl).windows(jm).name],'NodeData',[jj jk jl 1 jm]);
                                            end
                                        end
                                        if ~isempty(building.geometry(jj).room(jk).wall(jl).doors)
                                            TreeGeometryN1111(jj,jk,jl,2) = uitreenode(TreeGeometryN111(jj,jk,jl),'Text','doors','NodeData',[jj jk jl 2]);
                                            for jm = 1:length(building.geometry(jj).room(jk).wall(jl).doors)
                                                TreeGeometryN11111(jj,jk,jl,2,jm) = uitreenode(TreeGeometryN1111(jj,jk,jl,2),'Text',[building.geometry(jj).room(jk).wall(jl).doors(jm).name],'NodeData',[jj jk jl 2 jm]);
                                            end
                                        end
                                    end
                                end
                            end
                        end
                        GeometryTreeSelectionChanged(app, app.GeometryTree.SelectionChangedFcn)
                        
                        
                    case 'Construction'
                        filenames = {};
                        files = evalin('base','dir(''*.xls'')');
                        for jj=1:length(files)
                            filenames{end+1} = files(jj).name;
                        end
                        files = evalin('base','dir(''*.xlsx'')');
                        for jj=1:length(files)
                            filenames{end+1} = files(jj).name;
                        end
                        files = evalin('base','dir(''*.xml'')');
                        for jj=1:length(files)
                            filenames{end+1} = files(jj).name;
                        end
                        app.ConstructionImportfromfileDropDown.Items = filenames;
                        
                        if length(building.construction) == 1
                            if isempty(building.construction(1).structure)
                                app.ConstructionTovariantnumberEditField.Value = 1;
                            else
                                app.ConstructionTovariantnumberEditField.Value = length(building.construction) + 1;
                            end
                        else
                            app.ConstructionTovariantnumberEditField.Value = length(building.construction) + 1;
                        end
                        
                        delete(app.ConstructionTree.Children)
                        for jj = 1:length(building.construction)
                            if ~strcmp(building.construction(jj).name,'')
                                TreeConstructionN1(jj) = uitreenode(app.ConstructionTree,'Text',[building.construction(jj).name ' (Variant ' num2str(jj) ')'],'NodeData',[jj]);
                                for jk = 1:length(building.construction(jj).structure)
                                    TreeConstructionN11(jj,jk) = uitreenode(TreeConstructionN1(jj),'Text',[building.construction(jj).structure(jk).name],'NodeData',[jj jk]);
                                end
                            end
                        end
                        ConstructionTreeSelectionChanged(app, app.ConstructionTree.SelectionChangedFcn)
                        
                        
                    case 'Gains'
                        filenames = {};
                        files = evalin('base','dir(''*.xls'')');
                        for jj=1:length(files)
                            filenames{end+1} = files(jj).name;
                        end
                        files = evalin('base','dir(''*.xlsx'')');
                        for jj=1:length(files)
                            filenames{end+1} = files(jj).name;
                        end
                        files = evalin('base','dir(''*.xml'')');
                        for jj=1:length(files)
                            filenames{end+1} = files(jj).name;
                        end
                        app.GainsImportfromfileDropDown.Items = filenames;
                        
                        if length(building.gains) == 1
                            if isempty(building.gains(1).gain)
                                app.GainsTovariantnumberEditField.Value = 1;
                            else
                                app.GainsTovariantnumberEditField.Value = length(building.gains) + 1;
                            end
                        else
                            app.GainsTovariantnumberEditField.Value = length(building.gains) + 1;
                        end
                        
                        delete(app.GainsTree.Children)
                        for jj = 1:length(building.gains)
                            if ~strcmp(building.gains(jj).name,'')
                                TreeGainsN1(jj) = uitreenode(app.GainsTree,'Text',[building.gains(jj).name ' (Variant ' num2str(jj) ')'],'NodeData',[jj]);
                                TreeGainsN11(jj,1) = uitreenode(TreeGainsN1(jj),'Text','gains','NodeData',[jj 1]);
                                TreeGainsN11(jj,2) = uitreenode(TreeGainsN1(jj),'Text','gain mapping (informative)','NodeData',[jj 2]);
                                for jk = 1:length(building.gains(jj).gain)
                                    TreeGainsN111(jj,1,jk) = uitreenode(TreeGainsN11(jj,1),'Text',[building.gains(jj).gain(jk).name],'NodeData',[jj 1 jk]);
                                end
                                for jk = 1:length(building.gains(jj).gain_to_zone)
                                    TreeGainsN111(jj,2,jk) = uitreenode(TreeGainsN11(jj,2),'Text',[building.gains(jj).gain_to_zone(jk).name_gains ' -> zone ' num2str(building.gains(jj).gain_to_zone(jk).number_of_zone(1))],'NodeData',[jj 2 jk]);
                                end
                            end
                        end
                        GainsTreeSelectionChanged(app, app.GainsTree.SelectionChangedFcn)
                        
                        
                    case 'Boundary'
                        filenames = {};
                        files = evalin('base','dir(''*.xls'')');
                        for jj=1:length(files)
                            filenames{end+1} = files(jj).name;
                        end
                        files = evalin('base','dir(''*.xlsx'')');
                        for jj=1:length(files)
                            filenames{end+1} = files(jj).name;
                        end
                        files = evalin('base','dir(''*.xml'')');
                        for jj=1:length(files)
                            filenames{end+1} = files(jj).name;
                        end
                        app.BoundaryImportfromfileDropDown.Items = filenames;
                        
                        if length(building.boundary) == 1
                            if isempty(building.boundary(1).weather)
                                app.BoundaryTovariantnumberEditField.Value = 1;
                            else
                                app.BoundaryTovariantnumberEditField.Value = length(building.boundary) + 1;
                            end
                        else
                            app.BoundaryTovariantnumberEditField.Value = length(building.boundary) + 1;
                        end
                        
                        delete(app.BoundaryTree.Children)
                        for jj = 1:length(building.boundary)
                            if ~strcmp(building.boundary(jj).name,'')
                                TreeBoundaryN1(jj) = uitreenode(app.BoundaryTree,'Text',[building.boundary(jj).name ' (Variant ' num2str(jj) ')'],'NodeData',jj);
                                TreeBoundaryN11(jj,1) = uitreenode(TreeBoundaryN1(jj),'Text','weather','NodeData',[jj 1]);
                                TreeBoundaryN11(jj,2) = uitreenode(TreeBoundaryN1(jj),'Text','ground','NodeData',[jj 2]);
                                TreeBoundaryN11(jj,3) = uitreenode(TreeBoundaryN1(jj),'Text','neighbour','NodeData',[jj 3]);
                                for jk = 1:length(building.boundary(jj).weather)
                                    TreeBoundaryN111(jj,1,jk) = uitreenode(TreeBoundaryN11(jj,1),'Text',[building.boundary(jj).weather(jk).name],'NodeData',[jj 1 jk]);
                                end
                                for jk = 1:length(building.boundary(jj).ground)
                                    TreeBoundaryN111(jj,2,jk) = uitreenode(TreeBoundaryN11(jj,2),'Text',[building.boundary(jj).ground(jk).name],'NodeData',[jj 2 jk]);
                                end
                                for jk = 1:length(building.boundary(jj).neighbour)
                                    TreeBoundaryN111(jj,3,jk) = uitreenode(TreeBoundaryN11(jj,3),'Text',[building.boundary(jj).neighbour(jk).name],'NodeData',[jj 3 jk]);
                                end
                            end
                        end
                        BoundaryTreeSelectionChanged(app, app.BoundaryTree.SelectionChangedFcn)
                        
                        
                    case 'Thermalzone'
                        filenames = {};
                        files = evalin('base','dir(''*.xls'')');
                        for jj=1:length(files)
                            filenames{end+1} = files(jj).name;
                        end
                        files = evalin('base','dir(''*.xlsx'')');
                        for jj=1:length(files)
                            filenames{end+1} = files(jj).name;
                        end
                        files = evalin('base','dir(''*.xml'')');
                        for jj=1:length(files)
                            filenames{end+1} = files(jj).name;
                        end
                        app.ThermalzoneImportfromfileDropDown.Items = filenames;
                        
                        if length(building.thermalzone) == 1
                            if isempty(building.thermalzone(1).zone(1).rooms)
                                app.ThermalzoneTovariantnumberEditField.Value = 1;
                            else
                                app.ThermalzoneTovariantnumberEditField.Value = length(building.thermalzone) + 1;
                            end
                        else
                            app.ThermalzoneTovariantnumberEditField.Value = length(building.thermalzone) + 1;
                        end
                        
                        delete(app.ThermalzoneTree.Children)
                        for jj = 1:length(building.thermalzone)
                            if ~strcmp(building.thermalzone(jj).name,'')
                                TreeThermalzoneN1(jj) = uitreenode(app.ThermalzoneTree,'Text',[building.thermalzone(jj).name ' (Variant ' num2str(jj) ')'],'NodeData',jj);
                                TreeThermalzoneN11(jj,1) = uitreenode(TreeThermalzoneN1(jj),'Text','zones','NodeData',[jj 1]);
                                TreeThermalzoneN11(jj,2) = uitreenode(TreeThermalzoneN1(jj),'Text','intersections','NodeData',[jj 2]);
                                for jk = 1:length(building.thermalzone(jj).zone)
                                    TreeThermalzoneN111(jj,1,jk) = uitreenode(TreeThermalzoneN11(jj,1),'Text',[building.thermalzone(jj).zone(jk).name],'NodeData',[jj 1 jk]);
                                end
                                for jk = 1:size(building.thermalzone(jj).intersection,1)
                                    TreeThermalzoneN111(jj,2,jk) = uitreenode(TreeThermalzoneN11(jj,2),'Text',[building.thermalzone(jj).zone(jk).name],'NodeData',[jj 2 jk]);
                                    for jl = 2:size(building.thermalzone(jj).intersection,2)
                                        if ~isempty(building.thermalzone(jj).intersection{jk,jl})
                                            TreeThermalzoneN1111(jj,2,jk,jl) = uitreenode(TreeThermalzoneN111(jj,2,jk),'Text',[building.thermalzone(jj).zone(jl).name],'NodeData',[jj 2 jk jl]);
                                        end
                                    end
                                end
                            end
                        end
                        ThermalzoneTreeSelectionChanged(app, app.ThermalzoneTree.SelectionChangedFcn)
                        
                        
                    case 'HVAC'
                        filenames = {};
                        files = evalin('base','dir(''*.xls'')');
                        for jj=1:length(files)
                            filenames{end+1} = files(jj).name;
                        end
                        files = evalin('base','dir(''*.xlsx'')');
                        for jj=1:length(files)
                            filenames{end+1} = files(jj).name;
                        end
                        files = evalin('base','dir(''*.xml'')');
                        for jj=1:length(files)
                            filenames{end+1} = files(jj).name;
                        end
                        files = evalin('base','dir(''*.mat'')');
                        for jj=1:length(files)
                            filenames{end+1} = files(jj).name;
                        end
                        app.HVACImportfromfileDropDown.Items = filenames;
                        
                        filenames = {};
                        files = evalin('base','dir(''*.mat'')');
                        for jj=1:length(files)
                            filenames{end+1} = files(jj).name;
                        end
                        app.HVACSystemImportFileDropDown.Items = filenames;
                        
                        if length(building.hvac) == 1
                            if isempty(building.hvac(1).system)
                                app.HVACTovariantnumberEditField.Value = 1;
                            else
                                app.HVACTovariantnumberEditField.Value = length(building.hvac) + 1;
                            end
                        else
                            app.HVACTovariantnumberEditField.Value = length(building.hvac) + 1;
                        end
                        
                        delete(app.HVACTree.Children)
                        for jj = 1:length(building.hvac)
                            if ~strcmp(building.hvac(jj).name,'')
                                TreeHVACN1(jj) = uitreenode(app.HVACTree,'Text',[building.hvac(jj).name ' (Variant ' num2str(jj) ')'],'NodeData',[jj]);
                                if ~isempty(building.hvac(jj).system)
                                    for jk = 1:length(building.hvac(jj).system)
                                        TreeHVACN11(jj,jk) = uitreenode(TreeHVACN1(jj),'Text',['(' num2str(building.hvac(jj).system(jk).number) ') ' building.hvac(jj).system(jk).name],'NodeData',[jj jk]);
                                    end
                                end
                            end
                        end
                        HVACTreeSelectionChanged(app, app.HVACTree.SelectionChangedFcn)
                end
            end
            pause(0.001)
            close(d);
        end

        % Button pushed function: SimulateStartSimulationButton
        function SimulateStartSimulationButtonPushed(app, event)
            global building NUMBEROFZONES PLOT
            sim_nr = app.SimulateSimulationnumberEditField.Value;
            
            building.variant_geometry = app.SimulateGeometryDropDown.Value;
            building.variant_construction = app.SimulateConstructionDropDown.Value;
            building.variant_gains = app.SimulateGainDropDown.Value;
            building.variant_boundary = app.SimulateBoundaryDropDown.Value;
            building.variant_thermalzone = app.SimulateThermalzoneDropDown.Value;
            building.variant_hvac = app.SimulateHVACDropDown.Value;
            
            building.model = app.SimulateModelDropDown.Value;
            model_name = strrep(building.model,'.slx','');
            
            try
                d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                
                %% update thermalzone
                try
                    building.thermalzone(building.variant_thermalzone) = building.thermalzone(building.variant_thermalzone).update_thermalzone(building.geometry(building.variant_geometry),building,building.variant_gains);
                catch
                    err = ('An error occured during the update of the thermalzones for the simulation.');
                    close(d);
                    uiconfirm(app.carnotUIBKUIFigure,err,'Failed!','Options',{'OK'},'Icon','error');
                    return
                end
                
                %% check building
                % check zones
                b2t = building.get_building();
                
                for jj = 1:NUMBEROFZONES
                    % ZONES
                    if length(b2t.thermalzone.zone(jj).name) > 4 && strcmp(b2t.thermalzone.zone(jj).name(1:5), 'EMPTY')
                        if ~isempty(building.get_building().thermalzone.zone(jj).matrix_wd) || ~isempty(building.get_building().thermalzone.zone(jj).matrix_wi) || ~isempty(building.get_building().thermalzone.zone(jj).matrix_gains)
                            err = ['The zone ' num2str(jj) ' should be empty, but it seems not to be the case. Check the name of the zone. EMTPY is not allowed as a key-word.'];
                            close(d);
                            uiconfirm(app.carnotUIBKUIFigure,err,'Failed!','Options',{'OK'},'Icon','error');
                            return
                        end
                    else
                        for jk = 1:size(b2t.thermalzone.zone(jj).matrix_wd,1)
                            if isempty(b2t.construction.get_structure(b2t.thermalzone.zone(jj).matrix_wd{jk,1}))
                                err = ['The construction ''' b2t.thermalzone.zone(jj).matrix_wd{jk,1} ''' is not defined! Please define it in the variant ' num2str(building.variant_construction) '.'];
                                close(d);
                                uiconfirm(app.carnotUIBKUIFigure,err,'Failed!','Options',{'OK'},'Icon','error');
                                return
                            end
                            if ~strcmp(b2t.thermalzone.zone(jj).matrix_wd{jk,3},'AMBIENT') && ~strcmp(b2t.thermalzone.zone(jj).matrix_wd{jk,3},'INTERNAL') && ~strcmp(b2t.thermalzone.zone(jj).matrix_wd{jk,3},'GROUND') && ~strcmp(b2t.thermalzone.zone(jj).matrix_wd{jk,3},'NEIGHBOUR') && ~strcmp(b2t.thermalzone.zone(jj).matrix_wd{jk,3},'GROUND_1') && ~strcmp(b2t.thermalzone.zone(jj).matrix_wd{jk,3},'NEIGHBOUR_1') && ~strcmp(b2t.thermalzone.zone(jj).matrix_wd{jk,3},'GROUND_2') && ~strcmp(b2t.thermalzone.zone(jj).matrix_wd{jk,3},'NEIGHBOUR_2') && ~strcmp(b2t.thermalzone.zone(jj).matrix_wd{jk,3},'GROUND_3') && ~strcmp(b2t.thermalzone.zone(jj).matrix_wd{jk,3},'NEIGHBOUR_3') && ~strcmp(b2t.thermalzone.zone(jj).matrix_wd{jk,3},'GROUND_4') && ~strcmp(b2t.thermalzone.zone(jj).matrix_wd{jk,3},'NEIGHBOUR_4') && ~strcmp(b2t.thermalzone.zone(jj).matrix_wd{jk,3},'GROUND_5') && ~strcmp(b2t.thermalzone.zone(jj).matrix_wd{jk,3},'NEIGHBOUR_5') && ~strcmp(b2t.thermalzone.zone(jj).matrix_wd{jk,3},'GROUND_6') && ~strcmp(b2t.thermalzone.zone(jj).matrix_wd{jk,3},'NEIGHBOUR_6')
                                err = ['The boundary condition ' b2t.thermalzone.zone(jj).matrix_wd{jk,3} ' in zone ' num2str(jj) ' is not well defined! Is the room existing or is there a typing error?'];
                                close(d);
                                uiconfirm(app.carnotUIBKUIFigure,err,'Failed!','Options',{'OK'},'Icon','error');
                                return
                            end
                            if b2t.thermalzone.zone(jj).matrix_wd{jk,2} == 0.0
                                err = ['The area of a wall in the zone ' num2str(jj) ' is zero. This is not allowed!'];
                                close(d);
                                uiconfirm(app.carnotUIBKUIFigure,err,'Failed!','Options',{'OK'},'Icon','error');
                                return
                            end
                        end
                        for jk = 1:size(b2t.thermalzone.zone(jj).matrix_wi,1)
                            if isempty(b2t.construction.get_structure(b2t.thermalzone.zone(jj).matrix_wi{jk,1}))
                                err = ['The construction ''' b2t.thermalzone.zone(jj).matrix_wi{jk,1} ''' is not defined! Please define it in the variant ' num2str(building.variant_construction) '.'];
                                close(d);
                                uiconfirm(app.carnotUIBKUIFigure,err,'Failed!','Options',{'OK'},'Icon','error');
                                return
                            end
                            if ~strcmp(b2t.thermalzone.zone(jj).matrix_wi{jk,4},'AMBIENT')
                                err = ['A window in the zone ' num2str(jj) ' is not well defined. It has not the boundary condition AMBIENT, but ' b2t.thermalzone.zone(jj).matrix_wi{jk,4} '.'];
                                close(d);
                                uiconfirm(app.carnotUIBKUIFigure,err,'Failed!','Options',{'OK'},'Icon','error');
                                return
                            end
                            if b2t.thermalzone.zone(jj).matrix_wi{jk,2} == 0.0
                                err = ['The area of a window in the zone ' num2str(jj) ' is zero. This is not allowed!'];
                                close(d);
                                uiconfirm(app.carnotUIBKUIFigure,err,'Failed!','Options',{'OK'},'Icon','error');
                                return
                            end
                        end
                    end
                    
                    % INTERSECTIONS
                    if jj < 10
                        for jjj = 1:NUMBEROFZONES
                            if ~isempty(b2t.thermalzone.intersection{jj,jjj})
                                if length(b2t.thermalzone.intersection{jj,jjj}.name) > 4 && strcmp(b2t.thermalzone.intersection{jj,jjj}.name(1:5), 'EMPTY')
                                    if ~isempty(building.get_building().thermalzone.intersection{jj,jjj}.matrix_wd)
                                        err = ['The intersection (' num2str(jj) ',' num2str(jjj) ') should be empty, but it seems not to be the case. Check the name of the zone. EMTPY is not allowed as a key-word.'];
                                        close(d);
                                        uiconfirm(app.carnotUIBKUIFigure,err,'Failed!','Options',{'OK'},'Icon','error');
                                        return
                                    end
                                else
                                    for jk = 1:size(b2t.thermalzone.intersection{jj,jjj}.matrix_wd,1)
                                        if isempty(b2t.construction.get_structure(b2t.thermalzone.intersection{jj,jjj}.matrix_wd{jk,1}))
                                            err = ['The construction ''' b2t.thermalzone.intersection{jj,jjj}.matrix_wd{jk,1} ''' is not defined! Please define it in the variant ' num2str(building.variant_construction) '.'];
                                            close(d);
                                            uiconfirm(app.carnotUIBKUIFigure,err,'Failed!','Options',{'OK'},'Icon','error');
                                            return
                                        end
                                        if b2t.thermalzone.intersection{jj,jjj}.matrix_wd{jk,2} == 0.0
                                            err = ['The area of a wall in the intersection (' num2str(jj) ',' num2str(jjj) ') is zero. This is not allowed!'];
                                            close(d);
                                            uiconfirm(app.carnotUIBKUIFigure,err,'Failed!','Options',{'OK'},'Icon','error');
                                            return
                                        end
                                    end
                                end
                            end
                        end
                    end
                end
                
                % building solar ratio
                for jj = 1:NUMBEROFZONES
                    if length(b2t.thermalzone.zone(jj).name) > 4 && strcmp(b2t.thermalzone.zone(jj).name(1:5), 'EMPTY')
                        % zone empty!
                    else
                        b2t.thermalzone.check_solar_ratio(jj)
                    end
                end
                
                %% plot matrizes to check
                if PLOT
                    % zones
                    for jj = 1:NUMBEROFZONES
                        b2t.thermalzone.check_matrix(jj)
                    end
                
                    % intersections
                    for jj = 1:NUMBEROFZONES
                        for jk = (jj+1):NUMBEROFZONES
                            b2t.thermalzone.check_matrix(jj,jk)
                        end
                    end
                end
                
                %%
                clear b2t
                close(d);
                
                %%
                if sim_nr <= length(building.result)
                    if building.result(sim_nr).number > 0
                        answer = uiconfirm(app.carnotUIBKUIFigure,['There is already a result with the number ' num2str(sim_nr) ' existing. Would you like to override the results?'],'Overwrite results?');
                        if strcmp(answer, 'OK')
                            d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','To stop the simulation please go to a Simulink Scope and stop it!');
                            building.save;
                            evalin('base','run4simulink')
                            feval(model_name)
                            close(d);
                            if ~app.SimulateDonotrunsimulationCheckBox.Value
                                d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','To stop the simulation please go to a Simulink Scope and stop it!');
                                evalin('base',['building = building.simulate(' num2str(sim_nr) ', ''' app.SimulateDescriptionEditField.Value ''', 1);'])
                                close(d);
                                uiconfirm(app.carnotUIBKUIFigure,'Simulation done!','Done!','Options',{'OK'},'Icon','success');
                            end
                        else
                            return
                        end
                    else
                        d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','To stop the simulation please go to a Simulink Scope and stop it!');
                        building.save;
                        evalin('base','run4simulink')
                        feval(model_name)
                        close(d);
                        if ~app.SimulateDonotrunsimulationCheckBox.Value
                            d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','To stop the simulation please go to a Simulink Scope and stop it!');
                            evalin('base',['building = building.simulate(' num2str(sim_nr) ', ''' app.SimulateDescriptionEditField.Value ''', 1);'])
                            close(d);
                            uiconfirm(app.carnotUIBKUIFigure,'Simulation done!','Done!','Options',{'OK'},'Icon','success');
                        end
                    end
                else
                    d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','To stop the simulation please go to a Simulink Scope and stop it!');
                    building.save;
                    evalin('base','run4simulink')
                    feval(model_name)
                    close(d);
                    if ~app.SimulateDonotrunsimulationCheckBox.Value
    %                     uiconfirm(app.carnotUIBKUIFigure,'Simulation started!','Started!','Options',{'OK'},'Icon','success');
                        d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','To stop the simulation please go to a Simulink Scope and stop it!');
                        evalin('base',['building = building.simulate(' num2str(sim_nr) ', ''' app.SimulateDescriptionEditField.Value ''', 1);'])
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'Simulation done!','Done!','Options',{'OK'},'Icon','success');
                    end
                end
            catch ME
                ME
                if exist('d','var')
                    close(d);
                end
                uiconfirm(app.carnotUIBKUIFigure,'Simulation failed!','Failed!','Options',{'OK'},'Icon','error');
            end
            
        end

        % Button pushed function: GeometryImportButton
        function GeometryImportButtonPushed(app, event)
            global building NUMBEROFZONES
            
            if ~isempty(app.GeometryNameEditField.Value)
                variant_nr = app.GeometryTovariantnumberEditField.Value;
                overwrite = 0;
                
                if variant_nr <= length(building.geometry)
                    if ~isempty(building.geometry(variant_nr).room)
                        answer = uiconfirm(app.carnotUIBKUIFigure,['There is already a variant with the number ' num2str(variant_nr) ' existing. Would you like to override the variant?'],'Overwrite variant?');
                        if strcmp(answer, 'OK')
                            overwrite = 1;
                        end
                    else
                        overwrite = 1;
                    end
                else
                    overwrite = 1;
                end
                
                if overwrite
                    d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                    modify.geometry = variant_nr;
                    modify.construction = -1;
                    modify.boundary = -1;
                    modify.gains = -1;
                    modify.hvac = -1;
                    modify.thermalzone = -1;
                    
                    filename = app.GeometryImportfromfileDropDown.Value;
                    filenames = strsplit(filename,'.');
                    
                    if strcmp(filenames{end},'xls') || strcmp(filenames{end},'xlsx')
                        import_mode = '';
                        
                        [status, sheets, xlFormat] = xlsfinfo(filename);
                        
                        matches = strfind(sheets,'Room');
                        tf = any(horzcat(matches{:}));
                        if tf
                            import_mode = 'excel';
                        end
                        
                        matches = strfind(sheets,'CARNOT');
                        tf = any(horzcat(matches{:}));
                        if tf
                            import_mode = 'PHPP';
                        end
                    elseif strcmp(filenames{end},'xml')
                        import_mode = 'gbXML';
                    else
                        import_mode = '';
                    end
                    
                    name_XML = '';
                    name_EXCEL = '';
                    name_PHPP = '';
                    if strcmp(import_mode,'gbXML')
                        name_XML = filename;
                        name_EXCEL = [filenames{1} '.xlsx'];
                    end
                    if strcmp(import_mode,'excel')
                        name_EXCEL = filename;
                    end
                    if strcmp(import_mode,'PHPP')
                        name_PHPP = filename;
                    end
                    close(d);
                    
                    if ~strcmp(import_mode,'')
                        try
                            d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                            building = create_building_automatic(building, modify, import_mode, name_XML, name_EXCEL, name_PHPP, NUMBEROFZONES);
                            building.geometry(variant_nr).name = app.GeometryNameEditField.Value;
                            building.save;
                            
                            TabGroupSelectionChanged(app,app.TabGroup.SelectionChangedFcn);
                            close(d);
                            uiconfirm(app.carnotUIBKUIFigure,'Import done!','Done!','Options',{'OK'},'Icon','success');
    
                        catch ME
                            ME
                            close(d);
                            answer = uiconfirm(app.carnotUIBKUIFigure,['The import of ' filename ' failed!'],'Import failed!','Options',{'OK'},'Icon','error');
                        end
                    else
                        answer = uiconfirm(app.carnotUIBKUIFigure,['' filename ' is not a valid file for the import!'],'Import failed!','Options',{'OK'},'Icon','error');
                    end
                end
            else
                uiconfirm(app.carnotUIBKUIFigure,'Please choose a file and a name!','Failed!','Options',{'OK'},'Icon','warning');
            end
        end

        % Button pushed function: GeometryCreateButton
        function GeometryCreateButtonPushed(app, event)
            global building
            
            if ~isempty(app.GeometryNameEditField.Value)
                variant_nr = app.GeometryTovariantnumberEditField.Value;
                overwrite = 0;
                
                if variant_nr <= length(building.geometry)
                    if ~isempty(building.geometry(variant_nr).room)
                        answer = uiconfirm(app.carnotUIBKUIFigure,['There is already a variant with the number ' num2str(variant_nr) ' existing. Would you like to override the variant?'],'Overwrite variant?');
                        if strcmp(answer, 'OK')
                            overwrite = 1;
                        end
                    else
                        overwrite = 1;
                    end
                else
                    overwrite = 1;
                end
                
                if overwrite
                    d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                    building.geometry(variant_nr) = GEOMETRY();
                    building.geometry(variant_nr).name = app.GeometryNameEditField.Value;
                    building.geometry(variant_nr).room = ROOM('empty', 0.1, 2.5, [], 0.6);
                    
                    TabGroupSelectionChanged(app,app.TabGroup.SelectionChangedFcn);
                    close(d);
                    uiconfirm(app.carnotUIBKUIFigure,'Variant created!','Done!','Options',{'OK'},'Icon','success');
                end
            else
                uiconfirm(app.carnotUIBKUIFigure,'Please choose a name!','Failed!','Options',{'OK'},'Icon','warning');
            end
        end

        % Button pushed function: ConstructionCreateButton
        function ConstructionCreateButtonPushed(app, event)
            global building
            
            if ~isempty(app.ConstructionNameEditField.Value)
                variant_nr = app.ConstructionTovariantnumberEditField.Value;
                overwrite = 0;
                
                if variant_nr <= length(building.construction)
                    if ~isempty(building.construction(variant_nr).structure)
                        answer = uiconfirm(app.carnotUIBKUIFigure,['There is already a variant with the number ' num2str(variant_nr) ' existing. Would you like to override the variant?'],'Overwrite variant?');
                        if strcmp(answer, 'OK')
                            overwrite = 1;
                        end
                    else
                        overwrite = 1;
                    end
                else
                    overwrite = 1;
                end
                
                if overwrite
                    d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                    building.construction(variant_nr) = CONSTRUCTION();
                    building.construction(variant_nr).name = app.ConstructionNameEditField.Value;
                    
                    TabGroupSelectionChanged(app,app.TabGroup.SelectionChangedFcn);
                    close(d);
                    uiconfirm(app.carnotUIBKUIFigure,'Variant created!','Done!','Options',{'OK'},'Icon','success');
                end
            else
                uiconfirm(app.carnotUIBKUIFigure,'Please choose a name!','Failed!','Options',{'OK'},'Icon','warning');
            end
        end

        % Button pushed function: GainsCreateButton
        function GainsCreateButtonPushed(app, event)
            global building
            app.GainsNameEditField.Value
            if ~isempty(app.GainsNameEditField.Value)
                variant_nr = app.GainsTovariantnumberEditField.Value;
                overwrite = 0;
                
                if variant_nr <= length(building.gains)
                    if ~isempty(building.gains(variant_nr).gain)
                        answer = uiconfirm(app.carnotUIBKUIFigure,['There is already a variant with the number ' num2str(variant_nr) ' existing. Would you like to override the variant?'],'Overwrite variant?');
                        if strcmp(answer, 'OK')
                            overwrite = 1;
                        end
                    else
                        overwrite = 1;
                    end
                else
                    overwrite = 1;
                end
                
                if overwrite
                    d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                    building.gains(variant_nr) = GAINS();
                    building.gains(variant_nr).name = app.GainsNameEditField.Value;
                    TabGroupSelectionChanged(app,app.TabGroup.SelectionChangedFcn);
                    close(d);
                    uiconfirm(app.carnotUIBKUIFigure,'Variant created!','Done!','Options',{'OK'},'Icon','success');
                end
            else
                uiconfirm(app.carnotUIBKUIFigure,'Please choose a name!','Failed!','Options',{'OK'},'Icon','warning');
            end
        end

        % Button pushed function: BoundaryCreateButton
        function BoundaryCreateButtonPushed(app, event)
            global building
            
            if ~isempty(app.BoundaryNameEditField.Value)
                variant_nr = app.BoundaryTovariantnumberEditField.Value;
                overwrite = 0;
                
                if variant_nr <= length(building.boundary)
                    if ~isempty(building.boundary(variant_nr).weather)
                        answer = uiconfirm(app.carnotUIBKUIFigure,['There is already a variant with the number ' num2str(variant_nr) ' existing. Would you like to override the variant?'],'Overwrite variant?');
                        if strcmp(answer, 'OK')
                            overwrite = 1;
                        end
                    else
                        overwrite = 1;
                    end
                else
                    overwrite = 1;
                end
                
                if overwrite
                    d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                    building.boundary(variant_nr) = BOUNDARY();
                    building.boundary(variant_nr).name = app.BoundaryNameEditField.Value;
                    for jj=1:6
                        building.boundary(variant_nr) = building.boundary(variant_nr).add_ground_set_temperature(building, jj, 'none', 0, 10);
                        building.boundary(variant_nr) = building.boundary(variant_nr).add_neighbour_set_temperature(building, jj, 'none', 0, 20);
                    end
                    
                    TabGroupSelectionChanged(app,app.TabGroup.SelectionChangedFcn);
                    close(d);
                    uiconfirm(app.carnotUIBKUIFigure,'Variant created!','Done!','Options',{'OK'},'Icon','success');
                end
            else
                uiconfirm(app.carnotUIBKUIFigure,'Please choose a name!','Failed!','Options',{'OK'},'Icon','warning');
            end
        end

        % Button pushed function: ThermalzoneCreateButton
        function ThermalzoneCreateButtonPushed(app, event)
            global building NUMBEROFZONES
            
            if ~isempty(app.ThermalzoneNameEditField.Value)
                variant_nr = app.ThermalzoneTovariantnumberEditField.Value;
                overwrite = 0;
                
                if variant_nr <= length(building.thermalzone)
                    if ~isempty(building.thermalzone(variant_nr).zone(1).name)
                        answer = uiconfirm(app.carnotUIBKUIFigure,['There is already a variant with the number ' num2str(variant_nr) ' existing. Would you like to override the variant?'],'Overwrite variant?');
                        if strcmp(answer, 'OK')
                            overwrite = 1;
                        end
                    else
                        overwrite = 1;
                    end
                else
                    overwrite = 1;
                end
                
                if overwrite
                    d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                    building.thermalzone(variant_nr) = THERMALZONE(NUMBEROFZONES);
                    building.thermalzone(variant_nr).name = app.ThermalzoneNameEditField.Value;
                    TabGroupSelectionChanged(app,app.TabGroup.SelectionChangedFcn);
                    close(d);
                    uiconfirm(app.carnotUIBKUIFigure,'Variant created!','Done!','Options',{'OK'},'Icon','success');
                end
            else
                uiconfirm(app.carnotUIBKUIFigure,'Please choose a name!','Failed!','Options',{'OK'},'Icon','warning');
            end
        end

        % Button pushed function: HVACCreateButton
        function HVACCreateButtonPushed(app, event)
            global building
            
            if ~isempty(app.HVACNameEditField.Value)
                variant_nr = app.HVACTovariantnumberEditField.Value;
                overwrite = 0;
                
                if variant_nr <= length(building.hvac)
                    if ~isempty(building.hvac(variant_nr).system)
                        answer = uiconfirm(app.carnotUIBKUIFigure,['There is already a variant with the number ' num2str(variant_nr) ' existing. Would you like to override the variant?'],'Overwrite variant?');
                        if strcmp(answer, 'OK')
                            overwrite = 1;
                        end
                    else
                        overwrite = 1;
                    end
                else
                    overwrite = 1;
                end
                
                if overwrite
                    d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                    building.hvac(variant_nr) = HVAC();
                    building.hvac(variant_nr).name = app.HVACNameEditField.Value;
                    TabGroupSelectionChanged(app,app.TabGroup.SelectionChangedFcn);
                    close(d);
                    uiconfirm(app.carnotUIBKUIFigure,'Variant created!','Done!','Options',{'OK'},'Icon','success');
                end
            else
                uiconfirm(app.carnotUIBKUIFigure,'Please choose a name!','Failed!','Options',{'OK'},'Icon','warning');
            end
        end

        % Button pushed function: ConstructionImportButton
        function ConstructionImportButtonPushed(app, event)
            global building NUMBEROFZONES
            
            if ~isempty(app.ConstructionImportfromfileDropDown.Value) && ~isempty(app.ConstructionNameEditField.Value)
                variant_nr = app.ConstructionTovariantnumberEditField.Value;
                overwrite = 0;
                
                if variant_nr <= length(building.construction)
                    if ~isempty(building.construction(variant_nr).structure)
                        answer = uiconfirm(app.carnotUIBKUIFigure,['There is already a variant with the number ' num2str(variant_nr) ' existing. Would you like to override the variant?'],'Overwrite variant?');
                        if strcmp(answer, 'OK')
                            overwrite = 1;
                        end
                    else
                        overwrite = 1;
                    end
                else
                    overwrite = 1;
                end
                
                if overwrite
                    d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                    
                    modify.geometry = -1;
                    modify.construction = variant_nr;
                    modify.boundary = -1;
                    modify.gains = -1;
                    modify.hvac = -1;
                    modify.thermalzone = -1;
                    
                    filename = app.ConstructionImportfromfileDropDown.Value;
                    filenames = strsplit(filename,'.');
                    
                    if strcmp(filenames{end},'xls') || strcmp(filenames{end},'xlsx')
                        import_mode = '';
                        
                        [status, sheets, xlFormat] = xlsfinfo(filename);
                        
                        matches = strfind(sheets,'Structure');
                        tf = any(horzcat(matches{:}));
                        if tf
                            import_mode = 'excel';
                        end
                        
                        matches = strfind(sheets,'CARNOT');
                        tf = any(horzcat(matches{:}));
                        if tf
                            import_mode = 'PHPP';
                        end
                    elseif strcmp(filenames{end},'xml')
                        import_mode = 'gbXML';
                    else
                        import_mode = '';
                    end
                    
                    name_XML = '';
                    name_EXCEL = '';
                    name_PHPP = '';
                    if strcmp(import_mode,'gbXML')
                        name_XML = filename;
                        name_EXCEL = [filenames{1} '.xlsx'];
                        uiconfirm(app.carnotUIBKUIFigure,'Until now this import type is not supported for this kind of variant. Please choose another file type.','Not supported','Options',{'OK'},'Icon','info');
                    end
                    if strcmp(import_mode,'excel')
                        name_EXCEL = filename;
                    end
                    if strcmp(import_mode,'PHPP')
                        name_PHPP = filename;
                    end
                    close(d);
                    
                    if ~strcmp(import_mode,'')
                        try
                            d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                            building = create_building_automatic(building, modify, import_mode, name_XML, name_EXCEL, name_PHPP, NUMBEROFZONES);
                            building.construction(variant_nr).name = app.ConstructionNameEditField.Value;
                            building.save;
                            
                            TabGroupSelectionChanged(app,app.TabGroup.SelectionChangedFcn);
                            close(d);
                            uiconfirm(app.carnotUIBKUIFigure,'Import done!','Done!','Options',{'OK'},'Icon','success');
                        catch ME
                            ME
                            close(d);
                            answer = uiconfirm(app.carnotUIBKUIFigure,['The import of ' filename ' failed!'],'Import failed!','Options',{'OK'},'Icon','error');
                        end
                    else
                        answer = uiconfirm(app.carnotUIBKUIFigure,['' filename ' is not a valid file for the import!'],'Import failed!','Options',{'OK'},'Icon','error');
                    end
                    
                end
            else
                uiconfirm(app.carnotUIBKUIFigure,'Please choose a file and a name!','Failed!','Options',{'OK'},'Icon','warning');
            end
        end

        % Button pushed function: GainsImportButton
        function GainsImportButtonPushed(app, event)
            global building NUMBEROFZONES
            
            if ~isempty(app.GainsImportfromfileDropDown.Value) && ~isempty(app.GainsNameEditField.Value)
                variant_nr = app.GainsTovariantnumberEditField.Value;
                overwrite = 0;
                
                if variant_nr <= length(building.gains)
                    if ~isempty(building.gains(variant_nr).gain)
                        answer = uiconfirm(app.carnotUIBKUIFigure,['There is already a variant with the number ' num2str(variant_nr) ' existing. Would you like to override the variant?'],'Overwrite variant?');
                        if strcmp(answer, 'OK')
                            overwrite = 1;
                        end
                    else
                        overwrite = 1;
                    end
                else
                    overwrite = 1;
                end
                
                if overwrite
                    d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                    
                    modify.geometry = -1;
                    modify.construction = -1;
                    modify.boundary = -1;
                    modify.gains = variant_nr;
                    modify.hvac = -1;
                    modify.thermalzone = -1;
                    
                    filename = app.GainsImportfromfileDropDown.Value;
                    filenames = strsplit(filename,'.');
                    
                    if strcmp(filenames{end},'xls') || strcmp(filenames{end},'xlsx')
                        import_mode = '';
                        
                        [status, sheets, xlFormat] = xlsfinfo(filename);
                        
                        matches = strfind(sheets,'Structure');
                        tf = any(horzcat(matches{:}));
                        if tf
                            import_mode = 'excel';
                        end
                        
                        matches = strfind(sheets,'CARNOT');
                        tf = any(horzcat(matches{:}));
                        if tf
                            import_mode = 'PHPP';
                        end
                    elseif strcmp(filenames{end},'xml')
                        import_mode = 'gbXML';
                    else
                        import_mode = '';
                    end
                    
                    name_XML = '';
                    name_EXCEL = '';
                    name_PHPP = '';
                    if strcmp(import_mode,'gbXML')
                        name_XML = filename;
                        name_EXCEL = [filenames{1} '.xlsx'];
                        uiconfirm(app.carnotUIBKUIFigure,'Until now this import type is not supported for this kind of variant. Please choose another file type.','Not supported','Options',{'OK'},'Icon','info');
                    end
                    if strcmp(import_mode,'excel')
                        name_EXCEL = filename;
                    end
                    if strcmp(import_mode,'PHPP')
                        name_PHPP = filename;
                    end
                    close(d);
                    
                    if ~strcmp(import_mode,'')
                        try
                            d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                            building = create_building_automatic(building, modify, import_mode, name_XML, name_EXCEL, name_PHPP, NUMBEROFZONES);
                            building.gains(variant_nr).name = app.GainsNameEditField.Value;
                            building.save;
                            
                            TabGroupSelectionChanged(app,app.TabGroup.SelectionChangedFcn);
                            close(d);
                            uiconfirm(app.carnotUIBKUIFigure,'Import done!','Done!','Options',{'OK'},'Icon','success');
                        catch ME
                            ME
                            close(d);
                            answer = uiconfirm(app.carnotUIBKUIFigure,['The import of ' filename ' failed!'],'Import failed!','Options',{'OK'},'Icon','error');
                        end
                    else
                        answer = uiconfirm(app.carnotUIBKUIFigure,['' filename ' is not a valid file for the import!'],'Import failed!','Options',{'OK'},'Icon','error');
                    end
                    
                end
            else
                uiconfirm(app.carnotUIBKUIFigure,'Please choose a file and a name!','Failed!','Options',{'OK'},'Icon','warning');
            end
        end

        % Button pushed function: BoundaryImportButton
        function BoundaryImportButtonPushed(app, event)
            global building NUMBEROFZONES
            
            if ~isempty(app.BoundaryImportfromfileDropDown.Value) && ~isempty(app.BoundaryNameEditField.Value)
                variant_nr = app.BoundaryTovariantnumberEditField.Value;
                overwrite = 0;
                
                if variant_nr <= length(building.boundary)
                    if ~isempty(building.boundary(variant_nr).weather)
                        answer = uiconfirm(app.carnotUIBKUIFigure,['There is already a variant with the number ' num2str(variant_nr) ' existing. Would you like to override the variant?'],'Overwrite variant?');
                        if strcmp(answer, 'OK')
                            overwrite = 1;
                        end
                    else
                        overwrite = 1;
                    end
                else
                    overwrite = 1;
                end
                
                if overwrite
                    d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                    
                    modify.geometry = -1;
                    modify.construction = -1;
                    modify.boundary = variant_nr;
                    modify.gains = -1;
                    modify.hvac = -1;
                    modify.thermalzone = -1;
                    
                    filename = app.BoundaryImportfromfileDropDown.Value;
                    filenames = strsplit(filename,'.');
                    
                    if strcmp(filenames{end},'xls') || strcmp(filenames{end},'xlsx')
                        import_mode = '';
                        
                        [status, sheets, xlFormat] = xlsfinfo(filename);
                        
                        matches = strfind(sheets,'Structure');
                        tf = any(horzcat(matches{:}));
                        if tf
                            import_mode = 'excel';
                        end
                        
                        matches = strfind(sheets,'CARNOT');
                        tf = any(horzcat(matches{:}));
                        if tf
                            import_mode = 'PHPP';
                        end
                    elseif strcmp(filenames{end},'xml')
                        import_mode = 'gbXML';
                    else
                        import_mode = '';
                    end
                    
                    name_XML = '';
                    name_EXCEL = '';
                    name_PHPP = '';
                    if strcmp(import_mode,'gbXML')
                        name_XML = filename;
                        name_EXCEL = [filenames{1} '.xlsx'];
                        uiconfirm(app.carnotUIBKUIFigure,'Until now this import type is not supported for this kind of variant. Please choose another file type.','Not supported','Options',{'OK'},'Icon','info');
                    end
                    if strcmp(import_mode,'excel')
                        name_EXCEL = filename;
                    end
                    if strcmp(import_mode,'PHPP')
                        name_PHPP = filename;
                    end
                    close(d);
                    
                    if ~strcmp(import_mode,'')
                        try
                            d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                            building = create_building_automatic(building, modify, import_mode, name_XML, name_EXCEL, name_PHPP, NUMBEROFZONES);
                            building.boundary(variant_nr).name = app.BoundaryNameEditField.Value;
                            building.save;
                            
                            TabGroupSelectionChanged(app,app.TabGroup.SelectionChangedFcn);
                            close(d);
                            uiconfirm(app.carnotUIBKUIFigure,'Import done!','Done!','Options',{'OK'},'Icon','success');
                        catch ME
                            ME
                            close(d);
                            answer = uiconfirm(app.carnotUIBKUIFigure,['The import of ' filename ' failed!'],'Import failed!','Options',{'OK'},'Icon','error');
                        end
                    else
                        answer = uiconfirm(app.carnotUIBKUIFigure,['' filename ' is not a valid file for the import!'],'Import failed!','Options',{'OK'},'Icon','error');
                    end
                    
                end
            else
                uiconfirm(app.carnotUIBKUIFigure,'Please choose a file and a name!','Failed!','Options',{'OK'},'Icon','warning');
            end
        end

        % Button pushed function: ThermalzoneImportButton
        function ThermalzoneImportButtonPushed(app, event)
            global building NUMBEROFZONES
            
            if ~isempty(app.ThermalzoneImportfromfileDropDown.Value) && ~isempty(app.ThermalzoneNameEditField.Value)
                variant_nr = app.ThermalzoneTovariantnumberEditField.Value;
                overwrite = 0;
                
                if variant_nr <= length(building.thermalzone)
                    if ~isempty(building.thermalzone(variant_nr).zone(1).rooms)
                        answer = uiconfirm(app.carnotUIBKUIFigure,['There is already a variant with the number ' num2str(variant_nr) ' existing. Would you like to override the variant?'],'Overwrite variant?');
                        if strcmp(answer, 'OK')
                            overwrite = 1;
                        end
                    else
                        overwrite = 1;
                    end
                else
                    overwrite = 1;
                end
                
                if overwrite
                    d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                    
                    modify.geometry = -1;
                    modify.construction = -1;
                    modify.boundary = -1;
                    modify.gains = -1;
                    modify.hvac = -1;
                    modify.thermalzone = variant_nr;
                    
                    filename = app.ThermalzoneImportfromfileDropDown.Value;
                    filenames = strsplit(filename,'.');
                    
                    if strcmp(filenames{end},'xls') || strcmp(filenames{end},'xlsx')
                        import_mode = '';
                        
                        [status, sheets, xlFormat] = xlsfinfo(filename);
                        
                        matches = strfind(sheets,'Structure');
                        tf = any(horzcat(matches{:}));
                        if tf
                            import_mode = 'excel';
                        end
                        
                        matches = strfind(sheets,'CARNOT');
                        tf = any(horzcat(matches{:}));
                        if tf
                            import_mode = 'PHPP';
                        end
                    elseif strcmp(filenames{end},'xml')
                        import_mode = 'gbXML';
                    else
                        import_mode = '';
                    end
                    
                    name_XML = '';
                    name_EXCEL = '';
                    name_PHPP = '';
                    if strcmp(import_mode,'gbXML')
                        name_XML = filename;
                        name_EXCEL = [filenames{1} '.xlsx'];
                    end
                    if strcmp(import_mode,'excel')
                        name_EXCEL = filename;
                    end
                    if strcmp(import_mode,'PHPP')
                        name_PHPP = filename;
                    end
                    close(d)
                    
                    if ~strcmp(import_mode,'')
                        try
                            d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                            building = create_building_automatic(building, modify, import_mode, name_XML, name_EXCEL, name_PHPP, NUMBEROFZONES);
                            building.thermalzone(variant_nr).name = app.ThermalzoneNameEditField.Value;
                            building.save;
                            
                            TabGroupSelectionChanged(app,app.TabGroup.SelectionChangedFcn);
                            close(d);
                            uiconfirm(app.carnotUIBKUIFigure,'Import done!','Done!','Options',{'OK'},'Icon','success');
                        catch ME
                            ME
                            close(d);
                            answer = uiconfirm(app.carnotUIBKUIFigure,['The import of ' filename ' failed!'],'Import failed!','Options',{'OK'},'Icon','error');
                        end
                    else
                        answer = uiconfirm(app.carnotUIBKUIFigure,['' filename ' is not a valid file for the import!'],'Import failed!','Options',{'OK'},'Icon','error');
                    end
                    
                end
            else
                uiconfirm(app.carnotUIBKUIFigure,'Please choose a file and a name!','Failed!','Options',{'OK'},'Icon','warning');
            end
        end

        % Button pushed function: HVACImportButton
        function HVACImportButtonPushed(app, event)
            global building NUMBEROFZONES
            
            if ~isempty(app.HVACImportfromfileDropDown.Value) && ~isempty(app.HVACNameEditField.Value)
                variant_nr = app.HVACTovariantnumberEditField.Value;
                overwrite = 0;
                
                if variant_nr <= length(building.hvac)
                    if ~isempty(building.hvac(variant_nr).system) || ~strcmp(building.hvac(variant_nr).name,'')
                        answer = uiconfirm(app.carnotUIBKUIFigure,['There is already a variant with the number ' num2str(variant_nr) ' existing. Would you like to override the variant?'],'Overwrite variant?');
                        if strcmp(answer, 'OK')
                            overwrite = 1;
                        end
                    else
                        overwrite = 1;
                    end
                else
                    overwrite = 1;
                end
                
                if overwrite
                    modify.geometry = -1;
                    modify.construction = -1;
                    modify.boundary = -1;
                    modify.gains = -1;
                    modify.hvac = variant_nr;
                    modify.thermalzone = -1;
                    
                    filename = app.HVACImportfromfileDropDown.Value;
                    filenames = strsplit(filename,'.');
                    
                    if strcmp(filenames{end},'xls') || strcmp(filenames{end},'xlsx')
                        import_mode = '';
                        
                        [status, sheets, xlFormat] = xlsfinfo(filename);
                        
                        matches = strfind(sheets,'Structure');
                        tf = any(horzcat(matches{:}));
                        if tf
                            import_mode = 'excel';
                        end
                        
                        matches = strfind(sheets,'CARNOT');
                        tf = any(horzcat(matches{:}));
                        if tf
                            import_mode = 'PHPP';
                        end
                    elseif strcmp(filenames{end},'mat')
                        import_mode = '';
                        
                        mat_test = load(filename);
                        
                        if isfield(mat_test,'HVACsave')
                            import_mode = 'MAT';
                        end
                    elseif strcmp(filenames{end},'xml')
                        import_mode = 'gbXML';
                    else
                        import_mode = '';
                    end
                    
                    name_XML = '';
                    name_EXCEL = '';
                    name_PHPP = '';
                    name_MAT = '';
                    if strcmp(import_mode,'gbXML')
                        name_XML = filename;
                        name_EXCEL = [filenames{1} '.xlsx'];
                        uiconfirm(app.carnotUIBKUIFigure,'Until now this import type is not supported for this kind of variant. Please choose another file type.','Not supported','Options',{'OK'},'Icon','info');
                    end
                    if strcmp(import_mode,'excel')
                        name_EXCEL = filename;
                        uiconfirm(app.carnotUIBKUIFigure,'Until now this import type is not supported for this kind of variant. Please choose another file type.','Not supported','Options',{'OK'},'Icon','info');
                    end
                    if strcmp(import_mode,'PHPP')
                        name_PHPP = filename;
                    end
                    if strcmp(import_mode,'MAT')
                        name_MAT = filename;
                    end
                    
                    if ~strcmp(import_mode,'')
                        try
                            d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                            if strcmp(import_mode,'MAT')
                                HVACload = load(name_MAT);
                                building.hvac(variant_nr) = HVACload.HVACsave;
                            else
                                building = create_building_automatic(building, modify, import_mode, name_XML, name_EXCEL, name_PHPP, NUMBEROFZONES);
                            end
                            building.hvac(variant_nr).name = app.HVACNameEditField.Value;
                            building.save;
                            close(d);
                            TabGroupSelectionChanged(app,app.TabGroup.SelectionChangedFcn);
                            uiconfirm(app.carnotUIBKUIFigure,'Import done!','Done!','Options',{'OK'},'Icon','success');
                        catch ME
                            ME
                            close(d);
                            answer = uiconfirm(app.carnotUIBKUIFigure,['The import of ' filename ' failed!'],'Import failed!','Options',{'OK'},'Icon','error');
                        end
                    else
                        answer = uiconfirm(app.carnotUIBKUIFigure,['' filename ' is not a valid file for the import!'],'Import failed!','Options',{'OK'},'Icon','error');
                    end
                end
            else
                uiconfirm(app.carnotUIBKUIFigure,'Please choose a file and a name!','Failed!','Options',{'OK'},'Icon','warning');
            end
        end

        % Selection changed function: ConstructionTree
        function ConstructionTreeSelectionChanged(app, event)
            d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
            
            global building
            
            selectedNodes = app.ConstructionTree.SelectedNodes;
            if ~isempty(selectedNodes)
                app.ConstructionPanel.Visible = 'on';
                selectedNodes = app.ConstructionTree.SelectedNodes.NodeData;
                
                app.ConstructionVariantnameEditField.Value = building.construction(selectedNodes(1)).name;
                
                delete(app.ConstructionStructureUIAxes.Children);
                app.ConstructionStructurePanel.Visible = 'off';
                app.ConstructionStructureUIAxes.Visible = 'off';
                app.ConstructionStructureInformationTextArea.Value = '';
                
                if length(selectedNodes) == 2
                    app.ConstructionStructurePanel.Visible = 'on';
                    
                    app.ConstructionStructureNameEditField.Value = building.construction(selectedNodes(1)).structure(selectedNodes(2)).name;
                    app.ConstructionStructureemissioninternalEditField.Value = building.construction(selectedNodes(1)).structure(selectedNodes(2)).emission_1;
                    app.ConstructionStructureemissionexternalEditField.Value = building.construction(selectedNodes(1)).structure(selectedNodes(2)).emission_2;
                    app.ConstructionStructureabsorptionexternalEditField.Value = building.construction(selectedNodes(1)).structure(selectedNodes(2)).absorption_2;
                    app.ConstructionStructureModelLevelDropDown.Value = building.construction(selectedNodes(1)).structure(selectedNodes(2)).model;
                    type = building.construction(selectedNodes(1)).structure(selectedNodes(2)).type;
                    switch type
                        case 0
                            app.ConstructionStructureTypeEditField.Value = 'wall';
                            app.ConstructionStructureModelLevelDropDown.Items = {'UA','RC','hygrothermal'};
                            text1{1} = ['U value: ' num2str(building.construction(selectedNodes(1)).structure(selectedNodes(2)).parameter.U) ' [W/(m^2.K)]'];
                            app.ConstructionStructureInformationTextArea.Value = text1;
                        case 1
                            app.ConstructionStructureTypeEditField.Value = 'window';
                            app.ConstructionStructureModelLevelDropDown.Items = {'TF','RC (not yet implemented)','TF with shading'};
                            text1{1} = ['U value glass: ' num2str(building.construction(selectedNodes(1)).structure(selectedNodes(2)).parameter.U_g) ' [W/(m^2.K)]'];
                            text1{end+1} = ['U value frame: ' num2str(building.construction(selectedNodes(1)).structure(selectedNodes(2)).parameter.U_f) ' [W/(m^2.K)]'];
                            text1{end+1} = ['g value: ' num2str(building.construction(selectedNodes(1)).structure(selectedNodes(2)).parameter.g_w) ' [-]'];
                            text1{end+1} = ['psi_g: ' num2str(building.construction(selectedNodes(1)).structure(selectedNodes(2)).parameter.psi_ge) ' [W/(m.K)]'];
                            app.ConstructionStructureInformationTextArea.Value = text1;
                        case 2
                            app.ConstructionStructureTypeEditField.Value = 'thermal bridge';
                            app.ConstructionStructureModelLevelDropDown.Items = {'UA','RC','hygrothermal'};
                            text1{1} = ['psi value: ' num2str(building.construction(selectedNodes(1)).structure(selectedNodes(2)).parameter.U) ' [W/(m.K)]'];
                            app.ConstructionStructureInformationTextArea.Value = text1;
                    end
                    
                    try
                        if isfield(building.construction(selectedNodes(1)).structure(selectedNodes(2)).parameter,'layers_d')
                            if (length(building.construction(selectedNodes(1)).structure(selectedNodes(2)).parameter.layers_d) == 1 && (building.construction(selectedNodes(1)).structure(selectedNodes(2)).parameter.layers_d) == 1) || building.construction(selectedNodes(1)).structure(selectedNodes(2)).type > 0
                                % no sense to plot
                            else
                                app.ConstructionStructureUIAxes.Visible = 'on';
                                app.ConstructionStructureUIAxes.NextPlot = 'replaceall';
                                app.ConstructionStructureUIAxes.XLabel.String = '';
                                app.ConstructionStructureUIAxes.YLabel.String = '';
                                
                                shape = zeros(length(building.construction(selectedNodes(1)).structure(selectedNodes(2)).parameter.layers_d)+1,1);
                                for jj = 1:length(building.construction(selectedNodes(1)).structure(selectedNodes(2)).parameter.layers_d)
                                    shape(jj+1) = shape(jj) + building.construction(selectedNodes(1)).structure(selectedNodes(2)).parameter.layers_d(jj);
                                    app.ConstructionStructureUIAxes.NextPlot = 'add';
                                end
                                
                                % if colors are existing
                                if isfield(building.construction(selectedNodes(1)).structure(selectedNodes(2)).parameter,'layers_colors')
                                    for jj = 1:length(building.construction(selectedNodes(1)).structure(selectedNodes(2)).parameter.layers_d)
                                        rectangle(app.ConstructionStructureUIAxes,'Position',[shape(jj),-0.1,building.construction(selectedNodes(1)).structure(selectedNodes(2)).parameter.layers_d(jj),1.2],'FaceColor',building.construction(selectedNodes(1)).structure(selectedNodes(2)).parameter.layers_colors(jj,:),'EdgeColor','k','LineWidth',2)
                                    end
                                else
                                    for jj = 1:length(building.construction(selectedNodes(1)).structure(selectedNodes(2)).parameter.layers_d)
                                        rectangle(app.ConstructionStructureUIAxes,'Position',[shape(jj),-0.1,building.construction(selectedNodes(1)).structure(selectedNodes(2)).parameter.layers_d(jj),1.2],'FaceColor',rand(1,3),'EdgeColor','k','LineWidth',2)
                                    end
                                end
                                
                                % beuken model
                                if building.construction(selectedNodes(1)).structure(selectedNodes(2)).model>=1 && isfield(building.construction(selectedNodes(1)).structure(selectedNodes(2)).parameter,'xmesh_beu')
                                    red_point = 0;
                                    for jj=1:length(building.construction(selectedNodes(1)).structure(selectedNodes(2)).parameter.xmesh_beu)
                                        red_point = red_point + building.construction(selectedNodes(1)).structure(selectedNodes(2)).parameter.xmesh_beu(jj);
                                        plot(app.ConstructionStructureUIAxes,red_point,0.5,'Marker','o','MarkerFaceColor','r','MarkerEdgeColor',[0.1,0.1,0.1],'MarkerSize',10) 
                                    end
                                    plot(app.ConstructionStructureUIAxes,0,0.5,'Marker','o','MarkerFaceColor','r','MarkerEdgeColor',[0.1,0.1,0.1],'MarkerSize',10)
                                    plot(app.ConstructionStructureUIAxes,shape(end),0.5,'Marker','o','MarkerFaceColor','r','MarkerEdgeColor',[0.1,0.1,0.1],'MarkerSize',10)  
                                    plot(app.ConstructionStructureUIAxes,[0:0.1:shape(end) shape(end)],ones(length(0:0.1:shape(end))+1,1)*0.5,'color','r','LineStyle','-')
                                end
                                
                                % hygrothermal model
                                if building.construction(selectedNodes(1)).structure(selectedNodes(2)).model==2 && isfield(building.construction(selectedNodes(1)).structure(selectedNodes(2)).parameter,'xmesh_hygro')
                                    for jj=1:length(building.construction(selectedNodes(1)).structure(selectedNodes(2)).parameter.xmesh_hygro)
                                        plot(app.ConstructionStructureUIAxes,ones(1,2)*building.construction(selectedNodes(1)).structure(selectedNodes(2)).parameter.xmesh_hygro(jj),[-0.1 1.1],'color',[0.5,0.5,0.5],'LineStyle','--')
                                    end
                                end
                                
                                % if names exist
                                if isfield(building.construction(selectedNodes(1)).structure(selectedNodes(2)).parameter,'layers_names')
                                    for jj=1:length(building.construction(selectedNodes(1)).structure(selectedNodes(2)).parameter.layers_names)
                                        if building.construction(selectedNodes(1)).structure(selectedNodes(2)).parameter.layers_d(jj) < 0.01
                                            text(app.ConstructionStructureUIAxes,shape(jj)-building.construction(selectedNodes(1)).structure(selectedNodes(2)).parameter.layers_d(jj),0.2,building.construction(selectedNodes(1)).structure(selectedNodes(2)).parameter.layers_names(jj),'HorizontalAlignment','center','Rotation',90)
                                        else
                                            text(app.ConstructionStructureUIAxes,shape(jj)+building.construction(selectedNodes(1)).structure(selectedNodes(2)).parameter.layers_d(jj)/2,0.2,building.construction(selectedNodes(1)).structure(selectedNodes(2)).parameter.layers_names(jj),'HorizontalAlignment','center','Rotation',90)
                                        end
                                    end
                                end
                                
                                xlim(app.ConstructionStructureUIAxes,[-.05 shape(end)+0.05]);
                                ylim(app.ConstructionStructureUIAxes,[0 1]);
                                name_mod = strrep(building.construction(selectedNodes(1)).structure(selectedNodes(2)).name,'_',' ');
    
                                title(app.ConstructionStructureUIAxes,name_mod);
                            end
                        else
                            warning('No dimensions available to plot!')
                        end
                        
                    catch ME
                        ME
                    end
                end
            else
                app.ConstructionPanel.Visible = 'off';
            end
            
            
        end

        % Selection changed function: GainsTree
        function GainsTreeSelectionChanged(app, event)
            d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
            
            global building
            
            selectedNodes = app.GainsTree.SelectedNodes;
            if ~isempty(selectedNodes)
                app.GainsPanel.Visible = 'on';
                app.GainsGainPanel.Visible = 'off';
                app.GainsGainUIAxes.Visible = 'off';
                app.GainsGain2UIAxes.Visible = 'off';
                selectedNodes = app.GainsTree.SelectedNodes.NodeData;
                
                delete(app.GainsGainUIAxes.Children)
                delete(app.GainsGain2UIAxes.Children)
                
                app.GainsVariantnameEditField.Value = building.gains(selectedNodes(1)).name;
                
                if length(selectedNodes) == 3 && selectedNodes(2) == 1
                    try
                        d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                        app.GainsGainPanel.Visible = 'on';
                        
                        app.GainsGainNameEditField.Value = building.gains(selectedNodes(1)).gain(selectedNodes(3)).name;
                        app.GainsGainControlDropDown.Value = building.gains(selectedNodes(1)).gain(selectedNodes(3)).control;
                        app.GainsGainTypeDropDown.Value = building.gains(selectedNodes(1)).gain(selectedNodes(3)).model;
                        switch app.GainsGainTypeDropDown.Value
                            case 0
                                app.GainsGainModelDropDown.ItemsData = [0 1 2];
                                app.GainsGainModelDropDown.Items = {'people (35W(c)+25W(r))','people (activity profile)','power in W/m²'};
                            case 1
                                app.GainsGainModelDropDown.ItemsData = [0 1];
                                app.GainsGainModelDropDown.Items = {'power in W/zone','power in W/m²'};
                            case 2
                                app.GainsGainModelDropDown.ItemsData = [0 1];
                                app.GainsGainModelDropDown.Items = {'power in W/zone','power in W/m²'};
                            case 3
                                app.GainsGainModelDropDown.ItemsData = [0];
                                app.GainsGainModelDropDown.Items = {'source in kg/s'};
                        end
                        app.GainsGainModelDropDown.Value = building.gains(selectedNodes(1)).gain(selectedNodes(3)).type;
                        
                        app.GainsGainUITable.Data = [];
                        app.GainsGainUITable.Enable = 'off';
                        if length(building.gains(selectedNodes(1)).gain(selectedNodes(3)).timeduration) == 24
                            app.GainsGainUITable.Enable = 'on';
                            switch app.GainsGainTypeDropDown.Value
                                case 0
                                    if app.GainsGainModelDropDown.Value == 2
                                        tt = array2table([building.gains(selectedNodes(1)).gain(selectedNodes(3)).values1]);
                                        app.GainsGainUITable.RowName = {'power'};
                                    elseif app.GainsGainModelDropDown.Value == 1
                                        tt = array2table([building.gains(selectedNodes(1)).gain(selectedNodes(3)).values1; building.gains(selectedNodes(1)).gain(selectedNodes(3)).values2]);
                                        app.GainsGainUITable.RowName = {'people','activity'};
                                    else
                                        tt = array2table([building.gains(selectedNodes(1)).gain(selectedNodes(3)).values1; building.gains(selectedNodes(1)).gain(selectedNodes(3)).values2]);
                                        app.GainsGainUITable.RowName = {'people','activity'};
                                    end
                                case 3
                                    tt = array2table([building.gains(selectedNodes(1)).gain(selectedNodes(3)).values1]);
                                    app.GainsGainUITable.RowName = {'source kg/s'};
                                otherwise
                                    tt = array2table([building.gains(selectedNodes(1)).gain(selectedNodes(3)).values1]);
                                    app.GainsGainUITable.RowName = {'power'};
                            end
                            app.GainsGainUITable.Data = tt;
                        else
                            uiconfirm(app.carnotUIBKUIFigure,'Only hourly profiles are supported by the GUI!','Not supported!','Options',{'OK'},'Icon','warning');
                        end
                        
                        app.GainsGainUseinzonesListBox.Value = [];
                        gain_to_zone = [];
                        for jj=1:length(building.gains(selectedNodes(1)).gain_to_zone)
                            if strcmp(building.gains(selectedNodes(1)).gain_to_zone(jj).name_gains,building.gains(selectedNodes(1)).gain(selectedNodes(3)).name)
                                gain_to_zone = [gain_to_zone, building.gains(selectedNodes(1)).gain_to_zone(jj).number_of_zone];
                            end
                        end
                        app.GainsGainUseinzonesListBox.Value = gain_to_zone;
                        
                        if ~length(building.gains(selectedNodes(1)).gain(selectedNodes(3)).timevalues) == 25
                            % no sense to plot
                        else
                            app.GainsGainUIAxes.Visible = 'on';
                            app.GainsGainUIAxes.NextPlot = 'replaceall';
                            app.GainsGainUIAxes.XLabel.String = '';
                            app.GainsGainUIAxes.YLabel.String = '';
                            
                            xxx = [building.gains(selectedNodes(1)).gain(selectedNodes(3)).timevalues/3600; building.gains(selectedNodes(1)).gain(selectedNodes(3)).timevalues/3600];
                            yyy = [building.gains(selectedNodes(1)).gain(selectedNodes(3)).values1 building.gains(selectedNodes(1)).gain(selectedNodes(3)).values1(1); building.gains(selectedNodes(1)).gain(selectedNodes(3)).values1 building.gains(selectedNodes(1)).gain(selectedNodes(3)).values1(1)];
                            h = area(app.GainsGainUIAxes,xxx([2:end end]),yyy(1:end));
                            h.FaceColor = [0 0.75 0.75];
                            name_mod = strrep(building.gains(selectedNodes(1)).gain(selectedNodes(3)).name,'_',' ');
                            title(app.GainsGainUIAxes,name_mod)
                            xlim(app.GainsGainUIAxes,[0 24]);
                            app.GainsGainUIAxes.XTick=[1:24];
                            if building.gains(selectedNodes(1)).gain(selectedNodes(3)).model == 0 && (building.gains(selectedNodes(1)).gain(selectedNodes(3)).type == 0 || building.gains(selectedNodes(1)).gain(selectedNodes(3)).type == 1)
                                app.GainsGainUIAxes.YLabel.String = 'People [people]';
                            elseif building.gains(selectedNodes(1)).gain(selectedNodes(3)).model == 0 && building.gains(selectedNodes(1)).gain(selectedNodes(3)).type == 2
                                app.GainsGainUIAxes.YLabel.String = 'People [W/m²]';
                            elseif building.gains(selectedNodes(1)).gain(selectedNodes(3)).model == 1 && building.gains(selectedNodes(1)).gain(selectedNodes(3)).type == 0
                                app.GainsGainUIAxes.YLabel.String = 'Light [W/light]';
                            elseif building.gains(selectedNodes(1)).gain(selectedNodes(3)).model == 1 && building.gains(selectedNodes(1)).gain(selectedNodes(3)).type == 1
                                app.GainsGainUIAxes.YLabel.String = 'Light [W/m²]';
                            elseif building.gains(selectedNodes(1)).gain(selectedNodes(3)).model == 2 && building.gains(selectedNodes(1)).gain(selectedNodes(3)).type == 0
                                app.GainsGainUIAxes.YLabel.String = 'Electricity [W/device]';
                            elseif building.gains(selectedNodes(1)).gain(selectedNodes(3)).model == 2 && building.gains(selectedNodes(1)).gain(selectedNodes(3)).type == 1
                                app.GainsGainUIAxes.YLabel.String = 'Electricity [W/m²]';
                            elseif building.gains(selectedNodes(1)).gain(selectedNodes(3)).model == 3 && building.gains(selectedNodes(1)).gain(selectedNodes(3)).type == 0
                                app.GainsGainUIAxes.YLabel.String = 'Moisture [kg/s]';
                            end
                            app.GainsGainUIAxes.XLabel.String = 'Time [h]';
                            
                            if building.gains(selectedNodes(1)).gain(selectedNodes(3)).model == 0 && (building.gains(selectedNodes(1)).gain(selectedNodes(3)).type == 0 || building.gains(selectedNodes(1)).gain(selectedNodes(3)).type == 1)
                                app.GainsGain2UIAxes.Visible = 'on';
                                app.GainsGain2UIAxes.NextPlot = 'replaceall';
                                app.GainsGain2UIAxes.XLabel.String = '';
                                app.GainsGain2UIAxes.YLabel.String = '';
                                
                                xxx = [building.gains(selectedNodes(1)).gain(selectedNodes(3)).timevalues/3600; building.gains(selectedNodes(1)).gain(selectedNodes(3)).timevalues/3600];
                                yyy = [building.gains(selectedNodes(1)).gain(selectedNodes(3)).values2 building.gains(selectedNodes(1)).gain(selectedNodes(3)).values2(1); building.gains(selectedNodes(1)).gain(selectedNodes(3)).values2 building.gains(selectedNodes(1)).gain(selectedNodes(3)).values2(1)];
                                h = area(app.GainsGain2UIAxes,xxx([2:end end]),yyy(1:end));
                                h.FaceColor = [0 0.75 0.75];
                                title(app.GainsGain2UIAxes,'')
                                xlim(app.GainsGain2UIAxes,[0 24]);
                                app.GainsGain2UIAxes.XTick=[1:24];
                                app.GainsGain2UIAxes.YLabel.String = 'Activity [activity]';
                                app.GainsGain2UIAxes.XLabel.String = 'Time [h]';
                            end
                        end
                        
                        close(d);
                    catch
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'There was an error loading this gain!','Failed!','Options',{'OK'},'Icon','error');
                        app.GainsGainPanel.Visible = 'off';
                    end
                end
            else
                app.GainsPanel.Visible = 'off';
                app.GainsGainPanel.Visible = 'off';
                app.GainsGainUIAxes.Visible = 'off';
                app.GainsGain2UIAxes.Visible = 'off';
            end
            
            close(d);
        end

        % Selection changed function: BoundaryTree
        function BoundaryTreeSelectionChanged(app, event)
            d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
            
            global building
            
            try
                selectedNodes = app.BoundaryTree.SelectedNodes;
                if ~isempty(selectedNodes)
                    app.BoundaryPanel.Visible = 'on';
                    app.WeatherPanel.Visible = 'off';
                    app.WeatherNameEditField.Value = '';
                    app.GroundPanel.Visible = 'off';
                    app.NeighbourPanel.Visible = 'off';
                    
                    try
                        delete(app.BoundaryUIAxes1.Legend);
                        delete(app.BoundaryUIAxes2.Legend);
                    catch
                        
                    end
                    try
                        delete(app.BoundaryUIAxes1.Children);
                        delete(app.BoundaryUIAxes2.Children);
                    catch
                        
                    end
                    app.BoundaryUIAxes1.Visible = 'off';
                    app.BoundaryUIAxes1.NextPlot = 'replaceall';
                    app.BoundaryUIAxes1.XLabel.String = '';
                    app.BoundaryUIAxes1.YLabel.String = '';
                    app.BoundaryUIAxes2.Visible = 'off';
                    app.BoundaryUIAxes2.NextPlot = 'replaceall';
                    app.BoundaryUIAxes2.XLabel.String = '';
                    app.BoundaryUIAxes2.YLabel.String = '';
                    
                    app.GroundPanel.Position(2) = 375;
                    app.NeighbourPanel.Position(2) = 375;
                    
                    selectedNodes = app.BoundaryTree.SelectedNodes.NodeData;
                    
                    app.BoundaryVariantnameEditField.Value = building.boundary(selectedNodes(1)).name;
                    
                    if length(selectedNodes) > 1
                        if selectedNodes(2) == 1
                            app.WeatherPanel.Visible = 'on';
                            if ~isempty(building.boundary(selectedNodes(1)).weather)
                                app.WeatherNameEditField.Value = building.boundary(selectedNodes(1)).weather.name;
                                
                                app.BoundaryUIAxes1.Visible = 'on';
                                plot(app.BoundaryUIAxes1,datetime(building.boundary(selectedNodes(1)).weather(1).t_ambient(:,1)+45*8760*3600,'ConvertFrom','posixtime'),building.boundary(selectedNodes(1)).weather(1).t_ambient(:,2))
                                app.BoundaryUIAxes1.NextPlot = 'add';
                                plot(app.BoundaryUIAxes1,datetime(building.boundary(selectedNodes(1)).weather(1).t_sky(:,1)+45*8760*3600,'ConvertFrom','posixtime'),building.boundary(selectedNodes(1)).weather(1).t_sky(:,2))
                                title(app.BoundaryUIAxes1,['Temperatures ' app.WeatherNameEditField.Value]);
                                app.BoundaryUIAxes1.XGrid = 'on';
                                app.BoundaryUIAxes1.YGrid = 'on';
                                xlim(app.BoundaryUIAxes1,[datetime('31-Dec-2015 23:59:00') datetime('01-Jan-2017 00:00:00')]);
                                ylabel(app.BoundaryUIAxes1,'temperatures [°C]');
                                legend(app.BoundaryUIAxes1,{'ambient','sky'},'Location','bestoutside','Orientation','horizontal')
                                
                                app.BoundaryUIAxes2.Visible = 'on';
                                plot(app.BoundaryUIAxes2,datetime(building.boundary(selectedNodes(1)).weather(1).radiation_beam_normal(:,1)+45*8760*3600,'ConvertFrom','posixtime'),building.boundary(selectedNodes(1)).weather(1).radiation_beam_normal(:,2))
                                app.BoundaryUIAxes2.NextPlot = 'add';
                                plot(app.BoundaryUIAxes2,datetime(building.boundary(selectedNodes(1)).weather(1).radiation_diffuse_horizontal(:,1)+45*8760*3600,'ConvertFrom','posixtime'),building.boundary(selectedNodes(1)).weather(1).radiation_diffuse_horizontal(:,2))
                                title(app.BoundaryUIAxes2,['Radiation ' app.WeatherNameEditField.Value]);
                                app.BoundaryUIAxes2.XGrid = 'on';
                                app.BoundaryUIAxes2.YGrid = 'on';
                                xlim(app.BoundaryUIAxes2,[datetime('31-Dec-2015 23:59:00') datetime('01-Jan-2017 00:00:00')]);
                                ylabel(app.BoundaryUIAxes2,'radiation [W/m^2]');
                                legend(app.BoundaryUIAxes2,{'direct (beam)','diffuse'},'Location','bestoutside','Orientation','horizontal')
                            end
                        end
                    end
                    if length(selectedNodes) > 2
                        if selectedNodes(2) == 2
                            app.GroundPanel.Visible = 'on';
                            app.GroundNameEditField.Value = building.boundary(selectedNodes(1)).ground(selectedNodes(3)).name;
                            if selectedNodes(3) == 1
                                app.GroundUseasboundaryEditField.Value = ['GROUND'];
                            else
                                app.GroundUseasboundaryEditField.Value = ['GROUND_' num2str(selectedNodes(3))];
                            end
                            
                            app.GroundUITable.Data = [];
                            try
                                if size(building.boundary(selectedNodes(1)).ground(selectedNodes(3)).temperature, 1) == 85
                                    tt = array2table([building.boundary(selectedNodes(1)).ground(selectedNodes(3)).temperature(1:12,2)']);
                                    app.GroundUITable.Data = tt;
                                    
                                    app.BoundaryUIAxes1.Visible = 'on';
                                    plot(app.BoundaryUIAxes1,datetime(building.boundary(selectedNodes(1)).ground(selectedNodes(3)).temperature(:,1)+45*8760*3600,'ConvertFrom','posixtime'),building.boundary(selectedNodes(1)).ground(selectedNodes(3)).temperature(:,2))
                                    title(app.BoundaryUIAxes1,['Temperatures ' app.GroundNameEditField.Value]);
                                    app.BoundaryUIAxes1.XGrid = 'on';
                                    app.BoundaryUIAxes1.YGrid = 'on';
                                    xlim(app.BoundaryUIAxes1,[datetime('31-Dec-2015 23:59:00') datetime('01-Jan-2017 00:00:00')]);
                                    ylabel(app.BoundaryUIAxes1,'temperatures [°C]');
                                    legend(app.BoundaryUIAxes1,{'ground'},'Location','bestoutside','Orientation','horizontal')
                                end
                            catch
                                
                            end
                        elseif selectedNodes(2) == 3
                            app.NeighbourPanel.Visible = 'on';
                            app.NeighbourNameEditField.Value = building.boundary(selectedNodes(1)).neighbour(selectedNodes(3)).name;
                            if selectedNodes(3) == 1
                                app.NeighbourUseasboundaryEditField.Value = ['NEIGHBOUR'];
                            else
                                app.NeighbourUseasboundaryEditField.Value = ['NEIGHBOUR_' num2str(selectedNodes(3))];
                            end
                            
                            app.NeighbourUITable.Data = [];
                            try
                                if size(building.boundary(selectedNodes(1)).neighbour(selectedNodes(3)).temperature, 1) == 85
                                    tt = array2table([building.boundary(selectedNodes(1)).neighbour(selectedNodes(3)).temperature(1:12,2)']);
                                    app.NeighbourUITable.Data = tt;
                                    
                                    app.BoundaryUIAxes1.Visible = 'on';
                                    plot(app.BoundaryUIAxes1,datetime(building.boundary(selectedNodes(1)).neighbour(selectedNodes(3)).temperature(:,1)+45*8760*3600,'ConvertFrom','posixtime'),building.boundary(selectedNodes(1)).neighbour(selectedNodes(3)).temperature(:,2))
                                    title(app.BoundaryUIAxes1,['Temperatures ' app.NeighbourNameEditField.Value]);
                                    app.BoundaryUIAxes1.XGrid = 'on';
                                    app.BoundaryUIAxes1.YGrid = 'on';
                                    xlim(app.BoundaryUIAxes1,[datetime('31-Dec-2015 23:59:00') datetime('01-Jan-2017 00:00:00')]);
                                    ylabel(app.BoundaryUIAxes1,'temperatures [°C]');
                                    legend(app.BoundaryUIAxes1,{'neighbour'},'Location','bestoutside','Orientation','horizontal')
                                end
                            catch
                                
                            end
                        end
                    end
                else
                    app.BoundaryPanel.Visible = 'off';
                end
                
                close(d);
            catch
                close(d);
            end
        end

        % Selection changed function: ThermalzoneTree
        function ThermalzoneTreeSelectionChanged(app, event)
            d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
            
            global building
            
            selectedNodes = app.ThermalzoneTree.SelectedNodes;
            if ~isempty(selectedNodes)
                app.ThermalzonePanel.Visible = 'on';
                app.ThermalzoneZonePanel.Visible = 'off';
                selectedNodes = app.ThermalzoneTree.SelectedNodes.NodeData;
                
                app.ThermalzoneVariantnameEditField.Value = building.thermalzone(selectedNodes(1)).name;
                
                if length(selectedNodes) > 2
                    if selectedNodes(2) == 1
                        app.ThermalzoneZonePanel.Visible = 'on';
                        
                        app.ThermalzoneZoneNameEditField.Value = building.thermalzone(selectedNodes(1)).zone(selectedNodes(3)).name;
                        app.ThermalzoneZoneCalculationmodelDropDown.Value = building.thermalzone(selectedNodes(1)).zone(selectedNodes(3)).model;
                        app.ThermalzoneZoneInitialtemperatureEditField.Value = building.thermalzone(selectedNodes(1)).zone(selectedNodes(3)).t_ini;
                        app.ThermalzoneZoneInitialrelhumidityEditField.Value = building.thermalzone(selectedNodes(1)).zone(selectedNodes(3)).phi_ini;
                        app.ThermalzoneZoneCpEditField.Value = building.thermalzone(selectedNodes(1)).zone(selectedNodes(3)).cp_spec;
                        app.ThermalzoneZoneTimeConstantEditField.Value = building.thermalzone(selectedNodes(1)).zone(selectedNodes(3)).timeconstant;
                        app.ThermalzoneZoneOrientationImportantDropDown.Value = building.thermalzone(selectedNodes(1)).zone(selectedNodes(3)).orientation_important;
                        app.ThermalzoneZoneProfileDropDown.Value = 'no change';
                        
                        date0 = datetime([2014,01,01],'Format','dd/MM/uuuu HH:mm');
                        dates = date0 + building.thermalzone(selectedNodes(1)).zone(selectedNodes(3)).profile_timevalues(1:(end-1))/24/3600;
                        T_convective = building.thermalzone(selectedNodes(1)).zone(selectedNodes(3)).profile_Tc;
                        T_radiative = building.thermalzone(selectedNodes(1)).zone(selectedNodes(3)).profile_Tr;
                        rH = building.thermalzone(selectedNodes(1)).zone(selectedNodes(3)).profile_rH;
                        CO2 = building.thermalzone(selectedNodes(1)).zone(selectedNodes(3)).profile_CO2;
                        pressure = building.thermalzone(selectedNodes(1)).zone(selectedNodes(3)).profile_p;
                        
                        tdata = table(dates', T_convective', T_radiative', rH', CO2', pressure', 'VariableNames', {'Date','T_convective','T_radiative','rel_humidity','CO2','pressure'});
                        app.ThermalzoneZoneProfileUITable.Data = tdata;
                        
                        if ~isempty(building.thermalzone(selectedNodes(1)).zone(selectedNodes(3)).rooms)
                            app.ThermalzoneZoneRoomsTextArea.Value = building.thermalzone(selectedNodes(1)).zone(selectedNodes(3)).rooms;
                        else
                            app.ThermalzoneZoneRoomsTextArea.Value = '';
                        end
                    else
                        app.ThermalzoneZonePanel.Visible = 'off';
                    end
                else
                    app.ThermalzoneZonePanel.Visible = 'off';
                end
                
            else
                app.ThermalzonePanel.Visible = 'off';
                app.ThermalzoneZonePanel.Visible = 'off';
            end
            
            close(d);
        end

        % Selection changed function: HVACTree
        function HVACTreeSelectionChanged(app, event)
            d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
            
            global building
            
            selectedNodes = app.HVACTree.SelectedNodes;
            if ~isempty(selectedNodes)
                app.HVACPanel.Visible = 'on';
                app.HVACSystemPanel.Visible = 'on';
                selectedNodes = app.HVACTree.SelectedNodes.NodeData;
                
                app.HVACVariantnameEditField.Value = building.hvac(selectedNodes(1)).name;
                
                if length(selectedNodes) == 2
                    app.HVACUITable.Visible = 'on';
                    app.HVACSystemParameterPanel.Visible = 'on';
                    
                    try
                        app.HVACSystemNameEditField.Value = building.hvac(selectedNodes(1)).system(selectedNodes(2)).name;
                        app.HVACSystemNumberEditField.Value = building.hvac(selectedNodes(1)).system(selectedNodes(2)).number;
                        
                        app.HVACUITable.ColumnName = {};
                        app.HVACUITable.Data = [];
                        
                        if ~isempty((building.hvac(selectedNodes(1)).system(selectedNodes(2)).parameter))
                            HVACparameter = dataset2table(struct2dataset(building.hvac(selectedNodes(1)).system(selectedNodes(2)).parameter));
                            
                            app.HVACUITable.ColumnName = HVACparameter.Properties.VariableNames;
                            app.HVACUITable.Data = HVACparameter;
                        end
                    catch ME
                        ME
                    end
                else
                    app.HVACUITable.Visible = 'off';
                    app.HVACSystemParameterPanel.Visible = 'off';
                end
            else
                app.HVACPanel.Visible = 'off';
                app.HVACSystemPanel.Visible = 'off';
                app.HVACSystemParameterPanel.Visible = 'off';
            end
            
            close(d);
        end

        % Cell selection callback: HVACUITable
        function HVACUITableCellSelection(app, event)
            indices = event.Indices;
            
            if isstruct(app.HVACUITable.Data(indices(1),indices(2)).Variables)
                if length(app.HVACUITable.Data(indices(1),indices(2)).Variables) > 1
                    data = app.HVACUITable.Data(indices(1),indices(2)).Variables;
                    for jj = 1:length(data)
                        disp(' ')
                        disp(['data set ' num2str(jj)])
                        disp(data(jj))
                    end
                else
                    disp(app.HVACUITable.Data(indices(1),indices(2)).Variables)
                end
            end
        end

        % Button pushed function: GeometryDeleteVariantButton
        function GeometryDeleteVariantButtonPushed(app, event)
            global building
            
            selection = uiconfirm(app.carnotUIBKUIFigure,'Do you really want to delete this variant?','Are you sure?','Options',{'Yes','No'},'Icon','question');
            
            if strcmp(selection,'Yes')
                selectedNodes = app.GeometryTree.SelectedNodes;
                if ~isempty(selectedNodes)
                    selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                    building.geometry(selectedNodes(1)) = GEOMETRY();
                    building.geometry(selectedNodes(1)).name = '';
                    TabGroupSelectionChanged(app,app.TabGroup.SelectionChangedFcn);
                    uiconfirm(app.carnotUIBKUIFigure,'Variant deleted!','Deleted!','Options',{'OK'},'Icon','success');
                end
            end
        end

        % Value changed function: ConstructionVariantnameEditField
        function ConstructionVariantnameEditFieldValueChanged(app, event)
            global building
            value = app.ConstructionVariantnameEditField.Value;
            
            selectedNodes = app.ConstructionTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.ConstructionTree.SelectedNodes.NodeData;
            end
            
            building.construction(selectedNodes(1)).name = value;
        end

        % Value changed function: GeometryVariantnameEditField
        function GeometryVariantnameEditFieldValueChanged(app, event)
            global building
            value = app.GeometryVariantnameEditField.Value;
            
            selectedNodes = app.GeometryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                
                building.geometry(selectedNodes(1)).name = value;
            end
            
            
        end

        % Value changed function: GainsVariantnameEditField
        function GainsVariantnameEditFieldValueChanged(app, event)
            global building
            value = app.GainsVariantnameEditField.Value;
            
            selectedNodes = app.GainsTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GainsTree.SelectedNodes.NodeData;
                
                building.gains(selectedNodes(1)).name = value;
            end
            
            
        end

        % Value changed function: BoundaryVariantnameEditField
        function BoundaryVariantnameEditFieldValueChanged(app, event)
            global building
            value = app.BoundaryVariantnameEditField.Value;
            
            selectedNodes = app.BoundaryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.BoundaryTree.SelectedNodes.NodeData;
                
                building.boundary(selectedNodes(1)).name = value;
            end
            
            
        end

        % Value changed function: ThermalzoneVariantnameEditField
        function ThermalzoneVariantnameEditFieldValueChanged(app, event)
            global building
            value = app.ThermalzoneVariantnameEditField.Value;
            
            selectedNodes = app.ThermalzoneTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.ThermalzoneTree.SelectedNodes.NodeData;
                
                building.thermalzone(selectedNodes(1)).name = value;
            end
            
            
        end

        % Value changed function: HVACVariantnameEditField
        function HVACVariantnameEditFieldValueChanged(app, event)
            global building
            value = app.HVACVariantnameEditField.Value;
            
            selectedNodes = app.HVACTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.HVACTree.SelectedNodes.NodeData;
                
                building.hvac(selectedNodes(1)).name = value;
            end
            
            
        end

        % Size changed function: ResultsTab
        function ResultsTabSizeChanged(app, event)
            position = app.ResultsTab.Position;
            
        end

        % Value changed function: GeometryRoomNameEditField
        function GeometryRoomNameEditFieldValueChanged(app, event)
            value = app.GeometryRoomNameEditField.Value;
            global building
            
            selectedNodes = app.GeometryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                building.geometry(selectedNodes(1)).room(selectedNodes(2)).name = value;
            end
        end

        % Value changed function: GeometryRoomAreaEditField
        function GeometryRoomAreaEditFieldValueChanged(app, event)
            value = app.GeometryRoomAreaEditField.Value;
            global building
            
            selectedNodes = app.GeometryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                building.geometry(selectedNodes(1)).room(selectedNodes(2)).area = value;
            end
        end

        % Value changed function: GeometryRoomHeightEditField
        function GeometryRoomHeightEditFieldValueChanged(app, event)
            value = app.GeometryRoomHeightEditField.Value;
            global building
            
            selectedNodes = app.GeometryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                building.geometry(selectedNodes(1)).room(selectedNodes(2)).height = value;
            end
        end

        % Value changed function: GeometryRoomn50EditField
        function GeometryRoomn50EditFieldValueChanged(app, event)
            value = app.GeometryRoomn50EditField.Value;
            global building
            
            selectedNodes = app.GeometryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                building.geometry(selectedNodes(1)).room(selectedNodes(2)).n50 = value;
            end
        end

        % Value changed function: GeometryWallNameEditField
        function GeometryWallNameEditFieldValueChanged(app, event)
            value = app.GeometryWallNameEditField.Value;
            global building
            
            selectedNodes = app.GeometryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).name = value;
            end
        end

        % Value changed function: GeometryWallWidthEditField
        function GeometryWallWidthEditFieldValueChanged(app, event)
            value = app.GeometryWallWidthEditField.Value;
            global building
            
            selectedNodes = app.GeometryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).width = value;
            end
        end

        % Value changed function: GeometryWallHeightEditField
        function GeometryWallHeightEditFieldValueChanged(app, event)
            value = app.GeometryWallHeightEditField.Value;
            global building
            
            selectedNodes = app.GeometryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).height = value;
            end
        end

        % Value changed function: GeometryWallSlopeEditField
        function GeometryWallSlopeEditFieldValueChanged(app, event)
            value = app.GeometryWallSlopeEditField.Value;
            global building
            
            selectedNodes = app.GeometryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).orientation_slope = value;
            end
        end

        % Value changed function: GeometryWallAzimuthEditField
        function GeometryWallAzimuthEditFieldValueChanged(app, event)
            value = app.GeometryWallAzimuthEditField.Value;
            global building
            
            selectedNodes = app.GeometryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).orientation_azimuth = value;
            end
        end

        % Value changed function: GeometryWallViewFacEditField
        function GeometryWallViewFacEditFieldValueChanged(app, event)
            value = app.GeometryWallViewFacEditField.Value;
            global building
            
            selectedNodes = app.GeometryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).view_factor = value;
            end
        end

        % Value changed function: GeometryWallAmbFactorEditField
        function GeometryWallAmbFactorEditFieldValueChanged(app, event)
            value = app.GeometryWallAmbFactorEditField.Value;
            global building
            
            selectedNodes = app.GeometryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).amb_factor = value;
            end
        end

        % Value changed function: 
        % GeometryWallModelConstructionDropDown
        function GeometryWallModelConstructionDropDownValueChanged(app, event)
            value = app.GeometryWallModelConstructionDropDown.Value;
            global building
            
            selectedNodes = app.GeometryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).model_cons = value;
            end
        end

        % Value changed function: 
        % GeometryWallModelHeattransferDropDown
        function GeometryWallModelHeattransferDropDownValueChanged(app, event)
            value = app.GeometryWallModelHeattransferDropDown.Value;
            global building
            
            selectedNodes = app.GeometryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).model_heattrans = value;
            end
        end

        % Value changed function: GeometryWallConstructionEditField
        function GeometryWallConstructionEditFieldValueChanged(app, event)
            value = app.GeometryWallConstructionEditField.Value;
            global building
            
            selectedNodes = app.GeometryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).construction = value;
            end
        end

        % Value changed function: GeometryWallInsideDropDown
        function GeometryWallInsideDropDownValueChanged(app, event)
            value = app.GeometryWallInsideDropDown.Value;
            global building
            
            selectedNodes = app.GeometryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).inside = value;
            end
        end

        % Value changed function: GeometryWallBoundaryEditField
        function GeometryWallBoundaryEditFieldValueChanged(app, event)
            value = app.GeometryWallBoundaryEditField.Value;
            global building
            
            selectedNodes = app.GeometryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).boundary = value;
            end
        end

        % Value changed function: GeometryWindowDoorNameEditField
        function GeometryWindowDoorNameEditFieldValueChanged(app, event)
            value = app.GeometryWindowDoorNameEditField.Value;
            global building
            
            selectedNodes = app.GeometryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                if selectedNodes(4) == 1
                    building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).name = value;
                else
                    building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).doors(selectedNodes(5)).name = value;
                end
            end
        end

        % Value changed function: GeometryWindowDoorWidthEditField
        function GeometryWindowDoorWidthEditFieldValueChanged(app, event)
            value = app.GeometryWindowDoorWidthEditField.Value;
            global building
            
            selectedNodes = app.GeometryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                if selectedNodes(4) == 1
                    building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).width = value;
                else
                    building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).doors(selectedNodes(5)).width = value;
                end
            end
        end

        % Value changed function: GeometryWindowDoorHeightEditField
        function GeometryWindowDoorHeightEditFieldValueChanged(app, event)
            value = app.GeometryWindowDoorHeightEditField.Value;
            global building
            
            selectedNodes = app.GeometryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                if selectedNodes(4) == 1
                    building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).height = value;
                else
                    building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).doors(selectedNodes(5)).height = value;
                end
            end
        end

        % Value changed function: 
        % GeometryWindowDoorConstructionEditField
        function GeometryWindowDoorConstructionEditFieldValueChanged(app, event)
            value = app.GeometryWindowDoorConstructionEditField.Value;
            global building
            
            selectedNodes = app.GeometryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                if selectedNodes(4) == 1
                    building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).construction = value;
                else
                    building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).doors(selectedNodes(5)).construction  = value;
                end
            end
        end

        % Value changed function: GeometryWindowDoorViewFacEditField
        function GeometryWindowDoorViewFacEditFieldValueChanged(app, event)
            value = app.GeometryWindowDoorViewFacEditField.Value;
            global building
            
            selectedNodes = app.GeometryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                if selectedNodes(4) == 1
                    building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).view_factor = value;
                else
                    building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).doors(selectedNodes(5)).view_factor = value;
                end
            end
        end

        % Value changed function: GeometryWindowDoorAmbFactorEditField
        function GeometryWindowDoorAmbFactorEditFieldValueChanged(app, event)
            value = app.GeometryWindowDoorAmbFactorEditField.Value;
            global building
            
            selectedNodes = app.GeometryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                if selectedNodes(4) == 1
                    building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).amb_factor = value;
                else
                    building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).doors(selectedNodes(5)).amb_factor = value;
                end
            end
        end

        % Value changed function: 
        % GeometryWindowDoorModelConstructionDropDown
        function GeometryWindowDoorModelConstructionDropDownValueChanged(app, event)
            value = app.GeometryWindowDoorModelConstructionDropDown.Value;
            global building
            
            selectedNodes = app.GeometryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                if selectedNodes(4) == 1
                    building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).model_cons = value;
                else
                    building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).doors(selectedNodes(5)).model_cons = value;
                    if value == 0
                        building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).doors(selectedNodes(5)).model_heattrans = 0;
                    else
                        building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).doors(selectedNodes(5)).model_heattrans = 2;
                    end
                end
            end
        end

        % Value changed function: GeometryWindowDoorDirtyFacEditField
        function GeometryWindowDoorDirtyFacEditFieldValueChanged(app, event)
            value = app.GeometryWindowDoorDirtyFacEditField.Value;
            global building
            
            selectedNodes = app.GeometryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                if selectedNodes(4) == 1
                    building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).fd = value;
                end
            end
        end

        % Value changed function: GeometryWindowDoorPsi1EditField
        function GeometryWindowDoorPsi1EditFieldValueChanged(app, event)
            value = app.GeometryWindowDoorPsi1EditField.Value;
            global building
            
            selectedNodes = app.GeometryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                if selectedNodes(4) == 1
                    building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).psi_i(1,1) = value;
                end
            end
        end

        % Value changed function: 
        % GeometryWindowDoorWidthGlassEditField
        function GeometryWindowDoorWidthGlassEditFieldValueChanged(app, event)
            value = app.GeometryWindowDoorWidthGlassEditField.Value;
            global building
            
            selectedNodes = app.GeometryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                if selectedNodes(4) == 1
                    building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).width_glass = value;
                end
            end
        end

        % Value changed function: GeometryWindowDoorPsi2EditField
        function GeometryWindowDoorPsi2EditFieldValueChanged(app, event)
            value = app.GeometryWindowDoorPsi2EditField.Value;
            global building
            
            selectedNodes = app.GeometryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                if selectedNodes(4) == 1
                    building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).psi_i(1,2) = value;
                end
            end
        end

        % Value changed function: GeometryWindowDoorPsi3EditField
        function GeometryWindowDoorPsi3EditFieldValueChanged(app, event)
            value = app.GeometryWindowDoorPsi3EditField.Value;
            global building
            
            selectedNodes = app.GeometryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                if selectedNodes(4) == 1
                    building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).psi_i(2,1) = value;
                end
            end
        end

        % Value changed function: GeometryWindowDoorPsi4EditField
        function GeometryWindowDoorPsi4EditFieldValueChanged(app, event)
            value = app.GeometryWindowDoorPsi4EditField.Value;
            global building
            
            selectedNodes = app.GeometryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                if selectedNodes(4) == 1
                    building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).psi_i(2,2) = value;
                end
            end
        end

        % Value changed function: 
        % GeometryWindowDoorHeightGlassEditField
        function GeometryWindowDoorHeightGlassEditFieldValueChanged(app, event)
            value = app.GeometryWindowDoorHeightGlassEditField.Value;
            global building
            
            selectedNodes = app.GeometryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                if selectedNodes(4) == 1
                    building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).height_glass = value;
                end
            end
        end

        % Value changed function: 
        % GeometryWindowDoorModelControlShadingDropDown
        function GeometryWindowDoorModelControlShadingDropDownValueChanged(app, event)
            value = app.GeometryWindowDoorModelControlShadingDropDown.Value;
            global building
            
            selectedNodes = app.GeometryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                if selectedNodes(4) == 1
                    building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).control_s = value;
                else
                    building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).doors(selectedNodes(5)).control_s = value;
                end
            end
        end

        % Value changed function: 
        % GeometryWindowDoorModelControlOpeningDropDown
        function GeometryWindowDoorModelControlOpeningDropDownValueChanged(app, event)
            value = app.GeometryWindowDoorModelControlOpeningDropDown.Value;
            global building
            
            selectedNodes = app.GeometryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                if selectedNodes(4) == 1
                    building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).control_i = value;
                else
                    building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).doors(selectedNodes(5)).control_i = value;
                end
            end
        end

        % Value changed function: GeometryWindowDoorShading1EditField
        function GeometryWindowDoorShading1EditFieldValueChanged(app, event)
            value = app.GeometryWindowDoorShading1EditField.Value;
            global building
            
            selectedNodes = app.GeometryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                if selectedNodes(4) == 1 && value >= 0
                    building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).fsvalue(1) = value;
                    building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).fsvalue(13) = value;
                    building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).fstime = [0,2678400,5097600,7776000,10368000,13046400,15638400,18316800,20995200,23587200,26265600,28857600,31536000];
                end
            end
        end

        % Value changed function: GeometryWindowDoorShading2EditField
        function GeometryWindowDoorShading2EditFieldValueChanged(app, event)
            value = app.GeometryWindowDoorShading2EditField.Value;
            global building
            
            selectedNodes = app.GeometryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                if selectedNodes(4) == 1 && value >= 0
                    building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).fsvalue(2) = value;
                    building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).fstime = [0,2678400,5097600,7776000,10368000,13046400,15638400,18316800,20995200,23587200,26265600,28857600,31536000];
                end
            end
        end

        % Value changed function: GeometryWindowDoorShading3EditField
        function GeometryWindowDoorShading3EditFieldValueChanged(app, event)
            value = app.GeometryWindowDoorShading3EditField.Value;
            global building
            
            selectedNodes = app.GeometryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                if selectedNodes(4) == 1 && value >= 0
                    building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).fsvalue(3) = value;
                    building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).fstime = [0,2678400,5097600,7776000,10368000,13046400,15638400,18316800,20995200,23587200,26265600,28857600,31536000];
                end
            end
        end

        % Value changed function: GeometryWindowDoorShading4EditField
        function GeometryWindowDoorShading4EditFieldValueChanged(app, event)
            value = app.GeometryWindowDoorShading4EditField.Value;
            global building
            
            selectedNodes = app.GeometryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                if selectedNodes(4) == 1 && value >= 0
                    building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).fsvalue(4) = value;
                    building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).fstime = [0,2678400,5097600,7776000,10368000,13046400,15638400,18316800,20995200,23587200,26265600,28857600,31536000];
                end
            end
        end

        % Value changed function: GeometryWindowDoorShading5EditField
        function GeometryWindowDoorShading5EditFieldValueChanged(app, event)
            value = app.GeometryWindowDoorShading5EditField.Value;
            global building
            
            selectedNodes = app.GeometryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                if selectedNodes(4) == 1 && value >= 0
                    building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).fsvalue(5) = value;
                    building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).fstime = [0,2678400,5097600,7776000,10368000,13046400,15638400,18316800,20995200,23587200,26265600,28857600,31536000];
                end
            end
        end

        % Value changed function: GeometryWindowDoorShading6EditField
        function GeometryWindowDoorShading6EditFieldValueChanged(app, event)
            value = app.GeometryWindowDoorShading6EditField.Value;
            global building
            
            selectedNodes = app.GeometryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                if selectedNodes(4) == 1 && value >= 0
                    building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).fsvalue(6) = value;
                    building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).fstime = [0,2678400,5097600,7776000,10368000,13046400,15638400,18316800,20995200,23587200,26265600,28857600,31536000];
                end
            end
        end

        % Value changed function: GeometryWindowDoorShading7EditField
        function GeometryWindowDoorShading7EditFieldValueChanged(app, event)
            value = app.GeometryWindowDoorShading7EditField.Value;
            global building
            
            selectedNodes = app.GeometryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                if selectedNodes(4) == 1 && value >= 0
                    building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).fsvalue(7) = value;
                    building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).fstime = [0,2678400,5097600,7776000,10368000,13046400,15638400,18316800,20995200,23587200,26265600,28857600,31536000];
                end
            end
        end

        % Value changed function: GeometryWindowDoorShading8EditField
        function GeometryWindowDoorShading8EditFieldValueChanged(app, event)
            value = app.GeometryWindowDoorShading8EditField.Value;
            global building
            
            selectedNodes = app.GeometryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                if selectedNodes(4) == 1 && value >= 0
                    building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).fsvalue(8) = value;
                    building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).fstime = [0,2678400,5097600,7776000,10368000,13046400,15638400,18316800,20995200,23587200,26265600,28857600,31536000];
                end
            end
        end

        % Value changed function: GeometryWindowDoorShading9EditField
        function GeometryWindowDoorShading9EditFieldValueChanged(app, event)
            value = app.GeometryWindowDoorShading9EditField.Value;
            global building
            
            selectedNodes = app.GeometryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                if selectedNodes(4) == 1 && value >= 0
                    building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).fsvalue(9) = value;
                    building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).fstime = [0,2678400,5097600,7776000,10368000,13046400,15638400,18316800,20995200,23587200,26265600,28857600,31536000];
                end
            end
        end

        % Value changed function: GeometryWindowDoorShading10EditField
        function GeometryWindowDoorShading10EditFieldValueChanged(app, event)
            value = app.GeometryWindowDoorShading10EditField.Value;
            global building
            
            selectedNodes = app.GeometryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                if selectedNodes(4) == 1 && value >= 0
                    building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).fsvalue(10) = value;
                    building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).fstime = [0,2678400,5097600,7776000,10368000,13046400,15638400,18316800,20995200,23587200,26265600,28857600,31536000];
                end
            end
        end

        % Value changed function: GeometryWindowDoorShading11EditField
        function GeometryWindowDoorShading11EditFieldValueChanged(app, event)
            value = app.GeometryWindowDoorShading11EditField.Value;
            global building
            
            selectedNodes = app.GeometryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                if selectedNodes(4) == 1 && value >= 0
                    building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).fsvalue(11) = value;
                end
            end
        end

        % Value changed function: GeometryWindowDoorShading12EditField
        function GeometryWindowDoorShading12EditFieldValueChanged(app, event)
            value = app.GeometryWindowDoorShading12EditField.Value;
            global building
            
            selectedNodes = app.GeometryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                if selectedNodes(4) == 1 && value >= 0
                    building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).fsvalue(12) = value;
                    building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)).fstime = [0,2678400,5097600,7776000,10368000,13046400,15638400,18316800,20995200,23587200,26265600,28857600,31536000];
                end
            end
        end

        % Close request function: carnotUIBKUIFigure
        function carnotUIBKUIFigureCloseRequest(app, event)
            global building
                
            if ~isempty(building.name)
                selection = uiconfirm(app.carnotUIBKUIFigure,'Do you want to save the building?','Save?','Options',{'Yes','No'},'Icon','question');
                
                if strcmp(selection,'Yes')
                    disp('Save building ...')
                    building.save;
                end
            end
            
            delete(app)
            
        end

        % Button pushed function: GeometryWallCalculateButton
        function GeometryWallCalculateButtonPushed(app, event)
            slope = app.GeometryWallSlopeEditField.Value;
            app.GeometryWallAmbFactorEditField.Value = 1 - (1+cos(slope/360*2*pi))/2;
        end

        % Button pushed function: GeometryCopyVariantButton
        function GeometryCopyVariantButtonPushed(app, event)
            global building
            
            selection = uiconfirm(app.carnotUIBKUIFigure,'Do you want to copy this variant?','Copy?','Options',{'Yes','No'},'Icon','question');
            
            if strcmp(selection,'Yes')
                selectedNodes = app.GeometryTree.SelectedNodes;
                if ~isempty(selectedNodes)
                    selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                    try
                        d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                        building.geometry(end+1) = building.geometry(selectedNodes(1));
                        TabGroupSelectionChanged(app,app.TabGroup.SelectionChangedFcn);
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'Variant copied!','Copied!','Options',{'OK'},'Icon','success');
                    catch ME
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'There was an error while coping the variant!','Copy was not working!','Options',{'OK'},'Icon','error');
                    end
                end
                
            end
        end

        % Button pushed function: GeometryExportVariantButton
        function GeometryExportVariantButtonPushed(app, event)
            global building
            
            selection = uiconfirm(app.carnotUIBKUIFigure,'Do you want to export this variant?','Export?','Options',{'Yes','No'},'Icon','question');
            
            if strcmp(selection,'Yes')
                selectedNodes = app.GeometryTree.SelectedNodes;
                if ~isempty(selectedNodes)
                    selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                    try
                        d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                        building.geometry.geometry_to_excel(['export_geometry_variant' num2str(selectedNodes(1)) '.xlsx'], building, selectedNodes(1));
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'Export done!','Done!','Options',{'OK'},'Icon','success');
                    catch ME
                        ME
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'There was an error during the export!','Not working!','Options',{'OK'},'Icon','error');
                    end
                end
            end
        end

        % Button pushed function: SimulateSaveSimulationButton
        function SimulateSaveSimulationButtonPushed(app, event)
            global building
            
            sim_nr = app.SimulateSimulationnumberEditField.Value;
            
            if evalin('base', 'exist(''saveAIB'',''var'') == 1')
                try
                    if sim_nr <= length(building.result)
                        if building.result(sim_nr).number > 0
                            answer = uiconfirm(app.carnotUIBKUIFigure,['There is already a result with the number ' num2str(sim_nr) ' existing. Would you like to override the results?'],'Overwrite results?');
                            if strcmp(answer, 'OK')
                                d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                                evalin('base',['building = building.add_simulation(' num2str(sim_nr) ', ''' app.SimulateDescriptionEditField.Value ''', saveAIB, saveBDB, saveBOUNDARY, saveHVAC, 1);'])
                                close(d)
                                uiconfirm(app.carnotUIBKUIFigure,'Simulation saved!','Done!','Options',{'OK'},'Icon','success');
                            else
                                return
                            end
                        else
                            d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                            evalin('base',['building = building.add_simulation(' num2str(sim_nr) ', ''' app.SimulateDescriptionEditField.Value ''', saveAIB, saveBDB, saveBOUNDARY, saveHVAC, 1);'])
                            close(d)
                            uiconfirm(app.carnotUIBKUIFigure,'Simulation saved!','Done!','Options',{'OK'},'Icon','success');
                        end
                    else
                        d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                        evalin('base',['building = building.add_simulation(' num2str(sim_nr) ', ''' app.SimulateDescriptionEditField.Value ''', saveAIB, saveBDB, saveBOUNDARY, saveHVAC, 1);'])
                        close(d)
                        uiconfirm(app.carnotUIBKUIFigure,'Simulation saved!','Done!','Options',{'OK'},'Icon','success');
                    end
                catch ME
                    ME
                    uiconfirm(app.carnotUIBKUIFigure,'There was an error! Are the simulation results valid?','Error during saving!','Options',{'OK'},'Icon','error');
                end
            else
                uiconfirm(app.carnotUIBKUIFigure,'No simulation result could be found!','No simulation result!','Options',{'OK'},'Icon','error');
            end
        end

        % Button pushed function: ConstructionDeleteVariantButton
        function ConstructionDeleteVariantButtonPushed(app, event)
            global building
            
            selection = uiconfirm(app.carnotUIBKUIFigure,'Do you really want to delete this variant?','Are you sure?','Options',{'Yes','No'},'Icon','question');
            
            if strcmp(selection,'Yes')
                selectedNodes = app.ConstructionTree.SelectedNodes;
                if ~isempty(selectedNodes)
                    selectedNodes = app.ConstructionTree.SelectedNodes.NodeData;
                    building.construction(selectedNodes(1)) = CONSTRUCTION();
                    building.construction(selectedNodes(1)).name = '';
                    TabGroupSelectionChanged(app,app.TabGroup.SelectionChangedFcn);
                    uiconfirm(app.carnotUIBKUIFigure,'Variant deleted!','Deleted!','Options',{'OK'},'Icon','success');
                end
                
            end
        end

        % Button pushed function: ConstructionCopyVariantButton
        function ConstructionCopyVariantButtonPushed(app, event)
            global building
            
            selection = uiconfirm(app.carnotUIBKUIFigure,'Do you want to copy this variant?','Copy?','Options',{'Yes','No'},'Icon','question');
            
            if strcmp(selection,'Yes')
                selectedNodes = app.ConstructionTree.SelectedNodes;
                if ~isempty(selectedNodes)
                    selectedNodes = app.ConstructionTree.SelectedNodes.NodeData;
                    try
                        d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                        building.construction(end+1) = building.construction(selectedNodes(1));
                        TabGroupSelectionChanged(app,app.TabGroup.SelectionChangedFcn);
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'Variant copied!','Copied!','Options',{'OK'},'Icon','success');
                    catch ME
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'There was an error while coping the variant!','Copy was not working!','Options',{'OK'},'Icon','error');
                    end
                end
                
            end
        end

        % Button pushed function: ConstructionExportVariantButton
        function ConstructionExportVariantButtonPushed(app, event)
            global building
            
            selection = uiconfirm(app.carnotUIBKUIFigure,'Do you want to export this variant?','Export?','Options',{'Yes','No'},'Icon','question');
            
            if strcmp(selection,'Yes')
                selectedNodes = app.ConstructionTree.SelectedNodes;
                if ~isempty(selectedNodes)
                    selectedNodes = app.ConstructionTree.SelectedNodes.NodeData;
                    try
                        d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                        building.construction.construction_to_excel(['export_construction_variant' num2str(selectedNodes(1)) '.xlsx'], building, selectedNodes(1));
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'Export done!','Done!','Options',{'OK'},'Icon','success');
                    catch ME
                        ME
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'There was an error during the export!','Not working!','Options',{'OK'},'Icon','error');
                    end
                end
            end
        end

        % Button pushed function: GainsDeleteVariantButton
        function GainsDeleteVariantButtonPushed(app, event)
            global building
            
            selection = uiconfirm(app.carnotUIBKUIFigure,'Do you really want to delete this variant?','Are you sure?','Options',{'Yes','No'},'Icon','question');
            
            if strcmp(selection,'Yes')
                selectedNodes = app.GainsTree.SelectedNodes;
                if ~isempty(selectedNodes)
                    selectedNodes = app.GainsTree.SelectedNodes.NodeData;
                    building.gains(selectedNodes(1)) = GAINS();
                    building.gains(selectedNodes(1)).name = '';
                    TabGroupSelectionChanged(app,app.TabGroup.SelectionChangedFcn);
                    uiconfirm(app.carnotUIBKUIFigure,'Variant deleted!','Deleted!','Options',{'OK'},'Icon','success');
                end
                
            end
        end

        % Button pushed function: GainsCopyVariantButton
        function GainsCopyVariantButtonPushed(app, event)
            global building
            
            selection = uiconfirm(app.carnotUIBKUIFigure,'Do you want to copy this variant?','Copy?','Options',{'Yes','No'},'Icon','question');
            
            if strcmp(selection,'Yes')
                selectedNodes = app.GainsTree.SelectedNodes;
                if ~isempty(selectedNodes)
                    selectedNodes = app.GainsTree.SelectedNodes.NodeData;
                    try
                        d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                        building.gains(end+1) = building.gains(selectedNodes(1));
                        TabGroupSelectionChanged(app,app.TabGroup.SelectionChangedFcn);
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'Variant copied!','Copied!','Options',{'OK'},'Icon','success');
                    catch ME
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'There was an error while coping the variant!','Copy was not working!','Options',{'OK'},'Icon','error');
                    end
                end
                
            end
        end

        % Button pushed function: GainsExportVariantButton
        function GainsExportVariantButtonPushed(app, event)
            global building
            
            selection = uiconfirm(app.carnotUIBKUIFigure,'Do you want to export this variant?','Export?','Options',{'Yes','No'},'Icon','question');
            
            if strcmp(selection,'Yes')
                selectedNodes = app.GainsTree.SelectedNodes;
                if ~isempty(selectedNodes)
                    selectedNodes = app.GainsTree.SelectedNodes.NodeData;
                    try
                        d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                        building.gains.gains_to_excel(['export_gains_variant' num2str(selectedNodes(1)) '.xlsx'], building, selectedNodes(1));
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'Export done!','Done!','Options',{'OK'},'Icon','success');
                    catch ME
                        ME
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'There was an error during the export!','Not working!','Options',{'OK'},'Icon','error');
                    end
                end
            end
        end

        % Button pushed function: BoundaryDeleteVariantButton
        function BoundaryDeleteVariantButtonPushed(app, event)
            global building
            
            selection = uiconfirm(app.carnotUIBKUIFigure,'Do you really want to delete this variant?','Are you sure?','Options',{'Yes','No'},'Icon','question');
            
            if strcmp(selection,'Yes')
                selectedNodes = app.BoundaryTree.SelectedNodes;
                if ~isempty(selectedNodes)
                    selectedNodes = app.BoundaryTree.SelectedNodes.NodeData;
                    building.boundary(selectedNodes(1)) = BOUNDARY();
                    building.boundary(selectedNodes(1)).name = '';
                    TabGroupSelectionChanged(app,app.TabGroup.SelectionChangedFcn);
                    uiconfirm(app.carnotUIBKUIFigure,'Variant deleted!','Deleted!','Options',{'OK'},'Icon','success');
                end
                
            end
        end

        % Button pushed function: BoundaryCopyVariantButton
        function BoundaryCopyVariantButtonPushed(app, event)
            global building
            
            selection = uiconfirm(app.carnotUIBKUIFigure,'Do you want to copy this variant?','Copy?','Options',{'Yes','No'},'Icon','question');
            
            if strcmp(selection,'Yes')
                selectedNodes = app.BoundaryTree.SelectedNodes;
                if ~isempty(selectedNodes)
                    selectedNodes = app.BoundaryTree.SelectedNodes.NodeData;
                    try
                        d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                        building.boundary(end+1) = building.boundary(selectedNodes(1));
                        TabGroupSelectionChanged(app,app.TabGroup.SelectionChangedFcn);
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'Variant copied!','Copied!','Options',{'OK'},'Icon','success');
                    catch ME
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'There was an error while coping the variant!','Copy was not working!','Options',{'OK'},'Icon','error');
                    end
                end
                
            end
        end

        % Button pushed function: BoundaryExportVariantButton
        function BoundaryExportVariantButtonPushed(app, event)
            global building
            
            selection = uiconfirm(app.carnotUIBKUIFigure,'Do you want to export this variant?','Export?','Options',{'Yes','No'},'Icon','question');
            
            if strcmp(selection,'Yes')
                selectedNodes = app.BoundaryTree.SelectedNodes;
                if ~isempty(selectedNodes)
                    selectedNodes = app.BoundaryTree.SelectedNodes.NodeData;
                    try
                        d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                        building.boundary.ground_neighbour_weather_to_excel(['export_boundary_variant' num2str(selectedNodes(1)) '.xlsx'], building, selectedNodes(1));
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'Export done!','Done!','Options',{'OK'},'Icon','success');
                    catch ME
                        ME
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'There was an error during the export!','Not working!','Options',{'OK'},'Icon','error');
                    end
                end
            end
        end

        % Button pushed function: ThermalzoneDeleteVariantButton
        function ThermalzoneDeleteVariantButtonPushed(app, event)
            global building
            
            selection = uiconfirm(app.carnotUIBKUIFigure,'Do you really want to delete this variant?','Are you sure?','Options',{'Yes','No'},'Icon','question');
            
            if strcmp(selection,'Yes')
                selectedNodes = app.ThermalzoneTree.SelectedNodes;
                if ~isempty(selectedNodes)
                    selectedNodes = app.ThermalzoneTree.SelectedNodes.NodeData;
                    building.thermalzone(selectedNodes(1)) = THERMALZONE();
                    building.thermalzone(selectedNodes(1)).name = '';
                    TabGroupSelectionChanged(app,app.TabGroup.SelectionChangedFcn);
                    uiconfirm(app.carnotUIBKUIFigure,'Variant deleted!','Deleted!','Options',{'OK'},'Icon','success');
                end
                
            end
        end

        % Button pushed function: ThermalzoneCopyVariantButton
        function ThermalzoneCopyVariantButtonPushed(app, event)
            global building
            
            selection = uiconfirm(app.carnotUIBKUIFigure,'Do you want to copy this variant?','Copy?','Options',{'Yes','No'},'Icon','question');
            
            if strcmp(selection,'Yes')
                selectedNodes = app.ThermalzoneTree.SelectedNodes;
                if ~isempty(selectedNodes)
                    selectedNodes = app.ThermalzoneTree.SelectedNodes.NodeData;
                    try
                        d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                        building.thermalzone(end+1) = building.thermalzone(selectedNodes(1));
                        TabGroupSelectionChanged(app,app.TabGroup.SelectionChangedFcn);
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'Variant copied!','Copied!','Options',{'OK'},'Icon','success');
                    catch ME
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'There was an error while coping the variant!','Copy was not working!','Options',{'OK'},'Icon','error');
                    end
                end
                
            end
        end

        % Button pushed function: ThermalzoneExportVariantButton
        function ThermalzoneExportVariantButtonPushed(app, event)
            global building
            
            selection = uiconfirm(app.carnotUIBKUIFigure,'Do you want to export this variant?','Export?','Options',{'Yes','No'},'Icon','question');
            
            if strcmp(selection,'Yes')
                selectedNodes = app.ThermalzoneTree.SelectedNodes;
                if ~isempty(selectedNodes)
                    selectedNodes = app.ThermalzoneTree.SelectedNodes.NodeData;
                    try
                        d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                        building.thermalzone.zone_to_excel(['export_thermalzone_variant' num2str(selectedNodes(1)) '.xlsx'], building, selectedNodes(1));
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'Export done!','Done!','Options',{'OK'},'Icon','success');
                    catch ME
                        ME
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'There was an error during the export!','Not working!','Options',{'OK'},'Icon','error');
                    end
                end
            end
        end

        % Button pushed function: HVACDeleteVariantButton
        function HVACDeleteVariantButtonPushed(app, event)
            global building
            
            selection = uiconfirm(app.carnotUIBKUIFigure,'Do you really want to delete this variant?','Are you sure?','Options',{'Yes','No'},'Icon','question');
            
            if strcmp(selection,'Yes')
                selectedNodes = app.HVACTree.SelectedNodes;
                if ~isempty(selectedNodes)
                    selectedNodes = app.HVACTree.SelectedNodes.NodeData;
                    building.hvac(selectedNodes(1)) = HVAC();
                    building.hvac(selectedNodes(1)).name = '';
                    TabGroupSelectionChanged(app,app.TabGroup.SelectionChangedFcn);
                    uiconfirm(app.carnotUIBKUIFigure,'Variant deleted!','Deleted!','Options',{'OK'},'Icon','success');
                end
                
            end
        end

        % Button pushed function: HVACCopyVariantButton
        function HVACCopyVariantButtonPushed(app, event)
            global building
            
            selection = uiconfirm(app.carnotUIBKUIFigure,'Do you want to copy this variant?','Copy?','Options',{'Yes','No'},'Icon','question');
            
            if strcmp(selection,'Yes')
                selectedNodes = app.HVACTree.SelectedNodes;
                if ~isempty(selectedNodes)
                    selectedNodes = app.HVACTree.SelectedNodes.NodeData;
                    try
                        d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                        building.hvac(end+1) = building.hvac(selectedNodes(1));
                        TabGroupSelectionChanged(app,app.TabGroup.SelectionChangedFcn);
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'Variant copied!','Copied!','Options',{'OK'},'Icon','success');
                    catch ME
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'There was an error while coping the variant!','Copy was not working!','Options',{'OK'},'Icon','error');
                    end
                end
                
            end
        end

        % Button pushed function: HVACExportVariantButton
        function HVACExportVariantButtonPushed(app, event)
            global building
            
            selection = uiconfirm(app.carnotUIBKUIFigure,'Do you want to export this variant?','Export?','Options',{'Yes','No'},'Icon','question');
            
            if strcmp(selection,'Yes')
                selectedNodes = app.HVACTree.SelectedNodes;
                if ~isempty(selectedNodes)
                    selectedNodes = app.HVACTree.SelectedNodes.NodeData;
                    try
                        d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                        HVACsave = building.hvac(selectedNodes(1));
                        save(['export_HVAC_variant' num2str(selectedNodes(1)) ''],'HVACsave','-v7.3')
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'Export done!','Done!','Options',{'OK'},'Icon','success');
                    catch ME
                        ME
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'There was an error during the export!','Not working!','Options',{'OK'},'Icon','error');
                    end
                else
                    uiconfirm(app.carnotUIBKUIFigure,'Please choose a variant first!','Not working!','Options',{'OK'},'Icon','error');
                end
            
            end
        end

        % Button pushed function: GeometryRoomAddWallButton
        function GeometryRoomAddWallButtonPushed(app, event)
            global building
            
            selection = uiconfirm(app.carnotUIBKUIFigure,'Do you want to add a wall?','Add wall?','Options',{'Yes','No'},'Icon','question');
            
            if strcmp(selection,'Yes')
                selectedNodes = app.GeometryTree.SelectedNodes;
                if ~isempty(selectedNodes)
                    selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                    try
                        d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                        if isempty(building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall)
                            building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall = WALL('new wall', 'AMBIENT', 0, 0, 0, 0.01, 0.01, 90, 180, 0, 1, 'wall_construction', 2, 2, 1.0, 0.5, 0, [0 0 0], [0 0 0], [0 0 0], 1, [], []);
                        else
                            building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(end+1) = WALL('new wall', 'AMBIENT', 0, 0, 0, 0.01, 0.01, 90, 180, 0, 1, 'wall_construction', 2, 2, 1.0, 0.5, 0, [0 0 0], [0 0 0], [0 0 0], 1, [], []);
                        end
                        TabGroupSelectionChanged(app,app.TabGroup.SelectionChangedFcn);
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'Wall added!','Adding a wall!','Options',{'OK'},'Icon','success');
                    catch ME
                        ME
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'There was an error while adding the wall!','Adding a wall was not working!','Options',{'OK'},'Icon','error');
                    end
                end
            end
        end

        % Button pushed function: GeometryRoomAddWindow
        function GeometryRoomAddWindowButtonPushed(app, event)
            global building
            
            selection = uiconfirm(app.carnotUIBKUIFigure,'Do you want to add a window?','Add window?','Options',{'Yes','No'},'Icon','question');
            
            if strcmp(selection,'Yes')
                selectedNodes = app.GeometryTree.SelectedNodes;
                if ~isempty(selectedNodes)
                    selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                    try
                        d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                        if isempty(building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows)
                            building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows = WINDOW('new window', 2, 0, 0, 0.05, 0.05, 0.01, 0.01, 1.0, 0.5, [0 0], [0 0], [0 0], [0 0], [0 31 59 90 120 151 181 212 243 273 304 334 365]*24*3600 + 5*365*24*3600, [1 1 1 1 1 1 1 1 1 1 1 1 1], 1.0, [0.04 0.04; 0.04 0.04], 1, 'windows construction', 0, [0 0 0], [0 0 0], [0 0 0], 1);
                        else
                            building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(end+1) = WINDOW('new window', 2, 0, 0, 0.05, 0.05, 0.01, 0.01, 1.0, 0.5, [0 0], [0 0], [0 0], [0 0], [0 31 59 90 120 151 181 212 243 273 304 334 365]*24*3600 + 5*365*24*3600, [1 1 1 1 1 1 1 1 1 1 1 1 1], 1.0, [0.04 0.04; 0.04 0.04], 1, 'windows construction', 0, [0 0 0], [0 0 0], [0 0 0], 1);
                        end
                        TabGroupSelectionChanged(app,app.TabGroup.SelectionChangedFcn);
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'Window added!','Adding a window!','Options',{'OK'},'Icon','success');
                    catch ME
                        ME
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'There was an error while adding the window!','Adding a window was not working!','Options',{'OK'},'Icon','error');
                    end
                end
            end
        end

        % Button pushed function: GeometryRoomAddDoor
        function GeometryRoomAddDoorButtonPushed(app, event)
            global building
            
            selection = uiconfirm(app.carnotUIBKUIFigure,'Do you want to add a door?','Add door?','Options',{'Yes','No'},'Icon','question');
            
            if strcmp(selection,'Yes')
                selectedNodes = app.GeometryTree.SelectedNodes;
                if ~isempty(selectedNodes)
                    selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                    try
                        d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                        if isempty(building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).doors)
                            building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).doors = DOOR('new door', 0, 0, 0.01, 0.01, 'door construction', 2, 2, 1.0, 0.5, [0 0], [0 0], [0 0], 1, 0);
                        else
                            building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).doors(end+1) = DOOR('new door', 0, 0, 0.01, 0.01, 'door construction', 2, 2, 1.0, 0.5, [0 0], [0 0], [0 0], 1, 0);
                        end
                        TabGroupSelectionChanged(app,app.TabGroup.SelectionChangedFcn);
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'Door added!','Adding a door!','Options',{'OK'},'Icon','success');
                    catch ME
                        ME
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'There was an error while adding the door!','Adding a door was not working!','Options',{'OK'},'Icon','error');
                    end
                end
            end
        end

        % Button pushed function: GeometryRoomDeleteRoomButton
        function GeometryRoomDeleteRoomButtonPushed(app, event)
            global building
            
            selection = uiconfirm(app.carnotUIBKUIFigure,'Do you want to delete the room?','Delete room?','Options',{'Yes','No'},'Icon','question');
            
            if strcmp(selection,'Yes')
                selectedNodes = app.GeometryTree.SelectedNodes;
                if ~isempty(selectedNodes)
                    selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                    try
                        d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                        building.geometry(selectedNodes(1)).room(selectedNodes(2)) = [];
                        TabGroupSelectionChanged(app,app.TabGroup.SelectionChangedFcn);
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'Room deleted!','Deleting a room!','Options',{'OK'},'Icon','success');
                    catch ME
                        ME
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'There was an error while deleting the room!','Deleting the room was not working!','Options',{'OK'},'Icon','error');
                    end
                end
            end
        end

        % Button pushed function: GeometryRoomDeleteWallButton
        function GeometryRoomDeleteWallButtonPushed(app, event)
            global building
            
            selection = uiconfirm(app.carnotUIBKUIFigure,'Do you want to delete the wall?','Delete wall?','Options',{'Yes','No'},'Icon','question');
            
            if strcmp(selection,'Yes')
                selectedNodes = app.GeometryTree.SelectedNodes;
                if ~isempty(selectedNodes)
                    selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                    try
                        d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                        building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)) = [];
                        TabGroupSelectionChanged(app,app.TabGroup.SelectionChangedFcn);
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'Wall deleted!','Deleting a wall!','Options',{'OK'},'Icon','success');
                    catch ME
                        ME
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'There was an error while deleting the wall!','Deleting the wall was not working!','Options',{'OK'},'Icon','error');
                    end
                end
            end
        end

        % Button pushed function: GeometryRoomDeleteWindowDoorButton
        function GeometryRoomDeleteWindowDoorButtonPushed(app, event)
            global building
            
            selection = uiconfirm(app.carnotUIBKUIFigure,'Do you want to delete the window/door?','Delete window/door?','Options',{'Yes','No'},'Icon','question');
            
            if strcmp(selection,'Yes')
                selectedNodes = app.GeometryTree.SelectedNodes;
                if ~isempty(selectedNodes)
                    selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                    try
                        d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                        if selectedNodes(4) == 1
                            building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).windows(selectedNodes(5)) = [];
                        else
                            building.geometry(selectedNodes(1)).room(selectedNodes(2)).wall(selectedNodes(3)).doors(selectedNodes(5)) = [];
                        end
                        TabGroupSelectionChanged(app,app.TabGroup.SelectionChangedFcn);
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'Window/door deleted!','Deleting a window/door!','Options',{'OK'},'Icon','success');
                    catch ME
                        ME
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'There was an error while deleting the window/door!','Deleting the window/door was not working!','Options',{'OK'},'Icon','error');
                    end
                end
            end
        end

        % Button pushed function: GeometryRoomAddRoomButton
        function GeometryRoomAddRoomButtonPushed(app, event)
            global building
            
            selection = uiconfirm(app.carnotUIBKUIFigure,'Do you want to add a room?','Add room?','Options',{'Yes','No'},'Icon','question');
            
            if strcmp(selection,'Yes')
                selectedNodes = app.GeometryTree.SelectedNodes;
                if ~isempty(selectedNodes)
                    selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                    try
                        d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                        if isempty(building.geometry(selectedNodes(1)).room)
                            building.geometry(selectedNodes(1)).room = ROOM('new room', 0.01, 2.5, [], 0.6);
                        else
                            building.geometry(selectedNodes(1)).room(end+1) = ROOM('new room', 0.01, 2.5, [], 0.6);
                        end
                        TabGroupSelectionChanged(app,app.TabGroup.SelectionChangedFcn);
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'Room added!','Adding a room!','Options',{'OK'},'Icon','success');
                    catch ME
                        ME
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'There was an error while adding the room!','Adding a room was not working!','Options',{'OK'},'Icon','error');
                    end
                end
            end
        end

        % Button pushed function: HVACSystemAddButton
        function HVACSystemAddButtonPushed(app, event)
            global building
            
            selection = uiconfirm(app.carnotUIBKUIFigure,'Do you want to add a empty system?','Add system?','Options',{'Yes','No'},'Icon','question');
            
            if strcmp(selection,'Yes')
                selectedNodes = app.HVACTree.SelectedNodes;
                if ~isempty(selectedNodes)
                    selectedNodes = app.HVACTree.SelectedNodes.NodeData;
                    try
                        d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                        building.hvac(selectedNodes(1)) = building.hvac(selectedNodes(1)).add_system('new HVAC SYSTEM',999,[]);
                        TabGroupSelectionChanged(app,app.TabGroup.SelectionChangedFcn);
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'Adding system done!','Done!','Options',{'OK'},'Icon','success');
                    catch ME
                        ME
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'There was an error during the adding!','Not working!','Options',{'OK'},'Icon','error');
                    end
                end
            end
        end

        % Button pushed function: HVACSystemImportButton
        function HVACSystemImportButtonPushed(app, event)
            global building
            
            filename = app.HVACSystemImportFileDropDown.Value;
            
            if ~isempty(filename)
                selection = uiconfirm(app.carnotUIBKUIFigure,'Do you want to import the system?','Import system?','Options',{'Yes','No'},'Icon','question');
                
                if strcmp(selection,'Yes')
                    selectedNodes = app.HVACTree.SelectedNodes;
                    if ~isempty(selectedNodes)
                        selectedNodes = app.HVACTree.SelectedNodes.NodeData;
                        try
                            d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                            import = 0;
                            mat_test = load(filename);
                            if isfield(mat_test,'SYSTEM_HVACsave')
                                import = 1;
                            end
                            if import
                                systemname = mat_test.SYSTEM_HVACsave.name;
                                systemnumber = mat_test.SYSTEM_HVACsave.number;
                                systemparameter = mat_test.SYSTEM_HVACsave.parameter;
                                building.hvac(selectedNodes(1)) = building.hvac(selectedNodes(1)).add_system(systemname,systemnumber,systemparameter);
                                TabGroupSelectionChanged(app,app.TabGroup.SelectionChangedFcn);
                                close(d);
                                uiconfirm(app.carnotUIBKUIFigure,'Import system done!','Done!','Options',{'OK'},'Icon','success');
                            else
                                close(d);
                                uiconfirm(app.carnotUIBKUIFigure,'The SYSTEM HVAC file is not valid!!','Not a valid file!','Options',{'OK'},'Icon','error');
                            end
                        catch ME
                            ME
                            close(d);
                            uiconfirm(app.carnotUIBKUIFigure,'There was an error during the import!','Not working!','Options',{'OK'},'Icon','error');
                        end
                    end
                end
            end
        end

        % Button pushed function: HVACSystemExportButton
        function HVACSystemExportButtonPushed(app, event)
            global building
            
            selection = uiconfirm(app.carnotUIBKUIFigure,'Do you want to export this system?','Export?','Options',{'Yes','No'},'Icon','question');
            
            if strcmp(selection,'Yes')
                selectedNodes = app.HVACTree.SelectedNodes;
                if ~isempty(selectedNodes)
                    selectedNodes = app.HVACTree.SelectedNodes.NodeData;
                    try
                        d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                        SYSTEM_HVACsave = building.hvac(selectedNodes(1)).system(selectedNodes(2));
                        save(['SYSTEM_HVAC_' num2str(building.hvac(selectedNodes(1)).system(selectedNodes(2)).number) ''],'SYSTEM_HVACsave')
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'Export done!','Done!','Options',{'OK'},'Icon','success');
                    catch ME
                        ME
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'There was an error during the export!','Not working!','Options',{'OK'},'Icon','error');
                    end
                end
            end
        end

        % Value changed function: HVACSystemNumberEditField
        function HVACSystemNumberEditFieldValueChanged(app, event)
            value = app.HVACSystemNumberEditField.Value;
            global building
            selectedNodes = app.HVACTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.HVACTree.SelectedNodes.NodeData;
                try
                    building.hvac(selectedNodes(1)).system(selectedNodes(2)).number = value;
                catch ME
                    
                end
            end
        end

        % Value changed function: HVACSystemNameEditField
        function HVACSystemNameEditFieldValueChanged(app, event)
            value = app.HVACSystemNameEditField.Value;
            global building
            selectedNodes = app.HVACTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.HVACTree.SelectedNodes.NodeData;
                try
                    building.hvac(selectedNodes(1)).system(selectedNodes(2)).name = value;
                catch ME
                    
                end
            end
        end

        % Button pushed function: HVACSystemDeleteButton
        function HVACSystemDeleteButtonPushed(app, event)
            global building
            
            selection = uiconfirm(app.carnotUIBKUIFigure,'Do you want to delete this system?','Delete?','Options',{'Yes','No'},'Icon','question');
            
            if strcmp(selection,'Yes')
                selectedNodes = app.HVACTree.SelectedNodes;
                if ~isempty(selectedNodes)
                    selectedNodes = app.HVACTree.SelectedNodes.NodeData;
                    try
                        d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                        building.hvac(selectedNodes(1)).system(selectedNodes(2)) = [];
                        TabGroupSelectionChanged(app,app.TabGroup.SelectionChangedFcn);
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'Deleted!','Done!','Options',{'OK'},'Icon','success');
                    catch ME
                        ME
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'There was an error during the deletion!','Not working!','Options',{'OK'},'Icon','error');
                    end
                end
            end
        end

        % Button pushed function: ThermalzoneZoneClearButton
        function ThermalzoneZoneClearButtonPushed(app, event)
            global building
            
            selection = uiconfirm(app.carnotUIBKUIFigure,'Do you want to deactive this zone?','Export?','Options',{'Yes','No'},'Icon','question');
            
            if strcmp(selection,'Yes')
                selectedNodes = app.ThermalzoneTree.SelectedNodes;
                if ~isempty(selectedNodes)
                    selectedNodes = app.ThermalzoneTree.SelectedNodes.NodeData;
                    try
                        d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                        app.ThermalzoneZoneNameEditField.Value = ['EMPTY_' num2str(selectedNodes(3))];
                        ThermalzoneZoneNameEditFieldValueChanged(app,app.ThermalzoneZoneNameEditField.ValueChangedFcn)
                        app.ThermalzoneZoneCalculationmodelDropDown.Value = 0;
                        ThermalzoneZoneCalculationmodelDropDownValueChanged(app,app.ThermalzoneZoneCalculationmodelDropDown.ValueChangedFcn)
                        TabGroupSelectionChanged(app,app.TabGroup.SelectionChangedFcn);
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'Deactived!','Done!','Options',{'OK'},'Icon','success');
                    catch ME
                        ME
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'There was an error during the deactivation!','Not working!','Options',{'OK'},'Icon','error');
                    end
                end
            end
        end

        % Value changed function: ThermalzoneZoneNameEditField
        function ThermalzoneZoneNameEditFieldValueChanged(app, event)
            value = app.ThermalzoneZoneNameEditField.Value;
            global building
            selectedNodes = app.ThermalzoneTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.ThermalzoneTree.SelectedNodes.NodeData;
                try
                    if selectedNodes(2) == 1
                        building.thermalzone(selectedNodes(1)).zone(selectedNodes(3)).name = value;
                    end
                catch ME
                    
                end
            end
        end

        % Value changed function: 
        % ThermalzoneZoneCalculationmodelDropDown
        function ThermalzoneZoneCalculationmodelDropDownValueChanged(app, event)
            value = app.ThermalzoneZoneCalculationmodelDropDown.Value;
            global building
            selectedNodes = app.ThermalzoneTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.ThermalzoneTree.SelectedNodes.NodeData;
                try
                    if selectedNodes(2) == 1
                        building.thermalzone(selectedNodes(1)).zone(selectedNodes(3)).model = value;
                    end
                catch ME
                    
                end
            end
        end

        % Value changed function: 
        % ThermalzoneZoneInitialtemperatureEditField
        function ThermalzoneZoneInitialtemperatureEditFieldValueChanged(app, event)
            value = app.ThermalzoneZoneInitialtemperatureEditField.Value;
            global building
            selectedNodes = app.ThermalzoneTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.ThermalzoneTree.SelectedNodes.NodeData;
                try
                    if selectedNodes(2) == 1
                        building.thermalzone(selectedNodes(1)).zone(selectedNodes(3)).t_ini = value;
                    end
                catch ME
                    
                end
            end
        end

        % Value changed function: 
        % ThermalzoneZoneInitialrelhumidityEditField
        function ThermalzoneZoneInitialrelhumidityEditFieldValueChanged(app, event)
            value = app.ThermalzoneZoneInitialrelhumidityEditField.Value;
            global building
            selectedNodes = app.ThermalzoneTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.ThermalzoneTree.SelectedNodes.NodeData;
                try
                    if selectedNodes(2) == 1
                        building.thermalzone(selectedNodes(1)).zone(selectedNodes(3)).phi_ini = value;
                    end
                catch ME
                    
                end
            end
        end

        % Value changed function: ThermalzoneZoneCpEditField
        function ThermalzoneZoneCpEditFieldValueChanged(app, event)
            value = app.ThermalzoneZoneCpEditField.Value;
            global building
            selectedNodes = app.ThermalzoneTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.ThermalzoneTree.SelectedNodes.NodeData;
                try
                    if selectedNodes(2) == 1
                        building.thermalzone(selectedNodes(1)).zone(selectedNodes(3)).cp_spec = value;
                    end
                catch ME
                    
                end
            end
        end

        % Value changed function: ThermalzoneZoneTimeConstantEditField
        function ThermalzoneZoneTimeConstantEditFieldValueChanged(app, event)
            value = app.ThermalzoneZoneTimeConstantEditField.Value;
            global building
            selectedNodes = app.ThermalzoneTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.ThermalzoneTree.SelectedNodes.NodeData;
                try
                    if selectedNodes(2) == 1
                        building.thermalzone(selectedNodes(1)).zone(selectedNodes(3)).timeconstant = value;
                    end
                catch ME
                    
                end
            end
        end

        % Value changed function: 
        % ThermalzoneZoneOrientationImportantDropDown
        function ThermalzoneZoneOrientationImportantDropDownValueChanged(app, event)
            value = app.ThermalzoneZoneOrientationImportantDropDown.Value;
            global building
            selectedNodes = app.ThermalzoneTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.ThermalzoneTree.SelectedNodes.NodeData;
                try
                    if selectedNodes(2) == 1
                        building.thermalzone(selectedNodes(1)).zone(selectedNodes(3)).orientation_important = value;
                    end
                catch ME
                    
                end
            end
        end

        % Cell edit callback: ThermalzoneZoneProfileUITable
        function ThermalzoneZoneProfileUITableCellEdit(app, event)
%             indices = event.Indices
%             newData = event.NewData
            global building
            selectedNodes = app.ThermalzoneTree.SelectedNodes;
            
            if ~isempty(selectedNodes)
                selectedNodes = app.ThermalzoneTree.SelectedNodes.NodeData;
                
                try
                    d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                    tdata = app.ThermalzoneZoneProfileUITable.Data;
                    if ~issortedrows(tdata,'Date')
                        tdata = sortrows(tdata);
                        app.ThermalzoneZoneProfileUITable.Data = tdata;
                    end
                    
                    dates = tdata.Date;
                    dates(end+1) = datetime([2015,01,01],'Format','dd/MM/uuuu HH:mm');
                    
                    profile_timevalues(1) = 0;
                    for jj=2:length(dates)
                        profile_timeduration(jj-1) = seconds(dates(jj)-dates(jj-1));
                        profile_timevalues(jj) = profile_timevalues(jj-1) + profile_timeduration(jj-1);
                    end
                    
                    building.thermalzone(selectedNodes(1)).zone(selectedNodes(3)).profile_timevalues = profile_timevalues;
                    building.thermalzone(selectedNodes(1)).zone(selectedNodes(3)).profile_timeduration = profile_timeduration;
                    building.thermalzone(selectedNodes(1)).zone(selectedNodes(3)).profile_Tc = tdata.T_convective';
                    building.thermalzone(selectedNodes(1)).zone(selectedNodes(3)).profile_Tr = tdata.T_radiative';
                    building.thermalzone(selectedNodes(1)).zone(selectedNodes(3)).profile_rH = tdata.rel_humidity';
                    building.thermalzone(selectedNodes(1)).zone(selectedNodes(3)).profile_CO2 = tdata.CO2';
                    building.thermalzone(selectedNodes(1)).zone(selectedNodes(3)).profile_p = tdata.pressure';
                    
                    close(d);
                catch ME
                    close(d);
                end
            end
            
        end

        % Value changed function: ThermalzoneZoneProfileDropDown
        function ThermalzoneZoneProfileDropDownValueChanged(app, event)
            value = app.ThermalzoneZoneProfileDropDown.Value;
            global building
            
            selection = uiconfirm(app.carnotUIBKUIFigure,'Do you want to change the time? Existing profiles are going to be deleted!','Change time?','Options',{'Yes','No'},'Icon','question');
            
            if strcmp(selection,'Yes')
                try
                    d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                    
                    if strcmp(value,'winter/sommer')
                        dates = datetime([2014,01,01;2014,04,01;2014,10,01],'Format','dd/MM/uuuu HH:mm')';
                        
                        T_convective = zeros(size(dates)) + 20.0;
                        T_radiative = zeros(size(dates)) + 20.0;
                        rH = zeros(size(dates)) + 50.0;
                        CO2 = zeros(size(dates)) + 400;
                        pressure = zeros(size(dates)) + 1e5;
                        
                        tdata = table(dates', T_convective', T_radiative', rH', CO2', pressure', 'VariableNames', {'Date','T_convective','T_radiative','rel_humidity','CO2','pressure'});
                        app.ThermalzoneZoneProfileUITable.Data = tdata;
                    elseif strcmp(value,'monthly')
                        for jj=1:12
                            dates(jj) = datetime([2014,jj,01],'Format','dd/MM/uuuu HH:mm')';
                        end
                        
                        T_convective = zeros(size(dates)) + 20.0;
                        T_radiative = zeros(size(dates)) + 20.0;
                        rH = zeros(size(dates)) + 50.0;
                        CO2 = zeros(size(dates)) + 400;
                        pressure = zeros(size(dates)) + 1e5;
                        
                        tdata = table(dates', T_convective', T_radiative', rH', CO2', pressure', 'VariableNames', {'Date','T_convective','T_radiative','rel_humidity','CO2','pressure'});
                        app.ThermalzoneZoneProfileUITable.Data = tdata;
                    elseif strcmp(value,'daily')
                        dates(1) = datetime([2014,1,01],'Format','dd/MM/uuuu HH:mm')';
                        for jj=2:365
                            dates(jj) = dates(jj-1)+1;
                        end
                        
                        T_convective = zeros(size(dates)) + 20.0;
                        T_radiative = zeros(size(dates)) + 20.0;
                        rH = zeros(size(dates)) + 50.0;
                        CO2 = zeros(size(dates)) + 400;
                        pressure = zeros(size(dates)) + 1e5;
                        
                        tdata = table(dates', T_convective', T_radiative', rH', CO2', pressure', 'VariableNames', {'Date','T_convective','T_radiative','rel_humidity','CO2','pressure'});
                        app.ThermalzoneZoneProfileUITable.Data = tdata;
                    elseif strcmp(value,'hourly')
                        dates(1) = datetime([2014,1,01],'Format','dd/MM/uuuu HH:mm')';
                        for jj=2:8760
                            dates(jj) = dates(jj-1)+1/24;
                        end
                        
                        T_convective = zeros(size(dates)) + 20.0;
                        T_radiative = zeros(size(dates)) + 20.0;
                        rH = zeros(size(dates)) + 50.0;
                        CO2 = zeros(size(dates)) + 400;
                        pressure = zeros(size(dates)) + 1e5;
                        
                        tdata = table(dates', T_convective', T_radiative', rH', CO2', pressure', 'VariableNames', {'Date','T_convective','T_radiative','rel_humidity','CO2','pressure'});
                        app.ThermalzoneZoneProfileUITable.Data = tdata;
                    elseif strcmp(value,'minutly')
                        uiconfirm(app.carnotUIBKUIFigure,'At the moment not supported!','Not supported!','Options',{'OK'},'Icon','info');
%                         dates(1) = datetime([2014,1,01],'Format','dd/MM/uuuu HH:mm')';
%                         for jj=2:(8760*60)
%                             dates(jj) = dates(jj-1)+1/24/60;
%                         end
%                         
%                         T_convective = zeros(size(dates)) + 20.0;
%                         T_radiative = zeros(size(dates)) + 20.0;
%                         rH = zeros(size(dates)) + 50.0;
%                         CO2 = zeros(size(dates)) + 400;
%                         pressure = zeros(size(dates)) + 1e5;
%                         
%                         tdata = table(dates', T_convective', T_radiative', rH', CO2', pressure', 'VariableNames', {'Date','T_convective','T_radiative','rel_humidity','CO2','pressure'});
%                         app.ThermalzoneZoneProfileUITable.Data = tdata;
                    end
                    
                    ThermalzoneZoneProfileUITableCellEdit(app, app.ThermalzoneZoneProfileUITable.CellEditCallback)
                    close(d);
                catch ME
                    close(d);
                end
            end
        end

        % Button pushed function: ThermalzoneZoneRoomsApplyButton
        function ThermalzoneZoneRoomsApplyButtonPushed(app, event)
            value = app.ThermalzoneZoneRoomsTextArea.Value;
            global building
            selectedNodes = app.ThermalzoneTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.ThermalzoneTree.SelectedNodes.NodeData;
                try
                    if selectedNodes(2) == 1
                        d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                        warning = 0;
                        for jj = 1:length(value)
                            if isempty(value{jj})
                                warning = 1;
                            end
                        end
                        if ~warning
                            building.thermalzone(selectedNodes(1)).zone(selectedNodes(3)).rooms = value';
                            close(d);
                        else
                            close(d);
                            uiconfirm(app.carnotUIBKUIFigure,'Please check, that there is no empty line in rooms!','Failed!','Options',{'OK'},'Icon','error');
                        end
                    end
                catch ME
                    ME
                    close(d);
                end
            end
        end

        % Value changed function: ConstructionStructureNameEditField
        function ConstructionStructureNameEditFieldValueChanged(app, event)
            value = app.ConstructionStructureNameEditField.Value;
            global building
            selectedNodes = app.ConstructionTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.ConstructionTree.SelectedNodes.NodeData;
                try
                    if ~isempty(building.construction(selectedNodes(1)).get_structure(value))
                        app.ConstructionStructureNameEditField.Value = building.construction(selectedNodes(1)).structure(selectedNodes(2)).name;
                        answer = uiconfirm(app.carnotUIBKUIFigure,['There is already a stucture with the same name. Please choose another name!'],'Creation failed!','Options',{'OK'},'Icon','error');
                    else
                        building.construction(selectedNodes(1)).structure(selectedNodes(2)).name = value;
                    end
                catch ME
                    ME
                end
            end
        end

        % Value changed function: GainsGainNameEditField
        function GainsGainNameEditFieldValueChanged(app, event)
            d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
            value = app.GainsGainNameEditField.Value;
            global building
            selectedNodes = app.GainsTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GainsTree.SelectedNodes.NodeData;
                try
                    ind = [];
                    for jj = 1:length(building.gains(selectedNodes(1)).gain)
                        if strcmp(building.gains(selectedNodes(1)).gain(jj).name,value)
                            ind = strcmp(building.gains(selectedNodes(1)).gain(jj).name,value);
                            break
                        end
                    end
                    if ind
                        app.GainsGainNameEditField.Value = building.gains(selectedNodes(1)).gain(selectedNodes(3)).name;
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'Changing name failed, already existing! Try another name!','Failed!','Options',{'OK'},'Icon','error');
                    else
                        building.gains(selectedNodes(1)).gain(selectedNodes(3)).name = value;
                        close(d);
                    end
                catch ME
                    ME
                    app.GainsGainNameEditField.Value = building.gains(selectedNodes(1)).gain(selectedNodes(3)).name;
                    close(d);
                    uiconfirm(app.carnotUIBKUIFigure,'Changing name failed!','Failed!','Options',{'OK'},'Icon','error');
                end
            end
        end

        % Value changed function: GainsGainTypeDropDown
        function GainsGainTypeDropDownValueChanged(app, event)
            value = app.GainsGainTypeDropDown.Value;
            global building
            selectedNodes = app.GainsTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GainsTree.SelectedNodes.NodeData;
                try
                    building.gains(selectedNodes(1)).gain(selectedNodes(3)).model = value;
                    building.gains(selectedNodes(1)).gain(selectedNodes(3)).type = 0;
                    GainsTreeSelectionChanged(app, app.GainsTree.SelectionChangedFcn)
                catch ME
                    
                end
            end
        end

        % Value changed function: GainsGainModelDropDown
        function GainsGainModelDropDownValueChanged(app, event)
            value = app.GainsGainModelDropDown.Value;
            global building
            selectedNodes = app.GainsTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GainsTree.SelectedNodes.NodeData;
                try
                    building.gains(selectedNodes(1)).gain(selectedNodes(3)).type = value;
                    GainsTreeSelectionChanged(app, app.GainsTree.SelectionChangedFcn)
                catch ME
                    
                end
            end
        end

        % Cell edit callback: GainsGainUITable
        function GainsGainUITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            global building
            selectedNodes = app.GainsTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GainsTree.SelectedNodes.NodeData;
                try
                    if indices(1) == 1
                        building.gains(selectedNodes(1)).gain(selectedNodes(3)).values1(indices(2)) = newData;
                    elseif indices(1) == 2
                        building.gains(selectedNodes(1)).gain(selectedNodes(3)).values2(indices(2)) = newData;
                    end
                catch ME
                    
                end
            end
        end

        % Button pushed function: GainsGainUseinzonesApplyButton
        function GainsGainUseinzonesApplyButtonPushed(app, event)
            global building
            selectedNodes = app.GainsTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GainsTree.SelectedNodes.NodeData;
                try
                    d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                    jk = 1;
                    for jj = 1:length(building.gains(selectedNodes(1)).gain_to_zone)
                        if strcmp(building.gains(selectedNodes(1)).gain_to_zone(jk).name_gains,building.gains(selectedNodes(1)).gain(selectedNodes(3)).name)
                            building.gains(selectedNodes(1)).gain_to_zone(jk) = [];
                        else
                            jk = jk + 1;
                        end
                    end
                    
                    values = app.GainsGainUseinzonesListBox.Value;
                    for jj = 1:length(values)
                        building.gains(selectedNodes(1)).gain_to_zone = [building.gains(selectedNodes(1)).gain_to_zone, GAIN_TO_ZONE(building.gains(selectedNodes(1)).gain(selectedNodes(3)).name, values(jj))];
                    end
                    
                    close(d);
                catch ME
                    ME
                    close(d);
                    uiconfirm(app.carnotUIBKUIFigure,'Adding to zones not possible!','Failed!','Options',{'OK'},'Icon','error');
                end
            end
        end

        % Button pushed function: GainsGainDeleteGainButton
        function GainsGainDeleteGainButtonPushed(app, event)
            global building
            
            selection = uiconfirm(app.carnotUIBKUIFigure,'Do you want to delete this gain?','Delete?','Options',{'Yes','No'},'Icon','question');
            
            if strcmp(selection,'Yes')
                selectedNodes = app.GainsTree.SelectedNodes;
                if ~isempty(selectedNodes)
                    selectedNodes = app.GainsTree.SelectedNodes.NodeData;
                    try
                        d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                        jk = 1;
                        for jj = 1:length(building.gains(selectedNodes(1)).gain_to_zone)
                            if strcmp(building.gains(selectedNodes(1)).gain_to_zone(jk).name_gains,building.gains(selectedNodes(1)).gain(selectedNodes(3)).name)
                                building.gains(selectedNodes(1)).gain_to_zone(jk) = [];
                            else
                                jk = jk + 1;
                            end
                        end
                        building.gains(selectedNodes(1)).gain(selectedNodes(3)) = [];
                        TabGroupSelectionChanged(app,app.TabGroup.SelectionChangedFcn);
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'Deleted!','Done!','Options',{'OK'},'Icon','success');
                    catch ME
                        ME
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'There was an error during the deletion!','Not working!','Options',{'OK'},'Icon','error');
                    end
                end
            end
        end

        % Button pushed function: GainsGainAddGainButton
        function GainsGainAddGainButtonPushed(app, event)
            global building
            
            selection = uiconfirm(app.carnotUIBKUIFigure,'Do you want to add a gain?','Delete?','Options',{'Yes','No'},'Icon','question');
            
            if strcmp(selection,'Yes')
                selectedNodes = app.GainsTree.SelectedNodes;
                if ~isempty(selectedNodes)
                    selectedNodes = app.GainsTree.SelectedNodes.NodeData;
                    try
                        d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                        if ~isempty(building.gains(selectedNodes(1)).gain)
                            name = ['new gain ' num2str(length(building.gains(selectedNodes(1)).gain)+1)];
                        else
                            name = 'new gain 1';
                        end
                        model = 0;
                        type = 0;
                        timevalues = [0:24]*3600;
                        values1 = zeros(1,24);
                        values2 = zeros(1,24);
                        control = 2;
                        if ~isempty(building.gains(selectedNodes(1)).gain)
                            building.gains(selectedNodes(1)).gain(end+1) = GAIN(name, model, type, timevalues, values1, values2, control);
                        else
                            building.gains(selectedNodes(1)).gain = GAIN(name, model, type, timevalues, values1, values2, control);
                        end
                        TabGroupSelectionChanged(app,app.TabGroup.SelectionChangedFcn);
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'Added!','Done!','Options',{'OK'},'Icon','success');
                    catch ME
                        ME
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'There was an error during adding the gain!','Not working!','Options',{'OK'},'Icon','error');
                    end
                end
            end
        end

        % Button pushed function: GainsGainRefreshplotsButton
        function GainsGainRefreshplotsButtonPushed(app, event)
            global building
            
            app.GainsGainUIAxes.Visible = 'off';
            app.GainsGain2UIAxes.Visible = 'off';
            delete(app.GainsGainUIAxes.Children)
            delete(app.GainsGain2UIAxes.Children)
            
            selectedNodes = app.GainsTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GainsTree.SelectedNodes.NodeData;
            end
            
            try
                d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                
                if ~length(building.gains(selectedNodes(1)).gain(selectedNodes(3)).timevalues) == 25
                    % no sense to plot
                else
                    app.GainsGainUIAxes.Visible = 'on';
                    app.GainsGainUIAxes.NextPlot = 'replaceall';
                    app.GainsGainUIAxes.XLabel.String = '';
                    app.GainsGainUIAxes.YLabel.String = '';
                    
                    xxx = [building.gains(selectedNodes(1)).gain(selectedNodes(3)).timevalues/3600; building.gains(selectedNodes(1)).gain(selectedNodes(3)).timevalues/3600];
                    yyy = [building.gains(selectedNodes(1)).gain(selectedNodes(3)).values1 building.gains(selectedNodes(1)).gain(selectedNodes(3)).values1(1); building.gains(selectedNodes(1)).gain(selectedNodes(3)).values1 building.gains(selectedNodes(1)).gain(selectedNodes(3)).values1(1)];
                    h = area(app.GainsGainUIAxes,xxx([2:end end]),yyy(1:end));
                    h.FaceColor = [0 0.75 0.75];
                    name_mod = strrep(building.gains(selectedNodes(1)).gain(selectedNodes(3)).name,'_',' ');
                    title(app.GainsGainUIAxes,name_mod)
                    xlim(app.GainsGainUIAxes,[0 24]);
                    app.GainsGainUIAxes.XTick=[1:24];
                    if building.gains(selectedNodes(1)).gain(selectedNodes(3)).model == 0 && (building.gains(selectedNodes(1)).gain(selectedNodes(3)).type == 0 || building.gains(selectedNodes(1)).gain(selectedNodes(3)).type == 1)
                        app.GainsGainUIAxes.YLabel.String = 'People [people]';
                    elseif building.gains(selectedNodes(1)).gain(selectedNodes(3)).model == 0 && building.gains(selectedNodes(1)).gain(selectedNodes(3)).type == 2
                        app.GainsGainUIAxes.YLabel.String = 'People [W/m²]';
                    elseif building.gains(selectedNodes(1)).gain(selectedNodes(3)).model == 1 && building.gains(selectedNodes(1)).gain(selectedNodes(3)).type == 0
                        app.GainsGainUIAxes.YLabel.String = 'Light [W/light]';
                    elseif building.gains(selectedNodes(1)).gain(selectedNodes(3)).model == 1 && building.gains(selectedNodes(1)).gain(selectedNodes(3)).type == 1
                        app.GainsGainUIAxes.YLabel.String = 'Light [W/m²]';
                    elseif building.gains(selectedNodes(1)).gain(selectedNodes(3)).model == 2 && building.gains(selectedNodes(1)).gain(selectedNodes(3)).type == 0
                        app.GainsGainUIAxes.YLabel.String = 'Electricity [W/device]';
                    elseif building.gains(selectedNodes(1)).gain(selectedNodes(3)).model == 2 && building.gains(selectedNodes(1)).gain(selectedNodes(3)).type == 1
                        app.GainsGainUIAxes.YLabel.String = 'Electricity [W/m²]';
                    elseif building.gains(selectedNodes(1)).gain(selectedNodes(3)).model == 3 && building.gains(selectedNodes(1)).gain(selectedNodes(3)).type == 0
                        app.GainsGainUIAxes.YLabel.String = 'Moisture [kg/s]';
                    end
                    app.GainsGainUIAxes.XLabel.String = 'Time [h]';
                    
                    if building.gains(selectedNodes(1)).gain(selectedNodes(3)).model == 0 && (building.gains(selectedNodes(1)).gain(selectedNodes(3)).type == 0 || building.gains(selectedNodes(1)).gain(selectedNodes(3)).type == 1)
                        app.GainsGain2UIAxes.Visible = 'on';
                        app.GainsGain2UIAxes.NextPlot = 'replaceall';
                        app.GainsGain2UIAxes.XLabel.String = '';
                        app.GainsGain2UIAxes.YLabel.String = '';
                        
                        xxx = [building.gains(selectedNodes(1)).gain(selectedNodes(3)).timevalues/3600; building.gains(selectedNodes(1)).gain(selectedNodes(3)).timevalues/3600];
                        yyy = [building.gains(selectedNodes(1)).gain(selectedNodes(3)).values2 building.gains(selectedNodes(1)).gain(selectedNodes(3)).values2(1); building.gains(selectedNodes(1)).gain(selectedNodes(3)).values2 building.gains(selectedNodes(1)).gain(selectedNodes(3)).values2(1)];
                        h = area(app.GainsGain2UIAxes,xxx([2:end end]),yyy(1:end));
                        h.FaceColor = [0 0.75 0.75];
                        title(app.GainsGain2UIAxes,'')
                        xlim(app.GainsGain2UIAxes,[0 24]);
                        app.GainsGain2UIAxes.XTick=[1:24];
                        app.GainsGain2UIAxes.YLabel.String = 'Activity [activity]';
                        app.GainsGain2UIAxes.XLabel.String = 'Time [h]';
                    end
                end
                
                close(d);
            catch ME
                ME
                close(d);
                uiconfirm(app.carnotUIBKUIFigure,'There was an error loading this gain!','Failed!','Options',{'OK'},'Icon','error');
                app.GainsGainUIAxes.Visible = 'off';
                app.GainsGain2UIAxes.Visible = 'off';
            end
        end

        % Button pushed function: GeometryPlotButton
        function GeometryPlotButtonPushed(app, event)
            global building
            
            selectedNodes = app.GeometryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                try
                    d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                    building.geometry(selectedNodes(1)).print_geometry();
                    close(d);
                catch ME
                    close(d);
                    ME
                end
            end
        end

        % Button pushed function: GeometryRoomPlotButton
        function GeometryRoomPlotButtonPushed(app, event)
            global building
            
            selectedNodes = app.GeometryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GeometryTree.SelectedNodes.NodeData;
                try
                    d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                    building.geometry(selectedNodes(1)).print_room(app.GeometryRoomNameEditField.Value);
                    close(d);
                catch ME
                    close(d);
                    ME
                end
            end

        end

        % Button pushed function: ConstructionAddstructureRCButton
        function ConstructionAddstructureRCButtonPushed(app, event)
            selectedNodes = app.ConstructionTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.ConstructionTree.SelectedNodes.NodeData;
                parameters.variant = selectedNodes(1);
                app.DialogAddConstructionRCApp = DialogAddConstructionRC(app,parameters);
            end
        end

        % Button pushed function: DeletestructureButton
        function DeletestructureButtonPushed(app, event)
            global building
            
            selection = uiconfirm(app.carnotUIBKUIFigure,'Do you want to delete this structure?','Delete?','Options',{'Yes','No'},'Icon','question');
            
            if strcmp(selection,'Yes')
                selectedNodes = app.ConstructionTree.SelectedNodes;
                if ~isempty(selectedNodes)
                    selectedNodes = app.ConstructionTree.SelectedNodes.NodeData;
                    try
                        d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                        building.construction(selectedNodes(1)).structure(selectedNodes(2)) = [];
                        TabGroupSelectionChanged(app,app.TabGroup.SelectionChangedFcn);
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'Deleted!','Done!','Options',{'OK'},'Icon','success');
                    catch ME
                        ME
                        close(d);
                        uiconfirm(app.carnotUIBKUIFigure,'There was an error during the deletion!','Not working!','Options',{'OK'},'Icon','error');
                    end
                end
            end
        end

        % Cell edit callback: HVACUITable
        function HVACUITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Button pushed function: ThermalzonePlotButton
        function ThermalzonePlotButtonPushed(app, event)
            global building
            selectedNodes = app.ThermalzoneTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.ThermalzoneTree.SelectedNodes.NodeData;
                building.variant_thermalzone = selectedNodes(1);
                try
                    d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                    evalin('base','plot(building.get_building())');
                    close(d);
                catch ME
                    ME
                    close(d);
                end
            end
        end

        % Value changed function: GainsGainControlDropDown
        function GainsGainControlDropDownValueChanged(app, event)
            value = app.GainsGainControlDropDown.Value;
            global building
            selectedNodes = app.GainsTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.GainsTree.SelectedNodes.NodeData;
                try
                    building.gains(selectedNodes(1)).gain(selectedNodes(3)).control = value;
                    GainsTreeSelectionChanged(app, app.GainsTree.SelectionChangedFcn)
                catch ME
                    
                end
            end
        end

        % Button pushed function: ConstructionAddWindowButton
        function ConstructionAddWindowButtonPushed(app, event)
            selectedNodes = app.ConstructionTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.ConstructionTree.SelectedNodes.NodeData;
                parameters.variant = selectedNodes(1);
                app.DialogAddWindowApp = DialogAddWindowApp(app,parameters);
            end
        end

        % Value changed function: 
        % ConstructionStructureemissioninternalEditField
        function ConstructionStructureemissioninternalEditFieldValueChanged(app, event)
            value = app.ConstructionStructureemissioninternalEditField.Value;
            global building
            selectedNodes = app.ConstructionTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.ConstructionTree.SelectedNodes.NodeData;
                try
                    building.construction(selectedNodes(1)).structure(selectedNodes(2)).emission_1 = value;
                catch ME
                    ME
                end
            end
        end

        % Value changed function: 
        % ConstructionStructureemissionexternalEditField
        function ConstructionStructureemissionexternalEditFieldValueChanged(app, event)
            value = app.ConstructionStructureemissionexternalEditField.Value;
            global building
            selectedNodes = app.ConstructionTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.ConstructionTree.SelectedNodes.NodeData;
                try
                    building.construction(selectedNodes(1)).structure(selectedNodes(2)).emission_2 = value;
                catch ME
                    ME
                end
            end
        end

        % Value changed function: 
        % ConstructionStructureabsorptionexternalEditField
        function ConstructionStructureabsorptionexternalEditFieldValueChanged(app, event)
            value = app.ConstructionStructureabsorptionexternalEditField.Value;
            global building
            selectedNodes = app.ConstructionTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.ConstructionTree.SelectedNodes.NodeData;
                try
                    building.construction(selectedNodes(1)).structure(selectedNodes(2)).absorption_2 = value;
                catch ME
                    ME
                end
            end
        end

        % Value changed function: WeatherNameEditField
        function WeatherNameEditFieldValueChanged(app, event)
            value = app.WeatherNameEditField.Value;
            global building
            selectedNodes = app.BoundaryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.BoundaryTree.SelectedNodes.NodeData;
                try
                    if ~isempty(value) && selectedNodes(2) == 1
                        building.boundary(selectedNodes(1)).weather.name = value;
                    end
                    BoundaryTreeSelectionChanged(app, app.BoundaryTree.SelectionChangedFcn)
                catch ME
                    ME
                end
            end
        end

        % Button pushed function: WeatherAddChangeweatherButton
        function WeatherAddChangeweatherButtonPushed(app, event)
            global building
            selectedNodes = app.BoundaryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.BoundaryTree.SelectedNodes.NodeData;
                try
                    [file, path] = uigetfile('*.dat','weather file (*.dat)');
                    if isequal(file,0)
    %                    disp('User selected Cancel');
                    else
    %                    disp(['User selected ', fullfile(path,file)]);
%                         fullfile(path,file)
                        building.boundary(selectedNodes(1)).weather = [];
                        building.boundary(selectedNodes(1)) = building.boundary(selectedNodes(1)).add_weather_from_file(building, fullfile(path,file), app.WeatherNameEditField.Value, 0, 0, 0);
                    end
                    BoundaryTreeSelectionChanged(app, app.BoundaryTree.SelectionChangedFcn)
                catch ME
                    ME
                    uiconfirm(app.carnotUIBKUIFigure,'There was an unknown error while adding/changing the weather!','Not working!','Options',{'OK'},'Icon','error');
                end
            end
        end

        % Value changed function: GroundNameEditField
        function GroundNameEditFieldValueChanged(app, event)
            value = app.GroundNameEditField.Value;
            global building
            selectedNodes = app.BoundaryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.BoundaryTree.SelectedNodes.NodeData;
                try
                    if ~isempty(value) && selectedNodes(2) == 2
                        building.boundary(selectedNodes(1)).ground(selectedNodes(3)).name = value;
                    end
                    BoundaryTreeSelectionChanged(app, app.BoundaryTree.SelectionChangedFcn)
                catch ME
                    ME
                end
            end
        end

        % Button pushed function: GroundApplyButton
        function GroundApplyButtonPushed(app, event)
            tt = app.GroundUITable.Data;
            global building
            selectedNodes = app.BoundaryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.BoundaryTree.SelectedNodes.NodeData;
                try
                    building.boundary(selectedNodes(1)) = building.boundary(selectedNodes(1)).add_ground_set_temperature(building, selectedNodes(3), app.GroundNameEditField.Value, 2, [0 31 59 90 120 151 181 212 243 273 304 334]*24*3600, tt.Variables);
                    BoundaryTreeSelectionChanged(app, app.BoundaryTree.SelectionChangedFcn)
                catch ME
                    ME
                    uiconfirm(app.carnotUIBKUIFigure,'There was an unknown error while changing the ground temperature!','Not working!','Options',{'OK'},'Icon','error');
                end
            end
        end

        % Value changed function: NeighbourNameEditField
        function NeighbourNameEditFieldValueChanged(app, event)
            value = app.NeighbourNameEditField.Value;
            global building
            selectedNodes = app.BoundaryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.BoundaryTree.SelectedNodes.NodeData;
                try
                    if ~isempty(value) && selectedNodes(2) == 3
                        building.boundary(selectedNodes(1)).neighbour(selectedNodes(3)).name = value;
                    end
                    BoundaryTreeSelectionChanged(app, app.BoundaryTree.SelectionChangedFcn)
                catch ME
                    ME
                end
            end
        end

        % Button pushed function: NeighbourApplyButton
        function NeighbourApplyButtonPushed(app, event)
            tt = app.NeighbourUITable.Data;
            global building
            selectedNodes = app.BoundaryTree.SelectedNodes;
            if ~isempty(selectedNodes)
                selectedNodes = app.BoundaryTree.SelectedNodes.NodeData;
                try
                    building.boundary(selectedNodes(1)) = building.boundary(selectedNodes(1)).add_neighbour_set_temperature(building, selectedNodes(3), app.NeighbourNameEditField.Value, 2, [0 31 59 90 120 151 181 212 243 273 304 334]*24*3600, tt.Variables);
                    BoundaryTreeSelectionChanged(app, app.BoundaryTree.SelectionChangedFcn)
                catch ME
                    ME
                    uiconfirm(app.carnotUIBKUIFigure,'There was an unknown error while changing the neighbour temperature!','Not working!','Options',{'OK'},'Icon','error');
                end
            end
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
             global building NUMBEROFZONES
            
            if app.StartGeomCheckBox.Value == true
                variant_nr_geom = app.StartGeometryEditField.Value;              
                if variant_nr_geom <= length(building.geometry)
                    if ~isempty(building.geometry(variant_nr_geom).room)
                        answer = uiconfirm(app.carnotUIBKUIFigure,['There is already a geometry variant with the number ' num2str(variant_nr_geom) ' existing. Would you like to override the variant?'],'Overwrite variant?');
                        if ~strcmp(answer, 'OK')
                            variant_nr_geom = -1;
                        end
                    end
                end
            end
            
            if app.StartCheckBoxConstr.Value == true
                variant_nr_constr = app.StartConstructionEditField.Value;              
                if variant_nr_constr <= length(building.construction)
                    if ~isempty(building.construction(variant_nr_constr).structure)
                        answer = uiconfirm(app.carnotUIBKUIFigure,['There is already a construction variant with the number ' num2str(variant_nr_constr) ' existing. Would you like to override the variant?'],'Overwrite variant?');
                        if ~strcmp(answer, 'OK')
                            variant_nr_constr = -1;
                        end
                    end
                end
            end
            
            if app.StartCheckBoxGains.Value == true
                variant_nr_gains = app.StartGainsEditField.Value;              
                if variant_nr_gains <= length(building.gains)
                    if ~isempty(building.gains(variant_nr_gains).gain)
                        answer = uiconfirm(app.carnotUIBKUIFigure,['There is already a gains variant with the number ' num2str(variant_nr_gains) ' existing. Would you like to override the variant?'],'Overwrite variant?');
                        if ~strcmp(answer, 'OK')
                            variant_nr_gains = -1;
                        end
                    end
                end
            end
            
            if app.StartCheckBoxBoundary.Value == true
                variant_nr_boundary = app.StartBoundaryEditField.Value;              
                if variant_nr_boundary <= length(building.boundary)
                    if ~isempty(building.boundary(variant_nr_boundary).weather)
                        answer = uiconfirm(app.carnotUIBKUIFigure,['There is already a boundary variant with the number ' num2str(variant_nr_boundary) ' existing. Would you like to override the variant?'],'Overwrite variant?');
                        if ~strcmp(answer, 'OK')
                            variant_nr_boundary = -1;
                        end
                    end
                end
            end
            
            if app.StartCheckBoxThermalZone.Value == true
                variant_nr_tz = app.StartThermalZoneEditField.Value;              
                if variant_nr_tz <= length(building.thermalzone)
                    if ~isempty(building.thermalzone(variant_nr_tz).zone(1,1).rooms)
                        answer = uiconfirm(app.carnotUIBKUIFigure,['There is already a thermalzone variant with the number ' num2str(variant_nr_tz) ' existing. Would you like to override the variant?'],'Overwrite variant?');
                        if ~strcmp(answer, 'OK')
                            variant_nr_tz = -1;
                        end
                    end
                end
            end 
            
           if app.StartCheckBoxHVAC.Value == true
                variant_nr_hvac = app.StartHVACEditField.Value;              
                if variant_nr_hvac <= length(building.hvac)
                    if ~isempty(building.hvac(variant_nr_hvac).system)
                        answer = uiconfirm(app.carnotUIBKUIFigure,['There is already a hvac variant with the number ' num2str(variant_nr_hvac) ' existing. Would you like to override the variant?'],'Overwrite variant?');
                        if ~strcmp(answer, 'OK')
                            variant_nr_hvac = -1;
                        end
                    end
                end
            end 
                
                if variant_nr_hvac>-1 || variant_nr_tz>-1 || variant_nr_boundary>-1 || variant_nr_gains>-1 || variant_nr_constr>-1 || variant_nr_geom>-1
                    
                    d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                    modify.geometry = variant_nr_geom;
                    modify.construction = variant_nr_constr;
                    modify.boundary = variant_nr_boundary;
                    modify.gains = variant_nr_gains;
                    modify.hvac = variant_nr_hvac;
                    modify.thermalzone = variant_nr_tz;
                    
                    filename = app.StartImportwholeBuildingdropList.Value;
                    filenames = strsplit(filename,'.');
                    
                    if strcmp(filenames{end},'xls') || strcmp(filenames{end},'xlsx')
                        import_mode = '';
                        
                        [status, sheets, xlFormat] = xlsfinfo(filename);
                        
                        matches = strfind(sheets,'Room');
                        tf = any(horzcat(matches{:}));
                        if tf
                            import_mode = 'excel';
                        end
                        
                        matches = strfind(sheets,'CARNOT');
                        tf = any(horzcat(matches{:}));
                        if tf
                            import_mode = 'PHPP';
                        end
                    elseif strcmp(filenames{end},'xml')
                        import_mode = 'gbXML';
                    else
                        import_mode = '';
                    end
                    
                    name_XML = '';
                    name_EXCEL = '';
                    name_PHPP = '';
                    if strcmp(import_mode,'gbXML')
                        name_XML = filename;
                        name_EXCEL = [filenames{1} '.xlsx'];
                    end
                    if strcmp(import_mode,'excel')
                        name_EXCEL = filename;
                    end
                    if strcmp(import_mode,'PHPP')
                        name_PHPP = filename;
                    end
                    close(d);
                    
                    if ~strcmp(import_mode,'')
                        try
                            d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                            building = create_building_automatic(building, modify, import_mode, name_XML, name_EXCEL, name_PHPP, NUMBEROFZONES);
                            if variant_nr_hvac>-1 
                               building.hvac(variant_nr_hvac).name = filename;
                              
                            end
                            if variant_nr_tz>-1
                               building.thermalzone(variant_nr_tz).name = filename;
                            end
                            if variant_nr_boundary>-1
                               building.boundary(variant_nr_boundary).name = filename;
                            end
                            if variant_nr_gains>-1
                               building.gains(variant_nr_gains).name = filename;
                            end
                            if variant_nr_constr>-1
                               building.construction(variant_nr_constr).name = filename;
                            end
                            if variant_nr_geom>-1
                               building.geometry(variant_nr_geom).name = filename;
                            end
                                
                            building.save;
                            
                            TabGroupSelectionChanged(app,app.TabGroup.SelectionChangedFcn);
                            close(d);
                            uiconfirm(app.carnotUIBKUIFigure,'Import done!','Done!','Options',{'OK'},'Icon','success');
    
                        catch ME
                            ME
                            close(d);
                            answer = uiconfirm(app.carnotUIBKUIFigure,['The import of ' filename ' failed!'],'Import failed!','Options',{'OK'},'Icon','error');
                        end
                    else
                        answer = uiconfirm(app.carnotUIBKUIFigure,['' filename ' is not a valid file for the import!'],'Import failed!','Options',{'OK'},'Icon','error');
                    end
                end

        end

        % Value changed function: SimulateGeometryDropDown
        function SimulateGeometryDropDownValueChanged(app, event)
            value = app.SimulateGeometryDropDown.Value;
            global building
            building.variant_geometry=value
        end

        % Value changed function: SimulateConstructionDropDown
        function SimulateConstructionDropDownValueChanged(app, event)
            value = app.SimulateConstructionDropDown.Value;
            global building;
            building.variant_construction=value;
        end

        % Value changed function: SimulateGainDropDown
        function SimulateGainDropDownValueChanged(app, event)
            value = app.SimulateGainDropDown.Value;
            global building;
            building.variant_gains=value;
        end

        % Value changed function: SimulateBoundaryDropDown
        function SimulateBoundaryDropDownValueChanged(app, event)
            value = app.SimulateBoundaryDropDown.Value;
            global building;
            building.variant_gains=value;
        end

        % Value changed function: SimulateThermalzoneDropDown
        function SimulateThermalzoneDropDownValueChanged(app, event)
            value = app.SimulateThermalzoneDropDown.Value;
            global building;
            building.variant_thermalzone=value;
        end

        % Value changed function: SimulateHVACDropDown
        function SimulateHVACDropDownValueChanged(app, event)
            value = app.SimulateHVACDropDown.Value;
            global building;
            building.variant_hvac=value;
        end

        % Value changed function: StartAllCheckBox
        function StartAllCheckBoxValueChanged(app, event)
            value = app.StartAllCheckBox.Value;
            
            if value
                app.StartGeomCheckBox.Value=true;
                app.StartCheckBoxConstr.Value=true;
                app.StartCheckBoxGains.Value=true;
                app.StartCheckBoxBoundary.Value=true;
                app.StartCheckBoxThermalZone.Value=true;
                app.StartCheckBoxHVAC.Value=true;
            else
                app.StartGeomCheckBox.Value=false;
                app.StartCheckBoxConstr.Value=false;
                app.StartCheckBoxGains.Value=false;
                app.StartCheckBoxBoundary.Value=false;
                app.StartCheckBoxThermalZone.Value=false;
                app.StartCheckBoxHVAC.Value=false;
                                
            end

        end

        % Value changed function: StartGeomCheckBox
        function StartGeomCheckBoxValueChanged(app, event)
            value = app.StartGeomCheckBox.Value;
            
        end

        % Value changed function: SimulateDonotrunsimulationCheckBox
        function SimulateDonotrunsimulationCheckBoxValueChanged(app, event)
            value = app.SimulateDonotrunsimulationCheckBox.Value;
            
        end

        % Value changed function: StartGeometryEditField
        function StartGeometryEditFieldValueChanged(app, event)
            value = app.StartGeometryEditField.Value;
            
        end

        % Value changed function: StartConstructionEditField
        function StartConstructionEditFieldValueChanged(app, event)
            value = app.StartConstructionEditField.Value;
            
        end

        % Value changed function: StartGainsEditField
        function StartGainsEditFieldValueChanged(app, event)
            value = app.StartGainsEditField.Value;
            
        end

        % Value changed function: StartBoundaryEditField
        function StartBoundaryEditFieldValueChanged(app, event)
            value = app.StartBoundaryEditField.Value;
            
        end

        % Value changed function: StartThermalZoneEditField
        function StartThermalZoneEditFieldValueChanged(app, event)
            value = app.StartThermalZoneEditField.Value;
            
        end

        % Value changed function: StartHVACEditField
        function StartHVACEditFieldValueChanged(app, event)
            value = app.StartHVACEditField.Value;
            
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            global building
            selection = uiconfirm(app.carnotUIBKUIFigure,'Do you want to export the selected variants?','Export?','Options',{'Yes','No'},'Icon','question');
        
                if strcmp(selection,'Yes')
                    try
                    d = uiprogressdlg(app.carnotUIBKUIFigure,'Title','Please Wait','Message','');
                    
                    if app.StartGeomCheckBox.Value == true
                        variant_nr_geom = app.StartGeometryEditField.Value;              
                        if variant_nr_geom > length(building.geometry)
                           answer = uiconfirm(app.carnotUIBKUIFigure,['The geometry variant ' num2str(variant_nr_geom) ' does not exist. Please select an existing variant to export.'],'ok');
                        else
                           building.geometry.geometry_to_excel([app.ExportEditField.Value '.xlsx'], building, variant_nr_geom);
                        end
                    end
                    
                    if app.StartCheckBoxConstr.Value == true
                        variant_nr_constr = app.StartConstructionEditField.Value;              
                        if variant_nr_constr > length(building.construction)
                           answer = uiconfirm(app.carnotUIBKUIFigure,['The construction variant ' num2str(variant_nr_constr) ' does not exist. Please select an existing variant to export.'],'ok');
                         else
                           building.construction.construction_to_excel([app.ExportEditField.Value '.xlsx'], building, variant_nr_constr);       
                        end
                    end
                    
                    if app.StartCheckBoxGains.Value == true
                        variant_nr_gains = app.StartGainsEditField.Value;              
                        if variant_nr_gains > length(building.gains)
                           answer = uiconfirm(app.carnotUIBKUIFigure,['The gains variant ' num2str(variant_nr_gains) ' does not exist. Please select an existing variant to export.'],'ok');
                         else
                           building.gains.gains_to_excel([app.ExportEditField.Value '.xlsx'], building, variant_nr_gains);
                        end
                    end
                    
                    if app.StartCheckBoxBoundary.Value == true
                        variant_nr_boundary = app.StartBoundaryEditField.Value;              
                        if variant_nr_boundary > length(building.boundary)
                           answer = uiconfirm(app.carnotUIBKUIFigure,['The boundary variant ' num2str(variant_nr_boundary) ' does not exist. Please select an existing variant to export.'],'ok');
                        else
                           building.boundary.ground_neighbour_weather_to_excel([app.ExportEditField.Value '.xlsx'], building, variant_nr_boundary);
                        end
                    end
                    
                    if app.StartCheckBoxThermalZone.Value == true
                        variant_nr_tz = app.StartThermalZoneEditField.Value;              
                        if variant_nr_tz > length(building.thermalzone)
                           answer = uiconfirm(app.carnotUIBKUIFigure,['The thermalzone variant ' num2str(variant_nr_tz) ' does not exist. Please select an existing variant to export.'],'ok');
                        else
                           building.thermalzone.zone_to_excel([app.ExportEditField.Value '.xlsx'], building, variant_nr_tz);
                        end
                    end 
                    
        %            if app.StartCheckBoxHVAC.Value == True
        %                 variant_nr_hvac = app.StartHVACEditField.Value;              
        %                 if variant_nr_hvac <= length(building.hvac)
        %                    answer = uiconfirm(app.carnotUIBKUIFigure,['The hvac variant ' num2str(variant_nr_hvac) ' does not exist. Please select an existing variant to export.'],'ok');
        %                 else
        %                     building.thermalzone.zone_to_excel([app.ExportEditField.Value '.xlsx'], building, variant_nr_tz);
        %                 end
        %             end
                   close(d);
                    uiconfirm(app.carnotUIBKUIFigure,'Export done!','Done!','Options',{'OK'},'Icon','success');
                   
                    catch ME
                            ME
                            close(d);
                            uiconfirm(app.carnotUIBKUIFigure,'There was an error during the export!','Not working!','Options',{'OK'},'Icon','error');
                    end
                end
        end

        % Value changed function: ExportEditField
        function ExportEditFieldValueChanged(app, event)
            value = app.ExportEditField.Value;
            display(value)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.carnotUIBKUIFigure = uifigure;
            app.carnotUIBKUIFigure.Name = 'carnotUIBK';
            app.carnotUIBKUIFigure.CloseRequestFcn = createCallbackFcn(app, @carnotUIBKUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.carnotUIBKUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.StartTab = uitab(app.TabGroup);
            app.StartTab.Title = 'Start';
            app.LoadorCreateButton = uibutton(app.StartTab, 'push');
            app.LoadorCreateButton.ButtonPushedFcn = createCallbackFcn(app, @LoadorCreateButtonPushed, true);
            app.LoadorCreateButton.Enable = 'off';
            app.LoadorCreateButton.Text = 'Load or Create';
            app.FilenameDropDownLabel = uilabel(app.StartTab);
            app.FilenameDropDownLabel.Enable = 'off';
            app.FilenameDropDownLabel.Text = 'Filename';
            app.FilenameDropDown = uidropdown(app.StartTab);
            app.FilenameDropDown.Items = {};
            app.FilenameDropDown.Editable = 'on';
            app.FilenameDropDown.Enable = 'off';
            app.FilenameDropDown.Value = {};
            app.SaveButton = uibutton(app.StartTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.ImportfromfileLabel_8 = uilabel(app.StartTab);
            app.ImportfromfileLabel_8.Text = 'Import from file:';
            app.StartImportwholeBuildingdropList = uidropdown(app.StartTab);
            app.StartImportwholeBuildingdropList.Items = {};
            app.StartImportwholeBuildingdropList.Value = {};
            app.Label_3 = uilabel(app.StartTab);
            app.Label_3.Text = 'Variables:';
            app.GeometryEditFieldLabel_2 = uilabel(app.StartTab);
            app.GeometryEditFieldLabel_2.Text = 'Geometry';
            app.StartGeometryEditField = uieditfield(app.StartTab, 'numeric');
            app.StartGeometryEditField.ValueChangedFcn = createCallbackFcn(app, @StartGeometryEditFieldValueChanged, true);
            app.StartGeometryEditField.Value = 1;
            app.ConstructionEditFieldLabel_2 = uilabel(app.StartTab);
            app.ConstructionEditFieldLabel_2.Text = 'Construction';
            app.StartConstructionEditField = uieditfield(app.StartTab, 'numeric');
            app.StartConstructionEditField.ValueChangedFcn = createCallbackFcn(app, @StartConstructionEditFieldValueChanged, true);
            app.StartConstructionEditField.Value = 1;
            app.GainsEditFieldLabel_2 = uilabel(app.StartTab);
            app.GainsEditFieldLabel_2.Text = 'Gains';
            app.StartGainsEditField = uieditfield(app.StartTab, 'numeric');
            app.StartGainsEditField.ValueChangedFcn = createCallbackFcn(app, @StartGainsEditFieldValueChanged, true);
            app.StartGainsEditField.Value = 1;
            app.BoundaryEditFieldLabel_3 = uilabel(app.StartTab);
            app.BoundaryEditFieldLabel_3.Text = 'Boundary';
            app.StartBoundaryEditField = uieditfield(app.StartTab, 'numeric');
            app.StartBoundaryEditField.ValueChangedFcn = createCallbackFcn(app, @StartBoundaryEditFieldValueChanged, true);
            app.StartBoundaryEditField.Value = 1;
            app.ThermalZoneEditFieldLabel = uilabel(app.StartTab);
            app.ThermalZoneEditFieldLabel.Text = 'Thermal Zone';
            app.StartThermalZoneEditField = uieditfield(app.StartTab, 'numeric');
            app.StartThermalZoneEditField.ValueChangedFcn = createCallbackFcn(app, @StartThermalZoneEditFieldValueChanged, true);
            app.StartThermalZoneEditField.Value = 1;
            app.HVACEditFieldLabel_2 = uilabel(app.StartTab);
            app.HVACEditFieldLabel_2.Text = 'HVAC';
            app.StartHVACEditField = uieditfield(app.StartTab, 'numeric');
            app.StartHVACEditField.ValueChangedFcn = createCallbackFcn(app, @StartHVACEditFieldValueChanged, true);
            app.StartHVACEditField.Value = 1;
            app.ImportButton = uibutton(app.StartTab, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.ExportButton = uibutton(app.StartTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.ExporttoEditFieldLabel = uilabel(app.StartTab);
            app.ExporttoEditFieldLabel.Text = 'Export to';
            app.ExportEditField = uieditfield(app.StartTab, 'text');
            app.ExportEditField.ValueChangedFcn = createCallbackFcn(app, @ExportEditFieldValueChanged, true);
            app.StartGeomCheckBox = uicheckbox(app.StartTab);
            app.StartGeomCheckBox.ValueChangedFcn = createCallbackFcn(app, @StartGeomCheckBoxValueChanged, true);
            app.StartGeomCheckBox.Text = '';
            app.StartCheckBoxConstr = uicheckbox(app.StartTab);
            app.StartCheckBoxConstr.Text = '';
            app.StartCheckBoxGains = uicheckbox(app.StartTab);
            app.StartCheckBoxGains.Text = '';
            app.StartCheckBoxBoundary = uicheckbox(app.StartTab);
            app.StartCheckBoxBoundary.Text = '';
            app.StartCheckBoxThermalZone = uicheckbox(app.StartTab);
            app.StartCheckBoxThermalZone.Text = '';
            app.StartCheckBoxHVAC = uicheckbox(app.StartTab);
            app.StartCheckBoxHVAC.Text = '';
            app.AllLabel = uilabel(app.StartTab);
            app.AllLabel.Text = 'All';
            app.StartAllCheckBox = uicheckbox(app.StartTab);
            app.StartAllCheckBox.ValueChangedFcn = createCallbackFcn(app, @StartAllCheckBoxValueChanged, true);
            app.StartAllCheckBox.Text = '';
            app.SimulateTab = uitab(app.TabGroup);
            app.SimulateTab.Title = 'Simulate';
            app.GeometryDropDownLabel = uilabel(app.SimulateTab);
            app.GeometryDropDownLabel.Text = 'Geometry:';
            app.SimulateGeometryDropDown = uidropdown(app.SimulateTab);
            app.SimulateGeometryDropDown.Items = {};
            app.SimulateGeometryDropDown.ValueChangedFcn = createCallbackFcn(app, @SimulateGeometryDropDownValueChanged, true);
            app.SimulateGeometryDropDown.Value = {};
            app.ConstructionDropDownLabel = uilabel(app.SimulateTab);
            app.ConstructionDropDownLabel.Text = 'Construction:';
            app.SimulateConstructionDropDown = uidropdown(app.SimulateTab);
            app.SimulateConstructionDropDown.Items = {};
            app.SimulateConstructionDropDown.ValueChangedFcn = createCallbackFcn(app, @SimulateConstructionDropDownValueChanged, true);
            app.SimulateConstructionDropDown.Value = {};
            app.GainDropDownLabel = uilabel(app.SimulateTab);
            app.GainDropDownLabel.Text = 'Gain:';
            app.SimulateGainDropDown = uidropdown(app.SimulateTab);
            app.SimulateGainDropDown.Items = {};
            app.SimulateGainDropDown.ValueChangedFcn = createCallbackFcn(app, @SimulateGainDropDownValueChanged, true);
            app.SimulateGainDropDown.Value = {};
            app.BoundaryDropDownLabel = uilabel(app.SimulateTab);
            app.BoundaryDropDownLabel.Text = 'Boundary:';
            app.SimulateBoundaryDropDown = uidropdown(app.SimulateTab);
            app.SimulateBoundaryDropDown.Items = {};
            app.SimulateBoundaryDropDown.ValueChangedFcn = createCallbackFcn(app, @SimulateBoundaryDropDownValueChanged, true);
            app.SimulateBoundaryDropDown.Value = {};
            app.ThermalzoneDropDownLabel = uilabel(app.SimulateTab);
            app.ThermalzoneDropDownLabel.Text = 'Thermalzone:';
            app.SimulateThermalzoneDropDown = uidropdown(app.SimulateTab);
            app.SimulateThermalzoneDropDown.Items = {};
            app.SimulateThermalzoneDropDown.ValueChangedFcn = createCallbackFcn(app, @SimulateThermalzoneDropDownValueChanged, true);
            app.SimulateThermalzoneDropDown.Value = {};
            app.HVACDropDownLabel = uilabel(app.SimulateTab);
            app.HVACDropDownLabel.Text = 'HVAC:';
            app.SimulateHVACDropDown = uidropdown(app.SimulateTab);
            app.SimulateHVACDropDown.Items = {};
            app.SimulateHVACDropDown.ValueChangedFcn = createCallbackFcn(app, @SimulateHVACDropDownValueChanged, true);
            app.SimulateHVACDropDown.Value = {};
            app.SimulationnumberEditFieldLabel = uilabel(app.SimulateTab);
            app.SimulationnumberEditFieldLabel.Text = 'Simulation number:';
            app.SimulateSimulationnumberEditField = uieditfield(app.SimulateTab, 'numeric');
            app.SimulateSimulationnumberEditField.Limits = [1 Inf];
            app.SimulateSimulationnumberEditField.ValueDisplayFormat = '%.0f';
            app.SimulateSimulationnumberEditField.Value = 1;
            app.DescriptionEditFieldLabel_2 = uilabel(app.SimulateTab);
            app.DescriptionEditFieldLabel_2.Text = 'Description:';
            app.SimulateDescriptionEditField = uieditfield(app.SimulateTab, 'text');
            app.ModelLabel = uilabel(app.SimulateTab);
            app.ModelLabel.Text = 'Model:';
            app.SimulateModelDropDown = uidropdown(app.SimulateTab);
            app.SimulateModelDropDown.Items = {};
            app.SimulateModelDropDown.Value = {};
            app.SimulateStartSimulationButton = uibutton(app.SimulateTab, 'push');
            app.SimulateStartSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateStartSimulationButtonPushed, true);
            app.SimulateStartSimulationButton.Enable = 'off';
            app.SimulateStartSimulationButton.Text = 'Start and Save Simulation';
            app.SimulateDonotrunsimulationCheckBox = uicheckbox(app.SimulateTab);
            app.SimulateDonotrunsimulationCheckBox.ValueChangedFcn = createCallbackFcn(app, @SimulateDonotrunsimulationCheckBoxValueChanged, true);
            app.SimulateDonotrunsimulationCheckBox.Text = 'do not run simulation automatically';
            app.SimulateSaveSimulationButton = uibutton(app.SimulateTab, 'push');
            app.SimulateSaveSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateSaveSimulationButtonPushed, true);
            app.SimulateSaveSimulationButton.Enable = 'off';
            app.SimulateSaveSimulationButton.Text = 'Save Simulation';
            app.GeometryTab = uitab(app.TabGroup);
            app.GeometryTab.Title = 'Geometry';
            app.GeometryTree = uitree(app.GeometryTab);
            app.GeometryTree.SelectionChangedFcn = createCallbackFcn(app, @GeometryTreeSelectionChanged, true);
            app.GeometryPanel = uipanel(app.GeometryTab);
            app.GeometryPanel.Title = 'Geometry';
            app.VariantnameEditFieldLabel = uilabel(app.GeometryPanel);
            app.VariantnameEditFieldLabel.Text = 'Variant name:';
            app.GeometryVariantnameEditField = uieditfield(app.GeometryPanel, 'text');
            app.GeometryVariantnameEditField.ValueChangedFcn = createCallbackFcn(app, @GeometryVariantnameEditFieldValueChanged, true);
            app.GeometryDeleteVariantButton = uibutton(app.GeometryPanel, 'push');
            app.GeometryDeleteVariantButton.ButtonPushedFcn = createCallbackFcn(app, @GeometryDeleteVariantButtonPushed, true);
            app.GeometryDeleteVariantButton.Text = 'Delete variant';
            app.GeometryRoomPanel = uipanel(app.GeometryPanel);
            app.GeometryRoomPanel.Title = 'Room';
            app.NameEditFieldLabel = uilabel(app.GeometryRoomPanel);
            app.NameEditFieldLabel.Text = 'Name:';
            app.GeometryRoomNameEditField = uieditfield(app.GeometryRoomPanel, 'text');
            app.GeometryRoomNameEditField.ValueChangedFcn = createCallbackFcn(app, @GeometryRoomNameEditFieldValueChanged, true);
            app.HeightEditFieldLabel = uilabel(app.GeometryRoomPanel);
            app.HeightEditFieldLabel.Text = 'Height:';
            app.GeometryRoomHeightEditField = uieditfield(app.GeometryRoomPanel, 'numeric');
            app.GeometryRoomHeightEditField.Limits = [0 Inf];
            app.GeometryRoomHeightEditField.ValueDisplayFormat = '%.2f';
            app.GeometryRoomHeightEditField.ValueChangedFcn = createCallbackFcn(app, @GeometryRoomHeightEditFieldValueChanged, true);
            app.n50EditFieldLabel = uilabel(app.GeometryRoomPanel);
            app.n50EditFieldLabel.Text = 'n50:';
            app.GeometryRoomn50EditField = uieditfield(app.GeometryRoomPanel, 'numeric');
            app.GeometryRoomn50EditField.Limits = [0 Inf];
            app.GeometryRoomn50EditField.ValueDisplayFormat = '%.2f';
            app.GeometryRoomn50EditField.ValueChangedFcn = createCallbackFcn(app, @GeometryRoomn50EditFieldValueChanged, true);
            app.GeometryRoomAddWallButton = uibutton(app.GeometryRoomPanel, 'push');
            app.GeometryRoomAddWallButton.ButtonPushedFcn = createCallbackFcn(app, @GeometryRoomAddWallButtonPushed, true);
            app.GeometryRoomAddWallButton.Text = 'Add Wall';
            app.AreaEditFieldLabel = uilabel(app.GeometryRoomPanel);
            app.AreaEditFieldLabel.Text = 'Area:';
            app.GeometryRoomAreaEditField = uieditfield(app.GeometryRoomPanel, 'numeric');
            app.GeometryRoomAreaEditField.Limits = [0.01 Inf];
            app.GeometryRoomAreaEditField.ValueDisplayFormat = '%.2f';
            app.GeometryRoomAreaEditField.ValueChangedFcn = createCallbackFcn(app, @GeometryRoomAreaEditFieldValueChanged, true);
            app.GeometryRoomAreaEditField.Value = 0.01;
            app.GeometryRoomDeleteRoomButton = uibutton(app.GeometryRoomPanel, 'push');
            app.GeometryRoomDeleteRoomButton.ButtonPushedFcn = createCallbackFcn(app, @GeometryRoomDeleteRoomButtonPushed, true);
            app.GeometryRoomDeleteRoomButton.Text = 'Delete Room';
            app.GeometryRoomPlotButton = uibutton(app.GeometryRoomPanel, 'push');
            app.GeometryRoomPlotButton.ButtonPushedFcn = createCallbackFcn(app, @GeometryRoomPlotButtonPushed, true);
            app.GeometryRoomPlotButton.Text = 'Plot room';
            app.GeometryWallPanel = uipanel(app.GeometryPanel);
            app.GeometryWallPanel.Title = 'Wall';
            app.NameEditFieldLabel_2 = uilabel(app.GeometryWallPanel);
            app.NameEditFieldLabel_2.Text = 'Name:';
            app.GeometryWallNameEditField = uieditfield(app.GeometryWallPanel, 'text');
            app.GeometryWallNameEditField.ValueChangedFcn = createCallbackFcn(app, @GeometryWallNameEditFieldValueChanged, true);
            app.WidthLabel = uilabel(app.GeometryWallPanel);
            app.WidthLabel.Text = 'Width:';
            app.GeometryWallWidthEditField = uieditfield(app.GeometryWallPanel, 'numeric');
            app.GeometryWallWidthEditField.Limits = [0.01 Inf];
            app.GeometryWallWidthEditField.ValueDisplayFormat = '%.2f';
            app.GeometryWallWidthEditField.ValueChangedFcn = createCallbackFcn(app, @GeometryWallWidthEditFieldValueChanged, true);
            app.GeometryWallWidthEditField.Value = 0.01;
            app.HeightEditFieldLabel_2 = uilabel(app.GeometryWallPanel);
            app.HeightEditFieldLabel_2.Text = 'Height:';
            app.GeometryWallHeightEditField = uieditfield(app.GeometryWallPanel, 'numeric');
            app.GeometryWallHeightEditField.Limits = [0.01 Inf];
            app.GeometryWallHeightEditField.ValueDisplayFormat = '%.2f';
            app.GeometryWallHeightEditField.ValueChangedFcn = createCallbackFcn(app, @GeometryWallHeightEditFieldValueChanged, true);
            app.GeometryWallHeightEditField.Value = 0.01;
            app.SlopeLabel = uilabel(app.GeometryWallPanel);
            app.SlopeLabel.Text = 'Slope:';
            app.GeometryWallSlopeEditField = uieditfield(app.GeometryWallPanel, 'numeric');
            app.GeometryWallSlopeEditField.Limits = [-180 180];
            app.GeometryWallSlopeEditField.ValueDisplayFormat = '%.2f';
            app.GeometryWallSlopeEditField.ValueChangedFcn = createCallbackFcn(app, @GeometryWallSlopeEditFieldValueChanged, true);
            app.AzimuthLabel = uilabel(app.GeometryWallPanel);
            app.AzimuthLabel.Text = 'Azimuth:';
            app.GeometryWallAzimuthEditField = uieditfield(app.GeometryWallPanel, 'numeric');
            app.GeometryWallAzimuthEditField.Limits = [-360 360];
            app.GeometryWallAzimuthEditField.ValueDisplayFormat = '%.2f';
            app.GeometryWallAzimuthEditField.ValueChangedFcn = createCallbackFcn(app, @GeometryWallAzimuthEditFieldValueChanged, true);
            app.ViewfactorLabel = uilabel(app.GeometryWallPanel);
            app.ViewfactorLabel.Text = 'View factor:';
            app.GeometryWallViewFacEditField = uieditfield(app.GeometryWallPanel, 'numeric');
            app.GeometryWallViewFacEditField.Limits = [0 1];
            app.GeometryWallViewFacEditField.ValueDisplayFormat = '%.2f';
            app.GeometryWallViewFacEditField.ValueChangedFcn = createCallbackFcn(app, @GeometryWallViewFacEditFieldValueChanged, true);
            app.GeometryWallViewFacEditField.Value = 1;
            app.AmbientfactorLabel = uilabel(app.GeometryWallPanel);
            app.AmbientfactorLabel.Text = 'Ambient factor:';
            app.GeometryWallAmbFactorEditField = uieditfield(app.GeometryWallPanel, 'numeric');
            app.GeometryWallAmbFactorEditField.Limits = [0 1];
            app.GeometryWallAmbFactorEditField.ValueDisplayFormat = '%.2f';
            app.GeometryWallAmbFactorEditField.ValueChangedFcn = createCallbackFcn(app, @GeometryWallAmbFactorEditFieldValueChanged, true);
            app.GeometryWallAmbFactorEditField.Value = 0.5;
            app.ModelconstructionDropDownLabel = uilabel(app.GeometryWallPanel);
            app.ModelconstructionDropDownLabel.Text = 'Model construction:';
            app.GeometryWallModelConstructionDropDown = uidropdown(app.GeometryWallPanel);
            app.GeometryWallModelConstructionDropDown.Items = {'deactivate', 'UA', 'RC', 'hygrothermal'};
            app.GeometryWallModelConstructionDropDown.ValueChangedFcn = createCallbackFcn(app, @GeometryWallModelConstructionDropDownValueChanged, true);
            app.GeometryWallModelConstructionDropDown.Value = 'RC';
            app.ModelheattransferDropDownLabel = uilabel(app.GeometryWallPanel);
            app.ModelheattransferDropDownLabel.Text = 'Model heat transfer:';
            app.GeometryWallModelHeattransferDropDown = uidropdown(app.GeometryWallPanel);
            app.GeometryWallModelHeattransferDropDown.Items = {'none', 'simple', 'complex'};
            app.GeometryWallModelHeattransferDropDown.ValueChangedFcn = createCallbackFcn(app, @GeometryWallModelHeattransferDropDownValueChanged, true);
            app.GeometryWallModelHeattransferDropDown.Value = 'complex';
            app.ConstructionLabel = uilabel(app.GeometryWallPanel);
            app.ConstructionLabel.Text = 'Construction:';
            app.GeometryWallConstructionEditField = uieditfield(app.GeometryWallPanel, 'text');
            app.GeometryWallConstructionEditField.ValueChangedFcn = createCallbackFcn(app, @GeometryWallConstructionEditFieldValueChanged, true);
            app.InsideLabel = uilabel(app.GeometryWallPanel);
            app.InsideLabel.Text = 'Inside:';
            app.GeometryWallInsideDropDown = uidropdown(app.GeometryWallPanel);
            app.GeometryWallInsideDropDown.Items = {'yes', 'no'};
            app.GeometryWallInsideDropDown.ValueChangedFcn = createCallbackFcn(app, @GeometryWallInsideDropDownValueChanged, true);
            app.GeometryWallInsideDropDown.Value = 'yes';
            app.BoundaryEditFieldLabel_2 = uilabel(app.GeometryWallPanel);
            app.BoundaryEditFieldLabel_2.Text = 'Boundary:';
            app.GeometryWallBoundaryEditField = uieditfield(app.GeometryWallPanel, 'text');
            app.GeometryWallBoundaryEditField.ValueChangedFcn = createCallbackFcn(app, @GeometryWallBoundaryEditFieldValueChanged, true);
            app.GeometryWallBoundaryEditField.Value = 'AMBIENT';
            app.GeometryWallCalculateButton = uibutton(app.GeometryWallPanel, 'push');
            app.GeometryWallCalculateButton.ButtonPushedFcn = createCallbackFcn(app, @GeometryWallCalculateButtonPushed, true);
            app.GeometryWallCalculateButton.Text = 'Calculate';
            app.GeometryRoomAddDoor = uibutton(app.GeometryWallPanel, 'push');
            app.GeometryRoomAddDoor.ButtonPushedFcn = createCallbackFcn(app, @GeometryRoomAddDoorButtonPushed, true);
            app.GeometryRoomAddDoor.Text = 'Add Door';
            app.GeometryRoomAddWindow = uibutton(app.GeometryWallPanel, 'push');
            app.GeometryRoomAddWindow.ButtonPushedFcn = createCallbackFcn(app, @GeometryRoomAddWindowButtonPushed, true);
            app.GeometryRoomAddWindow.Text = 'Add Window';
            app.GeometryRoomDeleteWallButton = uibutton(app.GeometryWallPanel, 'push');
            app.GeometryRoomDeleteWallButton.ButtonPushedFcn = createCallbackFcn(app, @GeometryRoomDeleteWallButtonPushed, true);
            app.GeometryRoomDeleteWallButton.Text = 'Delete Wall';
            app.GeometryWindowDoorPanel = uipanel(app.GeometryPanel);
            app.GeometryWindowDoorPanel.Title = 'Window/Door';
            app.NameEditFieldLabel_3 = uilabel(app.GeometryWindowDoorPanel);
            app.NameEditFieldLabel_3.Text = 'Name:';
            app.GeometryWindowDoorNameEditField = uieditfield(app.GeometryWindowDoorPanel, 'text');
            app.GeometryWindowDoorNameEditField.ValueChangedFcn = createCallbackFcn(app, @GeometryWindowDoorNameEditFieldValueChanged, true);
            app.WidthwindowLabel = uilabel(app.GeometryWindowDoorPanel);
            app.WidthwindowLabel.Text = 'Width window:';
            app.GeometryWindowDoorWidthEditField = uieditfield(app.GeometryWindowDoorPanel, 'numeric');
            app.GeometryWindowDoorWidthEditField.Limits = [0.01 Inf];
            app.GeometryWindowDoorWidthEditField.ValueDisplayFormat = '%.2f';
            app.GeometryWindowDoorWidthEditField.ValueChangedFcn = createCallbackFcn(app, @GeometryWindowDoorWidthEditFieldValueChanged, true);
            app.GeometryWindowDoorWidthEditField.Value = 0.01;
            app.HeightwindowLabel = uilabel(app.GeometryWindowDoorPanel);
            app.HeightwindowLabel.Text = 'Height window:';
            app.GeometryWindowDoorHeightEditField = uieditfield(app.GeometryWindowDoorPanel, 'numeric');
            app.GeometryWindowDoorHeightEditField.Limits = [0.01 Inf];
            app.GeometryWindowDoorHeightEditField.ValueDisplayFormat = '%.2f';
            app.GeometryWindowDoorHeightEditField.ValueChangedFcn = createCallbackFcn(app, @GeometryWindowDoorHeightEditFieldValueChanged, true);
            app.GeometryWindowDoorHeightEditField.Value = 0.01;
            app.ConstructionLabel_2 = uilabel(app.GeometryWindowDoorPanel);
            app.ConstructionLabel_2.Text = 'Construction:';
            app.GeometryWindowDoorConstructionEditField = uieditfield(app.GeometryWindowDoorPanel, 'text');
            app.GeometryWindowDoorConstructionEditField.ValueChangedFcn = createCallbackFcn(app, @GeometryWindowDoorConstructionEditFieldValueChanged, true);
            app.ViewfactorLabel_2 = uilabel(app.GeometryWindowDoorPanel);
            app.ViewfactorLabel_2.Text = 'View factor:';
            app.GeometryWindowDoorViewFacEditField = uieditfield(app.GeometryWindowDoorPanel, 'numeric');
            app.GeometryWindowDoorViewFacEditField.Limits = [0 1];
            app.GeometryWindowDoorViewFacEditField.ValueDisplayFormat = '%.2f';
            app.GeometryWindowDoorViewFacEditField.ValueChangedFcn = createCallbackFcn(app, @GeometryWindowDoorViewFacEditFieldValueChanged, true);
            app.GeometryWindowDoorViewFacEditField.Value = 1;
            app.AmbientfactorLabel_2 = uilabel(app.GeometryWindowDoorPanel);
            app.AmbientfactorLabel_2.Text = 'Ambient factor:';
            app.GeometryWindowDoorAmbFactorEditField = uieditfield(app.GeometryWindowDoorPanel, 'numeric');
            app.GeometryWindowDoorAmbFactorEditField.Limits = [0 1];
            app.GeometryWindowDoorAmbFactorEditField.ValueDisplayFormat = '%.2f';
            app.GeometryWindowDoorAmbFactorEditField.ValueChangedFcn = createCallbackFcn(app, @GeometryWindowDoorAmbFactorEditFieldValueChanged, true);
            app.GeometryWindowDoorAmbFactorEditField.Value = 0.5;
            app.ModelconstructionDropDownLabel_2 = uilabel(app.GeometryWindowDoorPanel);
            app.ModelconstructionDropDownLabel_2.Text = 'Model construction:';
            app.GeometryWindowDoorModelConstructionDropDown = uidropdown(app.GeometryWindowDoorPanel);
            app.GeometryWindowDoorModelConstructionDropDown.Items = {'TF / UA', 'RC / RC', 'TF w shading / hygrothermal'};
            app.GeometryWindowDoorModelConstructionDropDown.ValueChangedFcn = createCallbackFcn(app, @GeometryWindowDoorModelConstructionDropDownValueChanged, true);
            app.GeometryWindowDoorModelConstructionDropDown.Value = 'TF w shading / hygrothermal';
            app.DirtyfactorLabel = uilabel(app.GeometryWindowDoorPanel);
            app.DirtyfactorLabel.Text = 'Dirty factor:';
            app.GeometryWindowDoorDirtyFacEditField = uieditfield(app.GeometryWindowDoorPanel, 'numeric');
            app.GeometryWindowDoorDirtyFacEditField.Limits = [0 1];
            app.GeometryWindowDoorDirtyFacEditField.ValueDisplayFormat = '%.2f';
            app.GeometryWindowDoorDirtyFacEditField.ValueChangedFcn = createCallbackFcn(app, @GeometryWindowDoorDirtyFacEditFieldValueChanged, true);
            app.GeometryWindowDoorDirtyFacEditField.Value = 1;
            app.psitopbottomleftrightLabel = uilabel(app.GeometryWindowDoorPanel);
            app.psitopbottomleftrightLabel.Text = 'psi (top/bottom/left/right):';
            app.GeometryWindowDoorPsi1EditField = uieditfield(app.GeometryWindowDoorPanel, 'numeric');
            app.GeometryWindowDoorPsi1EditField.ValueDisplayFormat = '%.2f';
            app.GeometryWindowDoorPsi1EditField.ValueChangedFcn = createCallbackFcn(app, @GeometryWindowDoorPsi1EditFieldValueChanged, true);
            app.GeometryWindowDoorPsi2EditField = uieditfield(app.GeometryWindowDoorPanel, 'numeric');
            app.GeometryWindowDoorPsi2EditField.ValueDisplayFormat = '%.2f';
            app.GeometryWindowDoorPsi2EditField.ValueChangedFcn = createCallbackFcn(app, @GeometryWindowDoorPsi2EditFieldValueChanged, true);
            app.GeometryWindowDoorPsi3EditField = uieditfield(app.GeometryWindowDoorPanel, 'numeric');
            app.GeometryWindowDoorPsi3EditField.ValueDisplayFormat = '%.2f';
            app.GeometryWindowDoorPsi3EditField.ValueChangedFcn = createCallbackFcn(app, @GeometryWindowDoorPsi3EditFieldValueChanged, true);
            app.GeometryWindowDoorPsi4EditField = uieditfield(app.GeometryWindowDoorPanel, 'numeric');
            app.GeometryWindowDoorPsi4EditField.ValueDisplayFormat = '%.2f';
            app.GeometryWindowDoorPsi4EditField.ValueChangedFcn = createCallbackFcn(app, @GeometryWindowDoorPsi4EditFieldValueChanged, true);
            app.WidthglassLabel = uilabel(app.GeometryWindowDoorPanel);
            app.WidthglassLabel.Text = 'Width glass:';
            app.GeometryWindowDoorWidthGlassEditField = uieditfield(app.GeometryWindowDoorPanel, 'numeric');
            app.GeometryWindowDoorWidthGlassEditField.Limits = [0.01 Inf];
            app.GeometryWindowDoorWidthGlassEditField.ValueDisplayFormat = '%.2f';
            app.GeometryWindowDoorWidthGlassEditField.ValueChangedFcn = createCallbackFcn(app, @GeometryWindowDoorWidthGlassEditFieldValueChanged, true);
            app.GeometryWindowDoorWidthGlassEditField.Value = 0.01;
            app.HeightglassLabel = uilabel(app.GeometryWindowDoorPanel);
            app.HeightglassLabel.Text = 'Height glass:';
            app.GeometryWindowDoorHeightGlassEditField = uieditfield(app.GeometryWindowDoorPanel, 'numeric');
            app.GeometryWindowDoorHeightGlassEditField.Limits = [0.01 Inf];
            app.GeometryWindowDoorHeightGlassEditField.ValueDisplayFormat = '%.2f';
            app.GeometryWindowDoorHeightGlassEditField.ValueChangedFcn = createCallbackFcn(app, @GeometryWindowDoorHeightGlassEditFieldValueChanged, true);
            app.GeometryWindowDoorHeightGlassEditField.Value = 0.01;
            app.ControlshadingLabel = uilabel(app.GeometryWindowDoorPanel);
            app.ControlshadingLabel.Text = 'Control shading:';
            app.GeometryWindowDoorModelControlShadingDropDown = uidropdown(app.GeometryWindowDoorPanel);
            app.GeometryWindowDoorModelControlShadingDropDown.Items = {'1 (always off)', '2 (always on)', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20'};
            app.GeometryWindowDoorModelControlShadingDropDown.ValueChangedFcn = createCallbackFcn(app, @GeometryWindowDoorModelControlShadingDropDownValueChanged, true);
            app.GeometryWindowDoorModelControlShadingDropDown.Value = '1 (always off)';
            app.ControlopeningLabel = uilabel(app.GeometryWindowDoorPanel);
            app.ControlopeningLabel.Text = 'Control opening:';
            app.GeometryWindowDoorModelControlOpeningDropDown = uidropdown(app.GeometryWindowDoorPanel);
            app.GeometryWindowDoorModelControlOpeningDropDown.Items = {'1 (always off)', '2 (always on)', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20'};
            app.GeometryWindowDoorModelControlOpeningDropDown.ValueChangedFcn = createCallbackFcn(app, @GeometryWindowDoorModelControlOpeningDropDownValueChanged, true);
            app.GeometryWindowDoorModelControlOpeningDropDown.Value = '1 (always off)';
            app.ShadingfactormonthlyLabel = uilabel(app.GeometryWindowDoorPanel);
            app.ShadingfactormonthlyLabel.Text = 'Shading factor (monthly):';
            app.GeometryWindowDoorShading1EditField = uieditfield(app.GeometryWindowDoorPanel, 'numeric');
            app.GeometryWindowDoorShading1EditField.Limits = [-1 1];
            app.GeometryWindowDoorShading1EditField.ValueDisplayFormat = '%.2f';
            app.GeometryWindowDoorShading1EditField.ValueChangedFcn = createCallbackFcn(app, @GeometryWindowDoorShading1EditFieldValueChanged, true);
            app.GeometryWindowDoorShading1EditField.Value = 1;
            app.GeometryWindowDoorShading2EditField = uieditfield(app.GeometryWindowDoorPanel, 'numeric');
            app.GeometryWindowDoorShading2EditField.Limits = [-1 1];
            app.GeometryWindowDoorShading2EditField.ValueDisplayFormat = '%.2f';
            app.GeometryWindowDoorShading2EditField.ValueChangedFcn = createCallbackFcn(app, @GeometryWindowDoorShading2EditFieldValueChanged, true);
            app.GeometryWindowDoorShading2EditField.Value = 1;
            app.GeometryWindowDoorShading3EditField = uieditfield(app.GeometryWindowDoorPanel, 'numeric');
            app.GeometryWindowDoorShading3EditField.Limits = [-1 1];
            app.GeometryWindowDoorShading3EditField.ValueDisplayFormat = '%.2f';
            app.GeometryWindowDoorShading3EditField.ValueChangedFcn = createCallbackFcn(app, @GeometryWindowDoorShading3EditFieldValueChanged, true);
            app.GeometryWindowDoorShading3EditField.Value = 1;
            app.GeometryWindowDoorShading4EditField = uieditfield(app.GeometryWindowDoorPanel, 'numeric');
            app.GeometryWindowDoorShading4EditField.Limits = [-1 1];
            app.GeometryWindowDoorShading4EditField.ValueDisplayFormat = '%.2f';
            app.GeometryWindowDoorShading4EditField.ValueChangedFcn = createCallbackFcn(app, @GeometryWindowDoorShading4EditFieldValueChanged, true);
            app.GeometryWindowDoorShading4EditField.Value = 1;
            app.GeometryWindowDoorShading5EditField = uieditfield(app.GeometryWindowDoorPanel, 'numeric');
            app.GeometryWindowDoorShading5EditField.Limits = [-1 1];
            app.GeometryWindowDoorShading5EditField.ValueDisplayFormat = '%.2f';
            app.GeometryWindowDoorShading5EditField.ValueChangedFcn = createCallbackFcn(app, @GeometryWindowDoorShading5EditFieldValueChanged, true);
            app.GeometryWindowDoorShading5EditField.Value = 1;
            app.GeometryWindowDoorShading6EditField = uieditfield(app.GeometryWindowDoorPanel, 'numeric');
            app.GeometryWindowDoorShading6EditField.Limits = [-1 1];
            app.GeometryWindowDoorShading6EditField.ValueDisplayFormat = '%.2f';
            app.GeometryWindowDoorShading6EditField.ValueChangedFcn = createCallbackFcn(app, @GeometryWindowDoorShading6EditFieldValueChanged, true);
            app.GeometryWindowDoorShading6EditField.Value = 1;
            app.GeometryWindowDoorShading7EditField = uieditfield(app.GeometryWindowDoorPanel, 'numeric');
            app.GeometryWindowDoorShading7EditField.Limits = [-1 1];
            app.GeometryWindowDoorShading7EditField.ValueDisplayFormat = '%.2f';
            app.GeometryWindowDoorShading7EditField.ValueChangedFcn = createCallbackFcn(app, @GeometryWindowDoorShading7EditFieldValueChanged, true);
            app.GeometryWindowDoorShading7EditField.Value = 1;
            app.GeometryWindowDoorShading8EditField = uieditfield(app.GeometryWindowDoorPanel, 'numeric');
            app.GeometryWindowDoorShading8EditField.Limits = [-1 1];
            app.GeometryWindowDoorShading8EditField.ValueDisplayFormat = '%.2f';
            app.GeometryWindowDoorShading8EditField.ValueChangedFcn = createCallbackFcn(app, @GeometryWindowDoorShading8EditFieldValueChanged, true);
            app.GeometryWindowDoorShading8EditField.Value = 1;
            app.GeometryWindowDoorShading9EditField = uieditfield(app.GeometryWindowDoorPanel, 'numeric');
            app.GeometryWindowDoorShading9EditField.Limits = [-1 1];
            app.GeometryWindowDoorShading9EditField.ValueDisplayFormat = '%.2f';
            app.GeometryWindowDoorShading9EditField.ValueChangedFcn = createCallbackFcn(app, @GeometryWindowDoorShading9EditFieldValueChanged, true);
            app.GeometryWindowDoorShading9EditField.Value = 1;
            app.GeometryWindowDoorShading10EditField = uieditfield(app.GeometryWindowDoorPanel, 'numeric');
            app.GeometryWindowDoorShading10EditField.Limits = [-1 1];
            app.GeometryWindowDoorShading10EditField.ValueDisplayFormat = '%.2f';
            app.GeometryWindowDoorShading10EditField.ValueChangedFcn = createCallbackFcn(app, @GeometryWindowDoorShading10EditFieldValueChanged, true);
            app.GeometryWindowDoorShading10EditField.Value = 1;
            app.GeometryWindowDoorShading11EditField = uieditfield(app.GeometryWindowDoorPanel, 'numeric');
            app.GeometryWindowDoorShading11EditField.Limits = [-1 1];
            app.GeometryWindowDoorShading11EditField.ValueDisplayFormat = '%.2f';
            app.GeometryWindowDoorShading11EditField.ValueChangedFcn = createCallbackFcn(app, @GeometryWindowDoorShading11EditFieldValueChanged, true);
            app.GeometryWindowDoorShading11EditField.Value = 1;
            app.GeometryWindowDoorShading12EditField = uieditfield(app.GeometryWindowDoorPanel, 'numeric');
            app.GeometryWindowDoorShading12EditField.Limits = [-1 1];
            app.GeometryWindowDoorShading12EditField.ValueDisplayFormat = '%.2f';
            app.GeometryWindowDoorShading12EditField.ValueChangedFcn = createCallbackFcn(app, @GeometryWindowDoorShading12EditFieldValueChanged, true);
            app.GeometryWindowDoorShading12EditField.Value = 1;
            app.GeometryRoomDeleteWindowDoorButton = uibutton(app.GeometryWindowDoorPanel, 'push');
            app.GeometryRoomDeleteWindowDoorButton.ButtonPushedFcn = createCallbackFcn(app, @GeometryRoomDeleteWindowDoorButtonPushed, true);
            app.GeometryRoomDeleteWindowDoorButton.Text = 'Delete Window/Door';
            app.GeometryCopyVariantButton = uibutton(app.GeometryPanel, 'push');
            app.GeometryCopyVariantButton.ButtonPushedFcn = createCallbackFcn(app, @GeometryCopyVariantButtonPushed, true);
            app.GeometryCopyVariantButton.Text = 'Copy variant';
            app.GeometryExportVariantButton = uibutton(app.GeometryPanel, 'push');
            app.GeometryExportVariantButton.ButtonPushedFcn = createCallbackFcn(app, @GeometryExportVariantButtonPushed, true);
            app.GeometryExportVariantButton.Text = 'Export variant';
            app.GeometryRoomAddRoomButton = uibutton(app.GeometryPanel, 'push');
            app.GeometryRoomAddRoomButton.ButtonPushedFcn = createCallbackFcn(app, @GeometryRoomAddRoomButtonPushed, true);
            app.GeometryRoomAddRoomButton.Text = 'Add Room';
            app.GeometryPlotButton = uibutton(app.GeometryPanel, 'push');
            app.GeometryPlotButton.ButtonPushedFcn = createCallbackFcn(app, @GeometryPlotButtonPushed, true);
            app.GeometryPlotButton.Text = 'Plot geometry';
            app.ImportfromfileLabel = uilabel(app.GeometryTab);
            app.ImportfromfileLabel.Text = 'Import from file:';
            app.GeometryImportfromfileDropDown = uidropdown(app.GeometryTab);
            app.GeometryImportfromfileDropDown.Items = {};
            app.GeometryImportfromfileDropDown.Value = {};
            app.variantnumberLabel = uilabel(app.GeometryTab);
            app.variantnumberLabel.Text = 'variant number:';
            app.GeometryTovariantnumberEditField = uieditfield(app.GeometryTab, 'numeric');
            app.GeometryTovariantnumberEditField.Limits = [1 Inf];
            app.GeometryTovariantnumberEditField.ValueDisplayFormat = '%.0f';
            app.GeometryTovariantnumberEditField.Value = 1;
            app.GeometryImportButton = uibutton(app.GeometryTab, 'push');
            app.GeometryImportButton.ButtonPushedFcn = createCallbackFcn(app, @GeometryImportButtonPushed, true);
            app.GeometryImportButton.Enable = 'off';
            app.GeometryImportButton.Text = 'Import';
            app.nameEditFieldLabel = uilabel(app.GeometryTab);
            app.nameEditFieldLabel.Text = 'name:';
            app.GeometryNameEditField = uieditfield(app.GeometryTab, 'text');
            app.GeometryCreateButton = uibutton(app.GeometryTab, 'push');
            app.GeometryCreateButton.ButtonPushedFcn = createCallbackFcn(app, @GeometryCreateButtonPushed, true);
            app.GeometryCreateButton.Enable = 'off';
            app.GeometryCreateButton.Text = 'Create empty';
            app.ConstructionTab = uitab(app.TabGroup);
            app.ConstructionTab.Title = 'Construction';
            app.ConstructionTree = uitree(app.ConstructionTab);
            app.ConstructionTree.SelectionChangedFcn = createCallbackFcn(app, @ConstructionTreeSelectionChanged, true);
            app.ConstructionPanel = uipanel(app.ConstructionTab);
            app.ConstructionPanel.Title = 'Construction';
            app.VariantnameEditFieldLabel_2 = uilabel(app.ConstructionPanel);
            app.VariantnameEditFieldLabel_2.Text = 'Variant name:';
            app.ConstructionVariantnameEditField = uieditfield(app.ConstructionPanel, 'text');
            app.ConstructionVariantnameEditField.ValueChangedFcn = createCallbackFcn(app, @ConstructionVariantnameEditFieldValueChanged, true);
            app.ConstructionDeleteVariantButton = uibutton(app.ConstructionPanel, 'push');
            app.ConstructionDeleteVariantButton.ButtonPushedFcn = createCallbackFcn(app, @ConstructionDeleteVariantButtonPushed, true);
            app.ConstructionDeleteVariantButton.Text = 'Delete variant';
            app.ConstructionCopyVariantButton = uibutton(app.ConstructionPanel, 'push');
            app.ConstructionCopyVariantButton.ButtonPushedFcn = createCallbackFcn(app, @ConstructionCopyVariantButtonPushed, true);
            app.ConstructionCopyVariantButton.Text = 'Copy variant';
            app.ConstructionExportVariantButton = uibutton(app.ConstructionPanel, 'push');
            app.ConstructionExportVariantButton.ButtonPushedFcn = createCallbackFcn(app, @ConstructionExportVariantButtonPushed, true);
            app.ConstructionExportVariantButton.Text = 'Export variant';
            app.ConstructionStructurePanel = uipanel(app.ConstructionPanel);
            app.ConstructionStructurePanel.Title = 'Structure';
            app.ConstructionStructureUIAxes = uiaxes(app.ConstructionStructurePanel);
            title(app.ConstructionStructureUIAxes, '')
            xlabel(app.ConstructionStructureUIAxes, '')
            ylabel(app.ConstructionStructureUIAxes, '')
            app.ModelLevelLabel = uilabel(app.ConstructionStructurePanel);
            app.ModelLevelLabel.Text = 'Model Level:';
            app.ConstructionStructureModelLevelDropDown = uidropdown(app.ConstructionStructurePanel);
            app.ConstructionStructureModelLevelDropDown.Items = {'UA', 'RC', 'hygrothermal'};
            app.ConstructionStructureModelLevelDropDown.Enable = 'off';
            app.ConstructionStructureModelLevelDropDown.Value = 'RC';
            app.TypeEditFieldLabel = uilabel(app.ConstructionStructurePanel);
            app.TypeEditFieldLabel.Text = 'Type:';
            app.ConstructionStructureTypeEditField = uieditfield(app.ConstructionStructurePanel, 'text');
            app.ConstructionStructureTypeEditField.Editable = 'off';
            app.StructurenameEditFieldLabel = uilabel(app.ConstructionStructurePanel);
            app.StructurenameEditFieldLabel.Text = 'Structure name:';
            app.ConstructionStructureNameEditField = uieditfield(app.ConstructionStructurePanel, 'text');
            app.ConstructionStructureNameEditField.ValueChangedFcn = createCallbackFcn(app, @ConstructionStructureNameEditFieldValueChanged, true);
            app.DeletestructureButton = uibutton(app.ConstructionStructurePanel, 'push');
            app.DeletestructureButton.ButtonPushedFcn = createCallbackFcn(app, @DeletestructureButtonPushed, true);
            app.DeletestructureButton.Text = 'Delete structure';
            app.emissioninternalEditFieldLabel = uilabel(app.ConstructionStructurePanel);
            app.emissioninternalEditFieldLabel.Text = 'emission internal:';
            app.ConstructionStructureemissioninternalEditField = uieditfield(app.ConstructionStructurePanel, 'numeric');
            app.ConstructionStructureemissioninternalEditField.Limits = [0 1];
            app.ConstructionStructureemissioninternalEditField.ValueDisplayFormat = '%.2f';
            app.ConstructionStructureemissioninternalEditField.ValueChangedFcn = createCallbackFcn(app, @ConstructionStructureemissioninternalEditFieldValueChanged, true);
            app.ConstructionStructureemissioninternalEditField.Value = 0.94;
            app.emissionexternalEditFieldLabel = uilabel(app.ConstructionStructurePanel);
            app.emissionexternalEditFieldLabel.Text = 'emission external:';
            app.ConstructionStructureemissionexternalEditField = uieditfield(app.ConstructionStructurePanel, 'numeric');
            app.ConstructionStructureemissionexternalEditField.Limits = [0 1];
            app.ConstructionStructureemissionexternalEditField.ValueDisplayFormat = '%.2f';
            app.ConstructionStructureemissionexternalEditField.ValueChangedFcn = createCallbackFcn(app, @ConstructionStructureemissionexternalEditFieldValueChanged, true);
            app.ConstructionStructureemissionexternalEditField.Value = 0.94;
            app.absorptionexternalEditFieldLabel = uilabel(app.ConstructionStructurePanel);
            app.absorptionexternalEditFieldLabel.Text = 'absorption external:';
            app.ConstructionStructureabsorptionexternalEditField = uieditfield(app.ConstructionStructurePanel, 'numeric');
            app.ConstructionStructureabsorptionexternalEditField.Limits = [0 1];
            app.ConstructionStructureabsorptionexternalEditField.ValueDisplayFormat = '%.2f';
            app.ConstructionStructureabsorptionexternalEditField.ValueChangedFcn = createCallbackFcn(app, @ConstructionStructureabsorptionexternalEditFieldValueChanged, true);
            app.ConstructionStructureabsorptionexternalEditField.Value = 0.65;
            app.InformationTextAreaLabel = uilabel(app.ConstructionStructurePanel);
            app.InformationTextAreaLabel.Text = 'Information:';
            app.ConstructionStructureInformationTextArea = uitextarea(app.ConstructionStructurePanel);
            app.ConstructionStructureInformationTextArea.Editable = 'off';
            app.ConstructionAddstructureRCButton = uibutton(app.ConstructionPanel, 'push');
            app.ConstructionAddstructureRCButton.ButtonPushedFcn = createCallbackFcn(app, @ConstructionAddstructureRCButtonPushed, true);
            app.ConstructionAddstructureRCButton.Text = 'Add structure (RC)';
            app.ConstructionAddWindowButton = uibutton(app.ConstructionPanel, 'push');
            app.ConstructionAddWindowButton.ButtonPushedFcn = createCallbackFcn(app, @ConstructionAddWindowButtonPushed, true);
            app.ConstructionAddWindowButton.Text = 'Add window';
            app.ImportfromfileLabel_2 = uilabel(app.ConstructionTab);
            app.ImportfromfileLabel_2.Text = 'Import from file:';
            app.ConstructionImportfromfileDropDown = uidropdown(app.ConstructionTab);
            app.ConstructionImportfromfileDropDown.Items = {};
            app.ConstructionImportfromfileDropDown.Value = {};
            app.variantnumberLabel_2 = uilabel(app.ConstructionTab);
            app.variantnumberLabel_2.Text = 'variant number:';
            app.ConstructionTovariantnumberEditField = uieditfield(app.ConstructionTab, 'numeric');
            app.ConstructionTovariantnumberEditField.Limits = [1 Inf];
            app.ConstructionTovariantnumberEditField.ValueDisplayFormat = '%.0f';
            app.ConstructionTovariantnumberEditField.Value = 1;
            app.nameEditFieldLabel_2 = uilabel(app.ConstructionTab);
            app.nameEditFieldLabel_2.Text = 'name:';
            app.ConstructionNameEditField = uieditfield(app.ConstructionTab, 'text');
            app.ConstructionImportButton = uibutton(app.ConstructionTab, 'push');
            app.ConstructionImportButton.ButtonPushedFcn = createCallbackFcn(app, @ConstructionImportButtonPushed, true);
            app.ConstructionImportButton.Enable = 'off';
            app.ConstructionImportButton.Text = 'Import';
            app.ConstructionCreateButton = uibutton(app.ConstructionTab, 'push');
            app.ConstructionCreateButton.ButtonPushedFcn = createCallbackFcn(app, @ConstructionCreateButtonPushed, true);
            app.ConstructionCreateButton.Enable = 'off';
            app.ConstructionCreateButton.Text = 'Create empty';
            app.GainsTab = uitab(app.TabGroup);
            app.GainsTab.Title = 'Gains';
            app.GainsTree = uitree(app.GainsTab);
            app.GainsTree.SelectionChangedFcn = createCallbackFcn(app, @GainsTreeSelectionChanged, true);
            app.GainsPanel = uipanel(app.GainsTab);
            app.GainsPanel.Title = 'Gains';
            app.VariantnameEditFieldLabel_3 = uilabel(app.GainsPanel);
            app.VariantnameEditFieldLabel_3.Text = 'Variant name:';
            app.GainsVariantnameEditField = uieditfield(app.GainsPanel, 'text');
            app.GainsVariantnameEditField.ValueChangedFcn = createCallbackFcn(app, @GainsVariantnameEditFieldValueChanged, true);
            app.GainsDeleteVariantButton = uibutton(app.GainsPanel, 'push');
            app.GainsDeleteVariantButton.ButtonPushedFcn = createCallbackFcn(app, @GainsDeleteVariantButtonPushed, true);
            app.GainsDeleteVariantButton.Text = 'Delete variant';
            app.GainsCopyVariantButton = uibutton(app.GainsPanel, 'push');
            app.GainsCopyVariantButton.ButtonPushedFcn = createCallbackFcn(app, @GainsCopyVariantButtonPushed, true);
            app.GainsCopyVariantButton.Text = 'Copy variant';
            app.GainsExportVariantButton = uibutton(app.GainsPanel, 'push');
            app.GainsExportVariantButton.ButtonPushedFcn = createCallbackFcn(app, @GainsExportVariantButtonPushed, true);
            app.GainsExportVariantButton.Text = 'Export variant';
            app.GainsGainPanel = uipanel(app.GainsPanel);
            app.GainsGainPanel.Title = 'Gain';
            app.GainnameLabel = uilabel(app.GainsGainPanel);
            app.GainnameLabel.Text = 'Gain name:';
            app.GainsGainNameEditField = uieditfield(app.GainsGainPanel, 'text');
            app.GainsGainNameEditField.ValueChangedFcn = createCallbackFcn(app, @GainsGainNameEditFieldValueChanged, true);
            app.TypeDropDownLabel = uilabel(app.GainsGainPanel);
            app.TypeDropDownLabel.Text = 'Type:';
            app.GainsGainTypeDropDown = uidropdown(app.GainsGainPanel);
            app.GainsGainTypeDropDown.Items = {'People', 'Light', 'Electricity', 'Moisture'};
            app.GainsGainTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @GainsGainTypeDropDownValueChanged, true);
            app.GainsGainTypeDropDown.Value = 'People';
            app.ModelDropDownLabel = uilabel(app.GainsGainPanel);
            app.ModelDropDownLabel.Text = 'Model:';
            app.GainsGainModelDropDown = uidropdown(app.GainsGainPanel);
            app.GainsGainModelDropDown.Items = {};
            app.GainsGainModelDropDown.ValueChangedFcn = createCallbackFcn(app, @GainsGainModelDropDownValueChanged, true);
            app.GainsGainModelDropDown.Value = {};
            app.GainsGainUITable = uitable(app.GainsGainPanel);
            app.GainsGainUITable.ColumnName = {'0-1'; '1-2'; '2-3'; '3-4'; '4-5'; '5-6'; '6-7'; '7-8'; '8-9'; '9-10'; '10-11'; '11-12'; '12-13'; '13-14'; '14-15'; '15-16'; '16-17'; '17-18'; '18-19'; '19-20'; '20-21'; '21-22'; '22-23'; '23-24'};
            app.GainsGainUITable.RowName = {'''peope'''; '''activity'''};
            app.GainsGainUITable.ColumnEditable = true;
            app.GainsGainUITable.CellEditCallback = createCallbackFcn(app, @GainsGainUITableCellEdit, true);
            app.useinzonesmultiselectwithCTRLandapplyLabel = uilabel(app.GainsGainPanel);
            app.useinzonesmultiselectwithCTRLandapplyLabel.Text = {'use in zones:'; ''; 'multiselect'; 'with CTRL'; 'and apply!'};
            app.GainsGainUseinzonesListBox = uilistbox(app.GainsGainPanel);
            app.GainsGainUseinzonesListBox.Items = {'zone 1', 'zone 2', 'zone 3', 'zone 4', 'zone 5', 'zone 6', 'zone 7', 'zone 8', 'zone 9', 'zone 10'};
            app.GainsGainUseinzonesListBox.Multiselect = 'on';
            app.GainsGainUseinzonesListBox.Value = {};
            app.GainsGainUseinzonesApplyButton = uibutton(app.GainsGainPanel, 'push');
            app.GainsGainUseinzonesApplyButton.ButtonPushedFcn = createCallbackFcn(app, @GainsGainUseinzonesApplyButtonPushed, true);
            app.GainsGainUseinzonesApplyButton.Text = 'Apply';
            app.GainsGainDeleteGainButton = uibutton(app.GainsGainPanel, 'push');
            app.GainsGainDeleteGainButton.ButtonPushedFcn = createCallbackFcn(app, @GainsGainDeleteGainButtonPushed, true);
            app.GainsGainDeleteGainButton.Text = 'Delete Gain';
            app.GainsGainUIAxes = uiaxes(app.GainsGainPanel);
            title(app.GainsGainUIAxes, 'Title')
            xlabel(app.GainsGainUIAxes, 'X')
            ylabel(app.GainsGainUIAxes, 'Y')
            app.GainsGain2UIAxes = uiaxes(app.GainsGainPanel);
            xlabel(app.GainsGain2UIAxes, 'X')
            ylabel(app.GainsGain2UIAxes, 'Y')
            app.GainsGainRefreshplotsButton = uibutton(app.GainsGainPanel, 'push');
            app.GainsGainRefreshplotsButton.ButtonPushedFcn = createCallbackFcn(app, @GainsGainRefreshplotsButtonPushed, true);
            app.GainsGainRefreshplotsButton.Text = 'Refresh plots';
            app.ControlgainLabel = uilabel(app.GainsGainPanel);
            app.ControlgainLabel.Text = 'Control gain:';
            app.GainsGainControlDropDown = uidropdown(app.GainsGainPanel);
            app.GainsGainControlDropDown.Items = {'1 (always off)', '2 (always on)', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20'};
            app.GainsGainControlDropDown.ValueChangedFcn = createCallbackFcn(app, @GainsGainControlDropDownValueChanged, true);
            app.GainsGainControlDropDown.Value = '2 (always on)';
            app.GainsGainAddGainButton = uibutton(app.GainsPanel, 'push');
            app.GainsGainAddGainButton.ButtonPushedFcn = createCallbackFcn(app, @GainsGainAddGainButtonPushed, true);
            app.GainsGainAddGainButton.Text = 'Add Gain';
            app.ImportfromfileLabel_3 = uilabel(app.GainsTab);
            app.ImportfromfileLabel_3.Text = 'Import from file:';
            app.GainsImportfromfileDropDown = uidropdown(app.GainsTab);
            app.GainsImportfromfileDropDown.Items = {};
            app.GainsImportfromfileDropDown.Value = {};
            app.variantnumberLabel_3 = uilabel(app.GainsTab);
            app.variantnumberLabel_3.Text = 'variant number:';
            app.GainsTovariantnumberEditField = uieditfield(app.GainsTab, 'numeric');
            app.GainsTovariantnumberEditField.Limits = [1 Inf];
            app.GainsTovariantnumberEditField.ValueDisplayFormat = '%.0f';
            app.GainsTovariantnumberEditField.Value = 1;
            app.nameEditFieldLabel_3 = uilabel(app.GainsTab);
            app.nameEditFieldLabel_3.Text = 'name:';
            app.GainsNameEditField = uieditfield(app.GainsTab, 'text');
            app.GainsImportButton = uibutton(app.GainsTab, 'push');
            app.GainsImportButton.ButtonPushedFcn = createCallbackFcn(app, @GainsImportButtonPushed, true);
            app.GainsImportButton.Enable = 'off';
            app.GainsImportButton.Text = 'Import';
            app.GainsCreateButton = uibutton(app.GainsTab, 'push');
            app.GainsCreateButton.ButtonPushedFcn = createCallbackFcn(app, @GainsCreateButtonPushed, true);
            app.GainsCreateButton.Enable = 'off';
            app.GainsCreateButton.Text = 'Create empty';
            app.BoundaryTab = uitab(app.TabGroup);
            app.BoundaryTab.Title = 'Boundary';
            app.BoundaryTree = uitree(app.BoundaryTab);
            app.BoundaryTree.SelectionChangedFcn = createCallbackFcn(app, @BoundaryTreeSelectionChanged, true);
            app.BoundaryPanel = uipanel(app.BoundaryTab);
            app.BoundaryPanel.Title = 'Boundary';
            app.VariantnameEditFieldLabel_4 = uilabel(app.BoundaryPanel);
            app.VariantnameEditFieldLabel_4.Text = 'Variant name:';
            app.BoundaryVariantnameEditField = uieditfield(app.BoundaryPanel, 'text');
            app.BoundaryVariantnameEditField.ValueChangedFcn = createCallbackFcn(app, @BoundaryVariantnameEditFieldValueChanged, true);
            app.BoundaryDeleteVariantButton = uibutton(app.BoundaryPanel, 'push');
            app.BoundaryDeleteVariantButton.ButtonPushedFcn = createCallbackFcn(app, @BoundaryDeleteVariantButtonPushed, true);
            app.BoundaryDeleteVariantButton.Text = 'Delete variant';
            app.BoundaryCopyVariantButton = uibutton(app.BoundaryPanel, 'push');
            app.BoundaryCopyVariantButton.ButtonPushedFcn = createCallbackFcn(app, @BoundaryCopyVariantButtonPushed, true);
            app.BoundaryCopyVariantButton.Text = 'Copy variant';
            app.BoundaryExportVariantButton = uibutton(app.BoundaryPanel, 'push');
            app.BoundaryExportVariantButton.ButtonPushedFcn = createCallbackFcn(app, @BoundaryExportVariantButtonPushed, true);
            app.BoundaryExportVariantButton.Text = 'Export variant';
            app.WeatherPanel = uipanel(app.BoundaryPanel);
            app.WeatherPanel.Title = 'Weather';
            app.NameEditFieldLabel_4 = uilabel(app.WeatherPanel);
            app.NameEditFieldLabel_4.Text = 'Name:';
            app.WeatherNameEditField = uieditfield(app.WeatherPanel, 'text');
            app.WeatherNameEditField.ValueChangedFcn = createCallbackFcn(app, @WeatherNameEditFieldValueChanged, true);
            app.WeatherAddChangeweatherButton = uibutton(app.WeatherPanel, 'push');
            app.WeatherAddChangeweatherButton.ButtonPushedFcn = createCallbackFcn(app, @WeatherAddChangeweatherButtonPushed, true);
            app.WeatherAddChangeweatherButton.Text = 'Add/Change weather';
            app.GroundPanel = uipanel(app.BoundaryPanel);
            app.GroundPanel.Title = 'Ground';
            app.NameEditFieldLabel_5 = uilabel(app.GroundPanel);
            app.NameEditFieldLabel_5.Text = 'Name:';
            app.GroundNameEditField = uieditfield(app.GroundPanel, 'text');
            app.GroundNameEditField.ValueChangedFcn = createCallbackFcn(app, @GroundNameEditFieldValueChanged, true);
            app.useasboundaryEditFieldLabel = uilabel(app.GroundPanel);
            app.useasboundaryEditFieldLabel.Text = 'use as boundary';
            app.GroundUseasboundaryEditField = uieditfield(app.GroundPanel, 'text');
            app.GroundUseasboundaryEditField.Editable = 'off';
            app.GroundUITable = uitable(app.GroundPanel);
            app.GroundUITable.ColumnName = {'01'; '02'; '03'; '04'; '05'; '06'; '07'; '08'; '09'; '10'; '11'; '12'};
            app.GroundUITable.RowName = {'temperature'};
            app.GroundUITable.ColumnEditable = true;
            app.GroundApplyButton = uibutton(app.GroundPanel, 'push');
            app.GroundApplyButton.ButtonPushedFcn = createCallbackFcn(app, @GroundApplyButtonPushed, true);
            app.GroundApplyButton.Text = 'Apply';
            app.NeighbourPanel = uipanel(app.BoundaryPanel);
            app.NeighbourPanel.Title = 'Neighbour';
            app.NameEditFieldLabel_6 = uilabel(app.NeighbourPanel);
            app.NameEditFieldLabel_6.Text = 'Name:';
            app.NeighbourNameEditField = uieditfield(app.NeighbourPanel, 'text');
            app.NeighbourNameEditField.ValueChangedFcn = createCallbackFcn(app, @NeighbourNameEditFieldValueChanged, true);
            app.useasboundaryEditFieldLabel_2 = uilabel(app.NeighbourPanel);
            app.useasboundaryEditFieldLabel_2.Text = 'use as boundary';
            app.NeighbourUseasboundaryEditField = uieditfield(app.NeighbourPanel, 'text');
            app.NeighbourUseasboundaryEditField.Editable = 'off';
            app.NeighbourUITable = uitable(app.NeighbourPanel);
            app.NeighbourUITable.ColumnName = {'01'; '02'; '03'; '04'; '05'; '06'; '07'; '08'; '09'; '10'; '11'; '12'};
            app.NeighbourUITable.RowName = {'temperature'};
            app.NeighbourUITable.ColumnEditable = true;
            app.NeighbourApplyButton = uibutton(app.NeighbourPanel, 'push');
            app.NeighbourApplyButton.ButtonPushedFcn = createCallbackFcn(app, @NeighbourApplyButtonPushed, true);
            app.NeighbourApplyButton.Text = 'Apply';
            app.BoundaryUIAxes1 = uiaxes(app.BoundaryPanel);
            title(app.BoundaryUIAxes1, 'Title')
            xlabel(app.BoundaryUIAxes1, 'X')
            ylabel(app.BoundaryUIAxes1, 'Y')
            app.BoundaryUIAxes2 = uiaxes(app.BoundaryPanel);
            title(app.BoundaryUIAxes2, 'Title')
            xlabel(app.BoundaryUIAxes2, 'X')
            ylabel(app.BoundaryUIAxes2, 'Y')
            app.ImportfromfileLabel_4 = uilabel(app.BoundaryTab);
            app.ImportfromfileLabel_4.Text = 'Import from file:';
            app.BoundaryImportfromfileDropDown = uidropdown(app.BoundaryTab);
            app.BoundaryImportfromfileDropDown.Items = {};
            app.BoundaryImportfromfileDropDown.Value = {};
            app.variantnumberLabel_4 = uilabel(app.BoundaryTab);
            app.variantnumberLabel_4.Text = 'variant number:';
            app.BoundaryTovariantnumberEditField = uieditfield(app.BoundaryTab, 'numeric');
            app.BoundaryTovariantnumberEditField.Limits = [1 Inf];
            app.BoundaryTovariantnumberEditField.ValueDisplayFormat = '%.0f';
            app.BoundaryTovariantnumberEditField.Value = 1;
            app.nameEditFieldLabel_4 = uilabel(app.BoundaryTab);
            app.nameEditFieldLabel_4.Text = 'name:';
            app.BoundaryNameEditField = uieditfield(app.BoundaryTab, 'text');
            app.BoundaryImportButton = uibutton(app.BoundaryTab, 'push');
            app.BoundaryImportButton.ButtonPushedFcn = createCallbackFcn(app, @BoundaryImportButtonPushed, true);
            app.BoundaryImportButton.Enable = 'off';
            app.BoundaryImportButton.Text = 'Import';
            app.BoundaryCreateButton = uibutton(app.BoundaryTab, 'push');
            app.BoundaryCreateButton.ButtonPushedFcn = createCallbackFcn(app, @BoundaryCreateButtonPushed, true);
            app.BoundaryCreateButton.Enable = 'off';
            app.BoundaryCreateButton.Text = 'Create empty';
            app.ThermalzoneTab = uitab(app.TabGroup);
            app.ThermalzoneTab.Title = 'Thermalzone';
            app.ThermalzoneTree = uitree(app.ThermalzoneTab);
            app.ThermalzoneTree.SelectionChangedFcn = createCallbackFcn(app, @ThermalzoneTreeSelectionChanged, true);
            app.ThermalzonePanel = uipanel(app.ThermalzoneTab);
            app.ThermalzonePanel.Title = 'Thermalzone';
            app.VariantnameEditFieldLabel_5 = uilabel(app.ThermalzonePanel);
            app.VariantnameEditFieldLabel_5.Text = 'Variant name:';
            app.ThermalzoneVariantnameEditField = uieditfield(app.ThermalzonePanel, 'text');
            app.ThermalzoneVariantnameEditField.ValueChangedFcn = createCallbackFcn(app, @ThermalzoneVariantnameEditFieldValueChanged, true);
            app.ThermalzoneDeleteVariantButton = uibutton(app.ThermalzonePanel, 'push');
            app.ThermalzoneDeleteVariantButton.ButtonPushedFcn = createCallbackFcn(app, @ThermalzoneDeleteVariantButtonPushed, true);
            app.ThermalzoneDeleteVariantButton.Text = 'Delete variant';
            app.ThermalzoneCopyVariantButton = uibutton(app.ThermalzonePanel, 'push');
            app.ThermalzoneCopyVariantButton.ButtonPushedFcn = createCallbackFcn(app, @ThermalzoneCopyVariantButtonPushed, true);
            app.ThermalzoneCopyVariantButton.Text = 'Copy variant';
            app.ThermalzoneExportVariantButton = uibutton(app.ThermalzonePanel, 'push');
            app.ThermalzoneExportVariantButton.ButtonPushedFcn = createCallbackFcn(app, @ThermalzoneExportVariantButtonPushed, true);
            app.ThermalzoneExportVariantButton.Text = 'Export variant';
            app.ThermalzoneZonePanel = uipanel(app.ThermalzonePanel);
            app.ThermalzoneZonePanel.Title = 'Zone';
            app.ZonenameEditFieldLabel = uilabel(app.ThermalzoneZonePanel);

            app.ZonenameEditFieldLabel.Text = 'Zone name:';
            app.ThermalzoneZoneNameEditField = uieditfield(app.ThermalzoneZonePanel, 'text');
            app.ThermalzoneZoneNameEditField.ValueChangedFcn = createCallbackFcn(app, @ThermalzoneZoneNameEditFieldValueChanged, true);
            app.ThermalzoneZoneClearButton = uibutton(app.ThermalzoneZonePanel, 'push');
            app.ThermalzoneZoneClearButton.ButtonPushedFcn = createCallbackFcn(app, @ThermalzoneZoneClearButtonPushed, true);
            app.ThermalzoneZoneClearButton.Text = 'Clear zone';
            app.CalculationmodelDropDownLabel = uilabel(app.ThermalzoneZonePanel);
            app.CalculationmodelDropDownLabel.Text = 'Calculation model:';
            app.ThermalzoneZoneCalculationmodelDropDown = uidropdown(app.ThermalzoneZonePanel);
            app.ThermalzoneZoneCalculationmodelDropDown.Items = {'none (profile used)', 'ideal (profile used)', '1*-node (change cp depending on wall model!)', '2*-node', '1*-node withour mass balance'};
            app.ThermalzoneZoneCalculationmodelDropDown.ValueChangedFcn = createCallbackFcn(app, @ThermalzoneZoneCalculationmodelDropDownValueChanged, true);
            app.ThermalzoneZoneCalculationmodelDropDown.Value = '2*-node';
            app.InitialtemperatureEditFieldLabel = uilabel(app.ThermalzoneZonePanel);
            app.InitialtemperatureEditFieldLabel.Text = 'Initial temperature:';
            app.ThermalzoneZoneInitialtemperatureEditField = uieditfield(app.ThermalzoneZonePanel, 'numeric');
            app.ThermalzoneZoneInitialtemperatureEditField.ValueDisplayFormat = '%.2f';
            app.ThermalzoneZoneInitialtemperatureEditField.ValueChangedFcn = createCallbackFcn(app, @ThermalzoneZoneInitialtemperatureEditFieldValueChanged, true);
            app.ThermalzoneZoneInitialtemperatureEditField.Value = 20;
            app.InitialrelhumidityEditFieldLabel = uilabel(app.ThermalzoneZonePanel);
            app.InitialrelhumidityEditFieldLabel.Text = 'Initial rel. humidity:';
            app.ThermalzoneZoneInitialrelhumidityEditField = uieditfield(app.ThermalzoneZonePanel, 'numeric');
            app.ThermalzoneZoneInitialrelhumidityEditField.Limits = [0 99];
            app.ThermalzoneZoneInitialrelhumidityEditField.ValueDisplayFormat = '%.2f';
            app.ThermalzoneZoneInitialrelhumidityEditField.ValueChangedFcn = createCallbackFcn(app, @ThermalzoneZoneInitialrelhumidityEditFieldValueChanged, true);
            app.ThermalzoneZoneInitialrelhumidityEditField.Value = 50;
            app.CLabel = uilabel(app.ThermalzoneZonePanel);
            app.CLabel.Text = '°C';
            app.Label = uilabel(app.ThermalzoneZonePanel);
            app.Label.Text = '%';
            app.JkgmLabel = uilabel(app.ThermalzoneZonePanel);
            app.JkgmLabel.Text = 'J/(kg.m²)';
            app.for1nodespeccpLabel = uilabel(app.ThermalzoneZonePanel);
            app.for1nodespeccpLabel.Text = 'for 1*-node: spec. cp:';
            app.ThermalzoneZoneCpEditField = uieditfield(app.ThermalzoneZonePanel, 'numeric');
            app.ThermalzoneZoneCpEditField.Limits = [100 Inf];
            app.ThermalzoneZoneCpEditField.ValueDisplayFormat = '%.0f';
            app.ThermalzoneZoneCpEditField.ValueChangedFcn = createCallbackFcn(app, @ThermalzoneZoneCpEditFieldValueChanged, true);
            app.ThermalzoneZoneCpEditField.Value = 475200;
            app.sLabel = uilabel(app.ThermalzoneZonePanel);
            app.sLabel.Text = 's';
            app.for2nodetimeconstantforradiativenodeLabel = uilabel(app.ThermalzoneZonePanel);
            app.for2nodetimeconstantforradiativenodeLabel.Text = 'for 2*-node: time constant for radiative node:';
            app.ThermalzoneZoneTimeConstantEditField = uieditfield(app.ThermalzoneZonePanel, 'numeric');
            app.ThermalzoneZoneTimeConstantEditField.Limits = [1 Inf];
            app.ThermalzoneZoneTimeConstantEditField.ValueDisplayFormat = '%.0f';
            app.ThermalzoneZoneTimeConstantEditField.ValueChangedFcn = createCallbackFcn(app, @ThermalzoneZoneTimeConstantEditFieldValueChanged, true);
            app.ThermalzoneZoneTimeConstantEditField.Value = 60;
            app.AllowmergingwallswithdifferentorientationDropDownLabel = uilabel(app.ThermalzoneZonePanel);
            app.AllowmergingwallswithdifferentorientationDropDownLabel.Text = 'Allow merging walls with different orientation:';
            app.ThermalzoneZoneOrientationImportantDropDown = uidropdown(app.ThermalzoneZonePanel);
            app.ThermalzoneZoneOrientationImportantDropDown.Items = {'yes', 'no'};
            app.ThermalzoneZoneOrientationImportantDropDown.ValueChangedFcn = createCallbackFcn(app, @ThermalzoneZoneOrientationImportantDropDownValueChanged, true);
            app.ThermalzoneZoneOrientationImportantDropDown.Value = 'yes';
            app.ThermalzoneZoneProfileUITable = uitable(app.ThermalzoneZonePanel);
            app.ThermalzoneZoneProfileUITable.ColumnName = {'Starts at'; 'T_convective'; 'T_radiative'; 'rel. humidity'; 'CO2'; 'pressure'};
            app.ThermalzoneZoneProfileUITable.RowName = {};
            app.ThermalzoneZoneProfileUITable.ColumnEditable = true;
            app.ThermalzoneZoneProfileUITable.CellEditCallback = createCallbackFcn(app, @ThermalzoneZoneProfileUITableCellEdit, true);
            app.ProfileDropDownLabel = uilabel(app.ThermalzoneZonePanel);
            app.ProfileDropDownLabel.Text = 'Profile:';
            app.ThermalzoneZoneProfileDropDown = uidropdown(app.ThermalzoneZonePanel);
            app.ThermalzoneZoneProfileDropDown.Items = {'no change', 'winter/sommer', 'monthly', 'daily', 'hourly', 'minutly'};
            app.ThermalzoneZoneProfileDropDown.ValueChangedFcn = createCallbackFcn(app, @ThermalzoneZoneProfileDropDownValueChanged, true);
            app.ThermalzoneZoneProfileDropDown.Value = 'no change';
            app.ProfilesLabel = uilabel(app.ThermalzoneZonePanel);
            app.ProfilesLabel.Text = 'Profiles:';
            app.RoomsLabel = uilabel(app.ThermalzoneZonePanel);
            app.RoomsLabel.Text = 'Rooms:';
            app.ThermalzoneZoneRoomsTextArea = uitextarea(app.ThermalzoneZonePanel);
            app.ThermalzoneZoneRoomsApplyButton = uibutton(app.ThermalzoneZonePanel, 'push');
            app.ThermalzoneZoneRoomsApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ThermalzoneZoneRoomsApplyButtonPushed, true);
            app.ThermalzoneZoneRoomsApplyButton.Text = 'Apply rooms';
            app.ThermalzonePlotButton = uibutton(app.ThermalzonePanel, 'push');
            app.ThermalzonePlotButton.ButtonPushedFcn = createCallbackFcn(app, @ThermalzonePlotButtonPushed, true);
            app.ThermalzonePlotButton.Text = 'Plot Building';
            app.ImportfromfileLabel_5 = uilabel(app.ThermalzoneTab);
            app.ImportfromfileLabel_5.Text = 'Import from file:';
            app.ThermalzoneImportfromfileDropDown = uidropdown(app.ThermalzoneTab);
            app.ThermalzoneImportfromfileDropDown.Items = {};
            app.ThermalzoneImportfromfileDropDown.Value = {};
            app.variantnumberLabel_5 = uilabel(app.ThermalzoneTab);
            app.variantnumberLabel_5.Text = 'variant number:';
            app.ThermalzoneTovariantnumberEditField = uieditfield(app.ThermalzoneTab, 'numeric');
            app.ThermalzoneTovariantnumberEditField.Limits = [1 Inf];
            app.ThermalzoneTovariantnumberEditField.ValueDisplayFormat = '%.0f';
            app.ThermalzoneTovariantnumberEditField.Value = 1;
            app.nameEditFieldLabel_5 = uilabel(app.ThermalzoneTab);
            app.nameEditFieldLabel_5.Text = 'name:';
            app.ThermalzoneNameEditField = uieditfield(app.ThermalzoneTab, 'text');
            app.ThermalzoneImportButton = uibutton(app.ThermalzoneTab, 'push');
            app.ThermalzoneImportButton.ButtonPushedFcn = createCallbackFcn(app, @ThermalzoneImportButtonPushed, true);
            app.ThermalzoneImportButton.Enable = 'off';
            app.ThermalzoneImportButton.Text = 'Import';
            app.ThermalzoneCreateButton = uibutton(app.ThermalzoneTab, 'push');
            app.ThermalzoneCreateButton.ButtonPushedFcn = createCallbackFcn(app, @ThermalzoneCreateButtonPushed, true);
            app.ThermalzoneCreateButton.Enable = 'off';
            app.ThermalzoneCreateButton.Text = 'Create empty';
            app.HVACTab = uitab(app.TabGroup);
            app.HVACTab.Title = 'HVAC';
            app.HVACTree = uitree(app.HVACTab);
            app.HVACTree.SelectionChangedFcn = createCallbackFcn(app, @HVACTreeSelectionChanged, true);
            app.HVACPanel = uipanel(app.HVACTab);
            app.HVACPanel.Title = 'HVAC';
            app.HVACUITable = uitable(app.HVACPanel);
            app.HVACUITable.ColumnName = '';
            app.HVACUITable.RowName = {};
            app.HVACUITable.CellEditCallback = createCallbackFcn(app, @HVACUITableCellEdit, true);
            app.HVACUITable.CellSelectionCallback = createCallbackFcn(app, @HVACUITableCellSelection, true);
            app.VariantnameEditFieldLabel_6 = uilabel(app.HVACPanel);
            app.VariantnameEditFieldLabel_6.Text = 'Variant name:';
            app.HVACVariantnameEditField = uieditfield(app.HVACPanel, 'text');
            app.HVACVariantnameEditField.ValueChangedFcn = createCallbackFcn(app, @HVACVariantnameEditFieldValueChanged, true);
            app.HVACDeleteVariantButton = uibutton(app.HVACPanel, 'push');
            app.HVACDeleteVariantButton.ButtonPushedFcn = createCallbackFcn(app, @HVACDeleteVariantButtonPushed, true);
            app.HVACDeleteVariantButton.Text = 'Delete variant';
            app.HVACCopyVariantButton = uibutton(app.HVACPanel, 'push');
            app.HVACCopyVariantButton.ButtonPushedFcn = createCallbackFcn(app, @HVACCopyVariantButtonPushed, true);
            app.HVACCopyVariantButton.Text = 'Copy variant';
            app.HVACExportVariantButton = uibutton(app.HVACPanel, 'push');
            app.HVACExportVariantButton.ButtonPushedFcn = createCallbackFcn(app, @HVACExportVariantButtonPushed, true);
            app.HVACExportVariantButton.Text = 'Export variant';
            app.HVACSystemPanel = uipanel(app.HVACPanel);
            app.HVACSystemPanel.Title = 'System';
            app.HVACSystemAddButton = uibutton(app.HVACSystemPanel, 'push');
            app.HVACSystemAddButton.ButtonPushedFcn = createCallbackFcn(app, @HVACSystemAddButtonPushed, true);
            app.HVACSystemAddButton.Text = 'Add empty system';
            app.HVACSystemImportButton = uibutton(app.HVACSystemPanel, 'push');
            app.HVACSystemImportButton.ButtonPushedFcn = createCallbackFcn(app, @HVACSystemImportButtonPushed, true);
            app.HVACSystemImportButton.Text = 'Import system';
            app.ImportfromfileLabel_7 = uilabel(app.HVACSystemPanel);
            app.ImportfromfileLabel_7.Text = 'Import from file:';
            app.HVACSystemImportFileDropDown = uidropdown(app.HVACSystemPanel);
            app.HVACSystemImportFileDropDown.Items = {};
            app.HVACSystemImportFileDropDown.Value = {};
            app.HVACSystemParameterPanel = uipanel(app.HVACPanel);
            app.HVACSystemParameterPanel.Title = 'Parameter';
            app.HVACSystemExportButton = uibutton(app.HVACSystemParameterPanel, 'push');
            app.HVACSystemExportButton.ButtonPushedFcn = createCallbackFcn(app, @HVACSystemExportButtonPushed, true);
            app.HVACSystemExportButton.Text = 'Export system';
            app.SystemnumberEditFieldLabel = uilabel(app.HVACSystemParameterPanel);
            app.SystemnumberEditFieldLabel.Text = 'System number:';
            app.HVACSystemNumberEditField = uieditfield(app.HVACSystemParameterPanel, 'numeric');
            app.HVACSystemNumberEditField.Limits = [1 Inf];
            app.HVACSystemNumberEditField.ValueDisplayFormat = '%.0f';
            app.HVACSystemNumberEditField.ValueChangedFcn = createCallbackFcn(app, @HVACSystemNumberEditFieldValueChanged, true);
            app.HVACSystemNumberEditField.Value = 1;
            app.SystemnameEditFieldLabel = uilabel(app.HVACSystemParameterPanel);
            app.SystemnameEditFieldLabel.Text = 'System name:';
            app.HVACSystemNameEditField = uieditfield(app.HVACSystemParameterPanel, 'text');
            app.HVACSystemNameEditField.ValueChangedFcn = createCallbackFcn(app, @HVACSystemNameEditFieldValueChanged, true);
            app.HVACSystemDeleteButton = uibutton(app.HVACSystemParameterPanel, 'push');
            app.HVACSystemDeleteButton.ButtonPushedFcn = createCallbackFcn(app, @HVACSystemDeleteButtonPushed, true);
            app.HVACSystemDeleteButton.Text = 'Delete system';
            app.CautionAtthemomentonlytheimportissupportedLabel_4 = uilabel(app.HVACSystemParameterPanel);
            app.CautionAtthemomentonlytheimportissupportedLabel_4.Text = {'Caution: At the moment a changing of the HVAC parameters in the GUI ist not possible. '; 'Please change the parameters in the MATLAB workspace.'; ' ---> building.hvac(variant).system(number).parameter'};
            app.ImportfromfileLabel_6 = uilabel(app.HVACTab);
            app.ImportfromfileLabel_6.Text = 'Import from file:';
            app.HVACImportfromfileDropDown = uidropdown(app.HVACTab);
            app.HVACImportfromfileDropDown.Items = {};
            app.HVACImportfromfileDropDown.Value = {};
            app.variantnumberLabel_6 = uilabel(app.HVACTab);
            app.variantnumberLabel_6.Text = 'variant number:';
            app.HVACTovariantnumberEditField = uieditfield(app.HVACTab, 'numeric');
            app.HVACTovariantnumberEditField.Limits = [1 Inf];
            app.HVACTovariantnumberEditField.ValueDisplayFormat = '%.0f';
            app.HVACTovariantnumberEditField.Value = 1;
            app.nameEditFieldLabel_6 = uilabel(app.HVACTab);
            app.nameEditFieldLabel_6.Text = 'name:';
            app.HVACNameEditField = uieditfield(app.HVACTab, 'text');
            app.HVACImportButton = uibutton(app.HVACTab, 'push');
            app.HVACImportButton.ButtonPushedFcn = createCallbackFcn(app, @HVACImportButtonPushed, true);
            app.HVACImportButton.Enable = 'off';
            app.HVACImportButton.Text = 'Import';
            app.HVACCreateButton = uibutton(app.HVACTab, 'push');
            app.HVACCreateButton.ButtonPushedFcn = createCallbackFcn(app, @HVACCreateButtonPushed, true);
            app.HVACCreateButton.Enable = 'off';
            app.HVACCreateButton.Text = 'Create empty';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.SizeChangedFcn = createCallbackFcn(app, @ResultsTabSizeChanged, true);
            app.ResultsTab.Title = 'Results';
            app.SimulationNumberDropDown = uidropdown(app.ResultsTab);
            app.SimulationNumberDropDown.Items = {};
            app.SimulationNumberDropDown.ValueChangedFcn = createCallbackFcn(app, @SimulationNumberDropDownValueChanged, true);
            app.SimulationNumberDropDown.Value = {};
            app.ResultPanel = uipanel(app.ResultsTab);
            app.ResultPanel.Title = 'Results';
            app.DateLabel = uilabel(app.ResultPanel);
            app.DateLabel.Text = 'Date:';
            app.ResultDateEditField = uieditfield(app.ResultPanel, 'text');
            app.ResultDateEditField.Editable = 'off';
            app.DescriptionEditFieldLabel = uilabel(app.ResultPanel);
            app.DescriptionEditFieldLabel.Text = 'Description:';
            app.ResultDescriptionEditField = uieditfield(app.ResultPanel, 'text');
            app.ResultDescriptionEditField.ValueChangedFcn = createCallbackFcn(app, @ResultDescriptionEditFieldValueChanged, true);
            app.NumberofzonesEditFieldLabel = uilabel(app.ResultPanel);
            app.NumberofzonesEditFieldLabel.Text = 'Number of zones: ';
            app.ResultNumberofzonesEditField = uieditfield(app.ResultPanel, 'text');
            app.ResultNumberofzonesEditField.Editable = 'off';
            app.GeometryEditFieldLabel = uilabel(app.ResultPanel);
            app.GeometryEditFieldLabel.Text = 'Geometry:';
            app.ResultGeometryEditField = uieditfield(app.ResultPanel, 'text');
            app.ResultGeometryEditField.Editable = 'off';
            app.ConstructionEditFieldLabel = uilabel(app.ResultPanel);
            app.ConstructionEditFieldLabel.Text = 'Construction:';
            app.ResultConstructionEditField = uieditfield(app.ResultPanel, 'text');
            app.ResultConstructionEditField.Editable = 'off';
            app.GainsEditFieldLabel = uilabel(app.ResultPanel);
            app.GainsEditFieldLabel.Text = 'Gains:';
            app.ResultGainsEditField = uieditfield(app.ResultPanel, 'text');
            app.ResultGainsEditField.Editable = 'off';
            app.BoundaryEditFieldLabel = uilabel(app.ResultPanel);
            app.BoundaryEditFieldLabel.Text = 'Boundary:';
            app.ResultBoundaryEditField = uieditfield(app.ResultPanel, 'text');
            app.ResultBoundaryEditField.Editable = 'off';
            app.ThermalzoneEditFieldLabel = uilabel(app.ResultPanel);
            app.ThermalzoneEditFieldLabel.Text = 'Thermalzone:';
            app.ResultThermalzoneEditField = uieditfield(app.ResultPanel, 'text');
            app.ResultThermalzoneEditField.Editable = 'off';
            app.HVACEditFieldLabel = uilabel(app.ResultPanel);
            app.HVACEditFieldLabel.Text = 'HVAC:';
            app.ResultHVACEditField = uieditfield(app.ResultPanel, 'text');
            app.ResultHVACEditField.Editable = 'off';
            app.ResultOverviewButton = uibutton(app.ResultPanel, 'push');
            app.ResultOverviewButton.ButtonPushedFcn = createCallbackFcn(app, @ResultOverviewButtonPushed, true);
            app.ResultOverviewButton.Text = 'Overview';
            app.ResultPlotButton = uibutton(app.ResultPanel, 'push');
            app.ResultPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ResultPlotButtonPushed, true);
            app.ResultPlotButton.Text = 'Plot';
            app.ResultOverviewplotsCheckBox = uicheckbox(app.ResultPanel);
            app.ResultOverviewplotsCheckBox.Text = 'Overview plots';
            app.ResultOverviewplotsCheckBox.Value = true;
            app.ResultTemperatureHumidityCO2Button = uibutton(app.ResultPanel, 'push');
            app.ResultTemperatureHumidityCO2Button.ButtonPushedFcn = createCallbackFcn(app, @ResultTemperatureHumidityCO2ButtonPushed, true);
            app.ResultTemperatureHumidityCO2Button.Text = 'Temperature - Humidity - CO2';
            app.ResultMonthlyEnergyBalanceButton = uibutton(app.ResultPanel, 'push');
            app.ResultMonthlyEnergyBalanceButton.ButtonPushedFcn = createCallbackFcn(app, @ResultMonthlyEnergyBalanceButtonPushed, true);
            app.ResultMonthlyEnergyBalanceButton.Text = 'Monthly Energy Balance';
            app.ResultEnergyDemandButton = uibutton(app.ResultPanel, 'push');
            app.ResultEnergyDemandButton.ButtonPushedFcn = createCallbackFcn(app, @ResultEnergyDemandButtonPushed, true);
            app.ResultEnergyDemandButton.Text = 'Energy Demand';
            app.ResultHeatingandCoolingPowerButton = uibutton(app.ResultPanel, 'push');
            app.ResultHeatingandCoolingPowerButton.ButtonPushedFcn = createCallbackFcn(app, @ResultHeatingandCoolingPowerButtonPushed, true);
            app.ResultHeatingandCoolingPowerButton.Text = 'Heating and Cooling Power';
            app.ResultHeatLoadCurveButton = uibutton(app.ResultPanel, 'push');
            app.ResultHeatLoadCurveButton.ButtonPushedFcn = createCallbackFcn(app, @ResultHeatLoadCurveButtonPushed, true);
            app.ResultHeatLoadCurveButton.Text = 'Heat Load Curve';
            app.ResultTemperatureBoundaryButton = uibutton(app.ResultPanel, 'push');
            app.ResultTemperatureBoundaryButton.ButtonPushedFcn = createCallbackFcn(app, @ResultTemperatureBoundaryButtonPushed, true);
            app.ResultTemperatureBoundaryButton.Text = 'Temperature Boundary';
            app.ResultTemperatureArrangedButton = uibutton(app.ResultPanel, 'push');
            app.ResultTemperatureArrangedButton.ButtonPushedFcn = createCallbackFcn(app, @ResultTemperatureArrangedButtonPushed, true);
            app.ResultTemperatureArrangedButton.Text = 'Temperature arranged';
            app.ResultMonthlyLossesGainsButton = uibutton(app.ResultPanel, 'push');
            app.ResultMonthlyLossesGainsButton.ButtonPushedFcn = createCallbackFcn(app, @ResultMonthlyLossesGainsButtonPushed, true);
            app.ResultMonthlyLossesGainsButton.Text = 'Monthly Losses and Gains';
            app.ResultCalculateCharacteristicsButton = uibutton(app.ResultPanel, 'push');
            app.ResultCalculateCharacteristicsButton.ButtonPushedFcn = createCallbackFcn(app, @ResultCalculateCharacteristicsButtonPushed, true);
            app.ResultCalculateCharacteristicsButton.Text = 'Calculate Characteristics';
            app.HeatingDemandLabel = uilabel(app.ResultPanel);
            app.HeatingDemandLabel.Text = 'Heating Demand:';
            app.CoolingDemandLabel = uilabel(app.ResultPanel);
            app.CoolingDemandLabel.Text = 'Cooling Demand:';
            app.ResultHeatingDemandEditField = uieditfield(app.ResultPanel, 'numeric');
            app.ResultHeatingDemandEditField.ValueDisplayFormat = '%.2f';
            app.ResultHeatingDemandEditField.Editable = 'off';
            app.ResultHeatingDemandSpecEditField = uieditfield(app.ResultPanel, 'numeric');
            app.ResultHeatingDemandSpecEditField.ValueDisplayFormat = '%.2f';
            app.ResultHeatingDemandSpecEditField.Editable = 'off';
            app.kWhaLabel = uilabel(app.ResultPanel);
            app.kWhaLabel.Text = 'kWh/a';
            app.kWhmaLabel = uilabel(app.ResultPanel);
            app.kWhmaLabel.Text = 'kWh/(m².a)';
            app.ResultCoolingDemandEditField = uieditfield(app.ResultPanel, 'numeric');
            app.ResultCoolingDemandEditField.ValueDisplayFormat = '%.2f';
            app.ResultCoolingDemandEditField.Editable = 'off';
            app.ResultCoolingDemandSpecEditField = uieditfield(app.ResultPanel, 'numeric');
            app.ResultCoolingDemandSpecEditField.ValueDisplayFormat = '%.2f';
            app.ResultCoolingDemandSpecEditField.Editable = 'off';
            app.kWhaLabel_2 = uilabel(app.ResultPanel);
            app.kWhaLabel_2.Text = 'kWh/a';
            app.kWhmaLabel_2 = uilabel(app.ResultPanel);
            app.kWhmaLabel_2.Text = 'kWh/(m².a)';
            app.ResultAreaEditField = uieditfield(app.ResultPanel, 'numeric');
            app.ResultAreaEditField.ValueDisplayFormat = '%.2f';
            app.ResultAreaEditField.Editable = 'off';
            app.mLabel = uilabel(app.ResultPanel);
            app.mLabel.Text = 'm²';
        end
    end

    methods (Access = public)

        % Construct app
        function app = building_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.carnotUIBKUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.carnotUIBKUIFigure)
        end
    end
end